classdef BeadsAlign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Twocamalignmentcase2youcoatbeadswithyoursamplePanel  matlab.ui.container.Panel
        frameNum_inuse                 matlab.ui.control.NumericEditField
        UseframeLabel                  matlab.ui.control.Label
        AlignmentButton_2              matlab.ui.control.Button
        image_path                     matlab.ui.control.EditField
        imagepathEditFieldLabel        matlab.ui.control.Label
        Twocamalignmentcase1yougotseparatebeadsimagesPanel  matlab.ui.container.Panel
        MinIntensityEditField          matlab.ui.control.NumericEditField
        MinIntensityEditFieldLabel     matlab.ui.control.Label
        AlignmentButton                matlab.ui.control.Button
        BeadsimagefullpathafterEditField  matlab.ui.control.EditField
        BeadsimagefullpathafterEditFieldLabel  matlab.ui.control.Label
        RadiusToleranceEditField       matlab.ui.control.NumericEditField
        RadiusToleranceEditFieldLabel  matlab.ui.control.Label
        LocalizationErrorEditField     matlab.ui.control.NumericEditField
        LocalizationErrorLabel         matlab.ui.control.Label
        PALMChEditField                matlab.ui.control.NumericEditField
        PALMChEditFieldLabel           matlab.ui.control.Label
        MPALMChEditField               matlab.ui.control.NumericEditField
        MPALMChEditFieldLabel          matlab.ui.control.Label
        BeadsimagefullpathbeforeEditField  matlab.ui.control.EditField
        BeadsimagefullpathbeforeEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainapp % Description
    end
    
    methods (Access = private)
        
        function t = registerImages_separateBeadsImage(app,image_path)
            % Description:
            % This image registration is more complicated, first pair the beads
            % localizations after initial transformation, then use the transformed
            % localization do control point registraion, then update the registered
            % image and do localization and pairing again and update the control point,
            % do this iteratively.
                          
            img_beads_560 = bfopenframe_oneChannel(fullfile(image_path),app.PALMChEditField.Value);
            img_beads_642 = bfopenframe_oneChannel(fullfile(image_path),app.MPALMChEditField.Value);
            
            FIXED = img_beads_642;
            MOVING = img_beads_560;
            
            % figure;
            % imshowpair(FIXED,MOVING,"montage")
            % 
            % figure;
            % imshowpair(FIXED,MOVING,"falsecolor")
            
            %% Step1: use phase correlation as a inital guess of alignment
            [MOVINGREG] = registerImages(MOVING,FIXED);
            
            % figure;
            % imshowpair(FIXED,MOVINGREG.RegisteredImage,"falsecolor")
            
            %% Step2: Do Gaussian fitting over the step1 aligned image, and convert the
            % localization back to unaligned image
            % I choose to use MTT to do localization
            
            % >>>>>>>>>>>>>>>>>>> MOTION BLUR DETECTION : MTT  >>>>>>>>>>>>>>>>>>>>>> %
            % Common parameters defined by users.
            LocalizationError = app.LocalizationErrorEditField.Value; % Localization Error: -6 = 10^-6 use more stringent than SPT since motionblur is sensitive to correct morphology, -6.25 detects more abnormal signal with only one high pixel and surrounding low pixel
            EmissionWavelength = 664; % wavelength in nm; consider emission max and filter cutoff
            ExposureTime = 30; % in milliseconds
            NumDeflationLoops = 0; % Generaly keep this to 0; another reason is PSF Analysis is aimed to analyze blurring particles NOT ignore them.
            
            % imaging parameters
            impars.PixelSize=0.11; % um per pixel
            impars.psf_scale=1.35; % PSF scaling
            impars.wvlnth= EmissionWavelength/1000; %emission wavelength in um
            impars.NA=1.49; % NA of detection objective
            impars.psfStd= impars.psf_scale*0.55*(impars.wvlnth)/impars.NA/1.17/impars.PixelSize/2; % PSF standard deviation in pixels
            impars.FrameRate= ExposureTime/1000; %secs
            impars.FrameSize= ExposureTime/1000; %secs
            
            % localization parameters
            locpars.wn=9; %detection box in pixels
            locpars.errorRate= LocalizationError; % error rate (10^-)
            locpars.dfltnLoops= NumDeflationLoops; % number of deflation loops
            locpars.minInt=app.MinIntensityEditField.Value; %minimum intensity in counts
            locpars.maxOptimIter= 50; % max number of iterations
            locpars.termTol= -2; % termination tolerance
            locpars.isRadiusTol=true; % use radius tolerance
            locpars.radiusTol=app.RadiusToleranceEditField.Value; % radius tolerance in percent
            locpars.posTol= 1.5;%max position refinement
            locpars.optim = [locpars.maxOptimIter,locpars.termTol,locpars.isRadiusTol,locpars.radiusTol,locpars.posTol];
            locpars.isThreshLocPrec = false;
            locpars.minLoc = 0;
            locpars.maxLoc = inf;
            locpars.isThreshSNR = false;
            locpars.minSNR = 0;
            locpars.maxSNR = inf;
            locpars.isThreshDensity = false;
            % <<<<<<<<<<<<<<<<<<< MOTION BLUR DETECTION : MTT  <<<<<<<<<<<<<<<<<<<<<< %
            
            % LOCALIZATION USING MTT
            loc_data_FIXED = localizeParticles_ASH(impars, locpars, FIXED);
            loc_data_PreRegisMOVING = localizeParticles_ASH(impars, locpars, MOVINGREG.RegisteredImage);
            
            % figure;
            % imshow(FIXED,[]);hold on;
            % scatter(loc_data_FIXED.ctrsX+1,loc_data_FIXED.ctrsY+1)
            % title({'Fixed image with','MTT detected localizations'})
            % 
            % figure;
            % imshow(MOVINGREG.RegisteredImage,[]);hold on;
            % scatter(loc_data_PreRegisMOVING.ctrsX+1,loc_data_PreRegisMOVING.ctrsY+1)
            % title("Phase contrast moving registered image with MTT detected localizations")
            
            %% Pair two sets of localizations using nearest neighbour with a small threshold
            
            % 转换点的坐标到矩阵  
            fixedPoints = [loc_data_FIXED.ctrsX+1, loc_data_FIXED.ctrsY+1];  
            movingPoints = [loc_data_PreRegisMOVING.ctrsX+1, loc_data_PreRegisMOVING.ctrsY+1];  
            
            % 设置阈值  
            min_pixel = 1; % 两个通道间点所允许的最大距离 
            [pairedIndices,val_distances] = PairLocalizations(fixedPoints,movingPoints,min_pixel);
            
            % % 如果你想要在图像上显示配对结果，你可以这样做（但这里只是一个示例，可能需要根据你的具体需求调整）  
            % figure;  
            % imshow(FIXED, []); hold on;  
            % scatter(fixedPoints(pairedIndices(:,1),1), fixedPoints(pairedIndices(:,1),2), 'r','SizeData',60); % 显示固定点（红色）
            % scatter(movingPoints(pairedIndices(:,2),1), movingPoints(pairedIndices(:,2),2), 'g','SizeData',60); 
            % for i = 1:size(pairedIndices, 1)  
            %     % 在图上绘制配对线（可选）  
            %     line([fixedPoints(pairedIndices(i,1),1), movingPoints(pairedIndices(i,2),1)], ...  
            %          [fixedPoints(pairedIndices(i,1),2), movingPoints(pairedIndices(i,2),2)], ...  
            %          'Color', 'y', 'LineWidth', 2);  
            % end  
            % hold off;  
            % title(["Paired MTT detections between","FIXED and moving registered image"])
            % 
            % 
            % figure;
            % histogram(val_distances*impars.PixelSize)
            % ylabel('Counts')
            % xlabel('displacement (um)')
            % title(["Paired MTT detections between","FIXED and moving registered image"])
            
            %% Step3: Use control point registration to perform accurate image registration
            % and keep iterate to minimize the beads displacement between aligned image
            % pair
            
            Transformation_T = MOVINGREG.Transformation.T;
            fixedPoints = [loc_data_FIXED.ctrsX+1, loc_data_FIXED.ctrsY+1]; 
            
            last_displacement = Inf;
            last_displacement_arr = [];
            ic = 1;
            
            while 1
            
                if ic == 1
                    % Convert the Moving registered localization back to its original image coordinate
                    % 将x和y坐标转换为齐次坐标
                    temp_movingPoints = [movingPoints(pairedIndices(:,2),1), movingPoints(pairedIndices(:,2),2), ones(height(movingPoints(pairedIndices(:,2),:)),1)];
                     
                    % Calculate the inverse of MOVINGREG.Transformation.T
                    % T_inv = inv(MOVINGREG.Transformation.T);
                    % Get the originalMovingPoints matrix by multiplying temp_movingPoints with the inverse
                    % originalMovingPoints = temp_movingPoints * T_inv;
                    originalMovingPoints = temp_movingPoints / Transformation_T;
                    ic = ic +1;
                else
                    [u,v] = transformPointsInverse(t,movingPoints(pairedIndices(:,2),1),movingPoints(pairedIndices(:,2),2));
                    originalMovingPoints = [u,v];        
                end
                
                % figure;
                % imshow(MOVING,[]);hold on;
                % scatter(originalMovingPoints(:,1),originalMovingPoints(:,2),'g','SizeData',20)
                % title({'Original moving image with','converted MTT detected localizations'})
                
                mp = originalMovingPoints(:,1:2);
                fp = fixedPoints(pairedIndices(:,1),:);
                
                t = fitgeotform2d(mp,fp,"similarity");    
                
                Rfixed = imref2d(size(FIXED));
                registered = imwarp(MOVING,t,OutputView=Rfixed);
                % registered = imwarp(MOVING,t);
            
                % figure
                % imshowpair(FIXED,registered,"falsecolor")
                    
                % LOCALIZATION USING MTT
                % loc_data_FIXED = localizeParticles_ASH(impars, locpars, FIXED);
                loc_data_FinalRegisMOVING = localizeParticles_ASH(impars, locpars, registered);
                
                
                % 转换点的坐标到矩阵  
                % fixedPoints = [loc_data_FIXED.ctrsX+1, loc_data_FIXED.ctrsY+1];  
                movingPoints = [loc_data_FinalRegisMOVING.ctrsX+1, loc_data_FinalRegisMOVING.ctrsY+1];  
                
                % 设置阈值  
                min_pixel = 1; % 两个通道间点所允许的最大距离 
                [pairedIndices,val_distances] = PairLocalizations(fixedPoints,movingPoints,min_pixel);
                
                % % 如果你想要在图像上显示配对结果，你可以这样做（但这里只是一个示例，可能需要根据你的具体需求调整）  
                % figure;  
                % imshow(FIXED, []); hold on;  
                % scatter(fixedPoints(pairedIndices(:,1),1), fixedPoints(pairedIndices(:,1),2), 'r','SizeData',60); % 显示固定点（红色）
                % scatter(movingPoints(pairedIndices(:,2),1), movingPoints(pairedIndices(:,2),2), 'g','SizeData',60); 
                % for i = 1:size(pairedIndices, 1)  
                %     % 在图上绘制配对线（可选）  
                %     line([fixedPoints(pairedIndices(i,1),1), movingPoints(pairedIndices(i,2),1)], ...  
                %          [fixedPoints(pairedIndices(i,1),2), movingPoints(pairedIndices(i,2),2)], ...  
                %          'Color', 'y', 'LineWidth', 2);  
                % end  
                % 
                % figure;
                % histogram(val_distances*impars.PixelSize)
                % ylabel('Counts')
                % xlabel('displacement (um)')
                % title(["Paired MTT detections between","FIXED and Final moving registered image"])
            
                mean_pair_dist = mean(val_distances*impars.PixelSize);
            
                if mean_pair_dist < last_displacement
                    last_displacement = mean_pair_dist;
                    last_displacement_arr = [last_displacement_arr,last_displacement];
                    fprintf('Difference between beads image pair displacement decreased to %.6f um, next loop begins. \n',last_displacement);
                else
                    fprintf('Difference between beads image pair displacement no longer decreases. \n')
                    break;
                end
            
            end
            
            figure;  
            imshowpair(FIXED,registered,"falsecolor");hold on;  
            scatter(fixedPoints(pairedIndices(:,1),1), fixedPoints(pairedIndices(:,1),2), 'r','SizeData',60); % 显示固定点（红色）
            scatter(movingPoints(pairedIndices(:,2),1), movingPoints(pairedIndices(:,2),2), 'g','SizeData',60); 
            for i = 1:size(pairedIndices, 1)  
                % 在图上绘制配对线（可选）  
                line([fixedPoints(pairedIndices(i,1),1), movingPoints(pairedIndices(i,2),1)], ...  
                     [fixedPoints(pairedIndices(i,1),2), movingPoints(pairedIndices(i,2),2)], ...  
                     'Color', 'y', 'LineWidth', 2);  
            end
            title('Paired beads localizations over the aligned image')

            figure;
            histogram(val_distances*impars.PixelSize)
            ylabel('Counts')
            xlabel('displacement (um)')
            title(["Paired MTT detections between","FIXED and Final moving registered image"])
            
            %% Auxiliary function
            function [MOVINGREG] = registerImages(MOVING,FIXED)
            %registerImages  Register grayscale images using auto-generated code from Registration Estimator app.
            %  [MOVINGREG] = registerImages(MOVING,FIXED) Register grayscale images
            %  MOVING and FIXED using auto-generated code from the Registration
            %  Estimator app. The values for all registration parameters were set
            %  interactively in the app and result in the registered image stored in the
            %  structure array MOVINGREG.
            
            % Auto-generated by registrationEstimator app on 03-Nov-2023
            % For the meaning of affine transformation, see matlab help doc at
            % "Matrix Representation of Geometric Transformations".
            %-----------------------------------------------------------
            
            
            % Default spatial referencing objects
            fixedRefObj = imref2d(size(FIXED));
            movingRefObj = imref2d(size(MOVING));
            
            % Phase correlation
            tform = imregcorr(MOVING,movingRefObj,FIXED,fixedRefObj,'transformtype','similarity','Window',true);
            MOVINGREG.Transformation = tform;
            MOVINGREG.RegisteredImage = imwarp(MOVING, movingRefObj, tform, 'OutputView', fixedRefObj, 'SmoothEdges', true);
            
            % Store spatial referencing object
            MOVINGREG.SpatialRefObj = fixedRefObj;
            
            end            
        end
        
        function [real_frame_idx, cell_tform] = registerImages_beadsOnSample(app)
            image_file = fullfile(app.image_path.Value);
            total_frame = max(app.mainapp.Ch2_loc_table.Frame);
            round_pos1 = [];
            round_pos2 = [];
            cell_tform = {};
            j = 1;

           
            % process all frame is too long, use just few images and do
            % interpolation
            
            real_frame_idx = round(linspace(1,total_frame,app.frameNum_inuse.Value));
            for frame_id = real_frame_idx
                [Ch1_img,Ch2_img] = bfopenframe_twoChannel(image_file,frame_id);
                if frame_id == 1
                
                    %% 560 channel PALM
                    
                    x_Ch1_beads = [];
                    y_Ch1_beads = [];
                    
                    figure('Name','First frame of Channel 1');
                    ax = axes('Position',[0 0 1 1]);
                    imshow(Ch1_img,[],'Parent',ax);
                    h1 = imcontrast();
                    uiwait(h1);
                    seg_prompt = {'Enter number of beads:'};
                    seg_prompt_name = 'Wait for user';
                    numlines = 1;
                    seg_defaults = {'1'};  % default is one nucleus mask, and zero compartments mask
                    options.Resize = 'on';
                    options.WindowStyle = 'normal';
                    seg_answer = inputdlg(seg_prompt,seg_prompt_name,numlines,seg_defaults);
                    beads_number = str2double(seg_answer{1});  % number of nucleus mask
                    
                    
                    for i = 1:beads_number
                        roi = drawrectangle(ax,"FaceAlpha",0,"Label",num2str(i));
                        round_pos1(i,:) = round(roi.Position);
                        crop_img = Ch1_img(round_pos1(i,2):round_pos1(i,2)+round_pos1(i,4),round_pos1(i,1):round_pos1(i,1)+round_pos1(i,3));
                        [xo,yo,zo] = prepareSurfaceData(round_pos1(i,1):round_pos1(i,1)+round_pos1(i,3),round_pos1(i,2):round_pos1(i,2)+round_pos1(i,4),crop_img); 
                    
                        fitresult = fit_gaussian2(xo,yo,zo);
                    
                        x_Ch1_beads(i) = fitresult(4);
                        y_Ch1_beads(i) = fitresult(5);
                    
                    
                    end
                    hold(ax,"on")
                    scatter(ax,x_Ch1_beads,y_Ch1_beads,'o');hold(ax,'off')
                    
                    xy_Ch1_beads = [x_Ch1_beads',y_Ch1_beads'];
                    
                    %% 642 channel MPALM
                    
                    x_Ch2_beads = [];
                    y_Ch2_beads = [];
                    
                    figure('Name','First frame of Channel 2');
                    ax = axes('Position',[0 0 1 1]);
                    imshow(Ch2_img,[],'Parent',ax);
                    h1 = imcontrast();
                    uiwait(h1);
                    
                    for i = 1:beads_number
                        roi = drawrectangle(ax,"FaceAlpha",0,"Label",num2str(i));
                        round_pos2(i,:) = round(roi.Position);
                        crop_img = Ch2_img(round_pos2(i,2):round_pos2(i,2)+round_pos2(i,4),round_pos2(i,1):round_pos2(i,1)+round_pos2(i,3));
                        [xo,yo,zo] = prepareSurfaceData(round_pos2(i,1):round_pos2(i,1)+round_pos2(i,3),round_pos2(i,2):round_pos2(i,2)+round_pos2(i,4),crop_img); 
                    
                        fitresult = fit_gaussian2(xo,yo,zo);
                    
                        x_Ch2_beads(i) = fitresult(4);
                        y_Ch2_beads(i) = fitresult(5);
                    
                    end
                    hold(ax,"on")
                    scatter(ax,x_Ch2_beads,y_Ch2_beads,'o');hold(ax,'off')
                    
                    xy_Ch2_beads = [x_Ch2_beads',y_Ch2_beads'];
                else
                    x_Ch1_beads = [];
                    y_Ch1_beads = [];

                    for i = 1:beads_number
                        % roi = drawrectangle(ax,"FaceAlpha",0,"Label",num2str(i));
                        % round_pos = round(roi.Position);
                        crop_img = Ch1_img(round_pos1(i,2):round_pos1(i,2)+round_pos1(i,4),round_pos1(i,1):round_pos1(i,1)+round_pos1(i,3));
                        [xo,yo,zo] = prepareSurfaceData(round_pos1(i,1):round_pos1(i,1)+round_pos1(i,3),round_pos1(i,2):round_pos1(i,2)+round_pos1(i,4),crop_img); 
                    
                        fitresult = fit_gaussian2(xo,yo,zo);
                    
                        x_Ch1_beads(i) = fitresult(4);
                        y_Ch1_beads(i) = fitresult(5);
                    
                    
                    end                                      
                    xy_Ch1_beads = [x_Ch1_beads',y_Ch1_beads'];

                    x_Ch2_beads = [];
                    y_Ch2_beads = [];

                    for i = 1:beads_number
                        % roi = drawrectangle(ax,"FaceAlpha",0,"Label",num2str(i));
                        % round_pos = round(roi.Position);
                        crop_img = Ch2_img(round_pos2(i,2):round_pos2(i,2)+round_pos2(i,4),round_pos2(i,1):round_pos2(i,1)+round_pos2(i,3));
                        [xo,yo,zo] = prepareSurfaceData(round_pos2(i,1):round_pos2(i,1)+round_pos2(i,3),round_pos2(i,2):round_pos2(i,2)+round_pos2(i,4),crop_img);  
                    
                        fitresult = fit_gaussian2(xo,yo,zo);
                    
                        x_Ch2_beads(i) = fitresult(4);
                        y_Ch2_beads(i) = fitresult(5);
                    
                    end                                      
                    xy_Ch2_beads = [x_Ch2_beads',y_Ch2_beads'];
                end
                mp = xy_Ch1_beads;
                fp = xy_Ch2_beads;
                
                cell_tform{j} = fitgeotform2d(mp,fp,"similarity");                    
                j = j+1;
                                               
            end

            function fitresult = fit_gaussian2(xData,yData,zData)
            %UNTITLED Summary of this function goes here
            %   Detailed explanation goes here
            
            % assign start point
            EmissionWavelength = 664;
            impars.PixelSize=0.11; % um per pixel, Andor iXon Ultra 897
            impars.psf_scale=1.35; % PSF scaling
            impars.wvlnth= EmissionWavelength/1000; %emission wavelength in um
            impars.NA=1.49; % NA of detection objective
            impars.psfStd= impars.psf_scale*0.55*(impars.wvlnth)/impars.NA/1.17/impars.PixelSize/2; % PSF standard deviation in pixels
            
            % assign the fit model using MTT paper
            g = fittype('I * exp(-(1/(2*r0^2))*((y - y0)^2 + (x - x0)^2)) + m','independent',{'x','y'},'dependent','z');
            fo = fitoptions(g);
            fo.StartPoint = [max(zData)-min(zData),min(zData),impars.psfStd,mean(xData),mean(yData)];
            fo.Lower = [0,0,0,min(xData),min(yData)];
            fo.Upper = [max(zData),mean(zData),Inf,max(xData),max(yData)];
            fitobject  = fit([xData,yData],zData,g,fo);
            fitresult = [fitobject.I, fitobject.r0, fitobject.m,fitobject.x0,fitobject.y0];
           
            
            % % % Check the fit result
            % figure
            % plot(fitobject, [xData yData], zData);
            % xlabel('X-coord (pixel)');ylabel('Y-coord (pixel)');zlabel('Intensity (A.U.)');
            end
        end
        
        function t = registerImages_separateBeadsImage2(app,image_path)
            % Description:
            % This image registration first localize the beads positions in two
            % channel, then pair them and do control point registration.
            
            img_beads_560 = bfopenframe_oneChannel(fullfile(image_path),app.PALMChEditField.Value);
            img_beads_642 = bfopenframe_oneChannel(fullfile(image_path),app.MPALMChEditField.Value);
            
            FIXED = img_beads_642;
            MOVING = img_beads_560;
            
            %% Step1: use phase correlation as a inital guess of alignment
            [MOVINGREG] = registerImages(MOVING,FIXED);
                                
            %% Step2: Do Gaussian fitting over the two channel 
            
            % >>>>>>>>>>>>>>>>>>> MOTION BLUR DETECTION : MTT  >>>>>>>>>>>>>>>>>>>>>> %
            % Common parameters defined by users.
            LocalizationError = app.LocalizationErrorEditField.Value; % Localization Error: -6 = 10^-6 use more stringent than SPT since motionblur is sensitive to correct morphology, -6.25 detects more abnormal signal with only one high pixel and surrounding low pixel
            EmissionWavelength = 664; % wavelength in nm; consider emission max and filter cutoff
            ExposureTime = 30; % in milliseconds
            NumDeflationLoops = 0; % Generaly keep this to 0; another reason is PSF Analysis is aimed to analyze blurring particles NOT ignore them.
            
            % imaging parameters
            % impars.PixelSize=0.11; % um per pixel
            impars.psf_scale=1.35; % PSF scaling
            impars.wvlnth= EmissionWavelength/1000; %emission wavelength in um
            impars.NA=1.49; % NA of detection objective
            % impars.psfStd= impars.psf_scale*0.55*(impars.wvlnth)/impars.NA/1.17/impars.PixelSize/2; % PSF standard deviation in pixels
            impars.FrameRate= ExposureTime/1000; %secs
            impars.FrameSize= ExposureTime/1000; %secs
            
            % localization parameters
            locpars.wn=9; %detection box in pixels
            locpars.errorRate= LocalizationError; % error rate (10^-)
            locpars.dfltnLoops= NumDeflationLoops; % number of deflation loops
            locpars.minInt=app.MinIntensityEditField.Value; %minimum intensity in counts
            locpars.maxOptimIter= 50; % max number of iterations
            locpars.termTol= -2; % termination tolerance
            locpars.isRadiusTol=true; % use radius tolerance
            locpars.radiusTol=app.RadiusToleranceEditField.Value; % radius tolerance in percent
            locpars.posTol= 1.5;%max position refinement
            locpars.optim = [locpars.maxOptimIter,locpars.termTol,locpars.isRadiusTol,locpars.radiusTol,locpars.posTol];
            locpars.isThreshLocPrec = false;
            locpars.minLoc = 0;
            locpars.maxLoc = inf;
            locpars.isThreshSNR = false;
            locpars.minSNR = 0;
            locpars.maxSNR = inf;
            locpars.isThreshDensity = false;
            % <<<<<<<<<<<<<<<<<<< MOTION BLUR DETECTION : MTT  <<<<<<<<<<<<<<<<<<<<<< %
            
            % LOCALIZATION USING MTT
            impars.PixelSize=0.11; impars.psfStd= impars.psf_scale*0.55*(impars.wvlnth)/impars.NA/1.17/impars.PixelSize/2; % PSF standard deviation in pixels
            loc_data_FIXED = localizeParticles_ASH(impars, locpars, FIXED);
            loc_data_PreRegisMOVING = localizeParticles_ASH(impars, locpars, MOVINGREG.RegisteredImage);
            
            impars.PixelSize=0.16; impars.psfStd= impars.psf_scale*0.55*(impars.wvlnth)/impars.NA/1.17/impars.PixelSize/2; % PSF standard deviation in pixels
            loc_data_MOVING = localizeParticles_ASH(impars, locpars, MOVING);
            disp('... MTT localization finished!');
                        
            figure; tiledlayout(1,3);            
            nexttile;imshow(FIXED,[]);hold on;
            scatter(loc_data_FIXED.ctrsX+1,loc_data_FIXED.ctrsY+1)
            title({'Fixed image with','MTT detected localizations'})
            
            nexttile;imshow(MOVING,[]);hold on;
            scatter(loc_data_MOVING.ctrsX+1,loc_data_MOVING.ctrsY+1)
            title({'Moving image with','MTT detected localizations'})
                        
            nexttile;imshow(MOVINGREG.RegisteredImage,[]);hold on;
            scatter(loc_data_PreRegisMOVING.ctrsX+1,loc_data_PreRegisMOVING.ctrsY+1)
            title("Phase contrast moving registered image with MTT detected localizations")
            
            %% Pair localizations from FIXED and PreRegisMOVING
            
            % 转换点的坐标到矩阵  
            fixedPoints = [loc_data_FIXED.ctrsX+1, loc_data_FIXED.ctrsY+1];  
            movingPoints = [loc_data_PreRegisMOVING.ctrsX+1, loc_data_PreRegisMOVING.ctrsY+1];  
            
            % 设置阈值  
            min_pixel = 1; % 两个通道间点所允许的最大距离 
            [pairedIndices1,val_distances] = PairLocalizations(fixedPoints,movingPoints,min_pixel);
            
            % close all
            % % 如果你想要在图像上显示配对结果，你可以这样做（但这里只是一个示例，可能需要根据你的具体需求调整）  
            % figure;  
            % imshow(FIXED, []); hold on;  
            % scatter(fixedPoints(pairedIndices1(:,1),1), fixedPoints(pairedIndices1(:,1),2), 'r','SizeData',60); % 显示固定点（红色）
            % scatter(movingPoints(pairedIndices1(:,2),1), movingPoints(pairedIndices1(:,2),2), 'g','SizeData',60); 
            % for i = 1:size(pairedIndices1, 1)  
            %     % 在图上绘制配对线（可选）  
            %     line([fixedPoints(pairedIndices1(i,1),1), movingPoints(pairedIndices1(i,2),1)], ...  
            %          [fixedPoints(pairedIndices1(i,1),2), movingPoints(pairedIndices1(i,2),2)], ...  
            %          'Color', 'y', 'LineWidth', 2);  
            % end  
            % hold off;  
            % title(["Paired MTT detections between","FIXED and moving registered image"])
            % 
            % figure;
            % histogram(val_distances*impars.PixelSize)
            % ylabel('Counts')
            % xlabel('displacement (um)')
            % title(["Paired MTT detections between","FIXED and moving registered image"])
            
            %% Pair localizations from PreRegisMOVING and MOVING
            
            % Calculate the inverse of MOVINGREG.Transformation.T
            Transformation_T = MOVINGREG.Transformation.T;
            temp_movingPoints = [movingPoints(pairedIndices1(:,2),1), movingPoints(pairedIndices1(:,2),2), ones(height(movingPoints(pairedIndices1(:,2),:)),1)];         
            % Get the originalMovingPoints matrix by multiplying temp_movingPoints with the inverse
            inverseMovingPoints = temp_movingPoints / Transformation_T;
            
            % Pair inverse transformed moving points with original loc_data_MOVING
            min_pixel = 1; % 两个通道间点所允许的最大距离 
            inverseMovingPoints = [inverseMovingPoints(:,1), inverseMovingPoints(:,2)]; 
            originalMovingPoints = [loc_data_MOVING.ctrsX+1,loc_data_MOVING.ctrsY+1];
            [pairedIndices2,val_distances] = PairLocalizations(inverseMovingPoints,originalMovingPoints,min_pixel);
            
            % figure;  
            % imshow(MOVING, []); hold on;  
            % scatter(inverseMovingPoints(pairedIndices2(:,1),1), inverseMovingPoints(pairedIndices2(:,1),2), 'r','SizeData',60); % 显示固定点（红色）
            % scatter(originalMovingPoints(pairedIndices2(:,2),1), originalMovingPoints(pairedIndices2(:,2),2), 'g','SizeData',60); 
            % for i = 1:size(pairedIndices2, 1)  
            %     % 在图上绘制配对线（可选）  
            %     line([inverseMovingPoints(pairedIndices2(i,1),1), originalMovingPoints(pairedIndices2(i,2),1)], ...  
            %          [inverseMovingPoints(pairedIndices2(i,1),2), originalMovingPoints(pairedIndices2(i,2),2)], ...  
            %          'Color', 'y', 'LineWidth', 2);  
            % end  
            % hold off;  
            % title(["Paired MTT detections between","inverseMovingPoints and orginalMovingPoints"])
            
            %% Step3: Use control point registration to perform accurate image registration
            % and keep iterate to minimize the beads displacement between aligned image
            % pair
            
            mp = originalMovingPoints(pairedIndices2(:,2),:);
            fp = fixedPoints(pairedIndices1(:,1),:);
            fp = fp(pairedIndices2(:,1),:);
            t = fitgeotform2d(mp,fp,"similarity");    
            Rfixed = imref2d(size(FIXED));
            registered = imwarp(MOVING,t,OutputView=Rfixed);
            
            figure
            imshowpair(FIXED,registered,"falsecolor")
            
            impars.PixelSize=0.11; impars.psfStd= impars.psf_scale*0.55*(impars.wvlnth)/impars.NA/1.17/impars.PixelSize/2; % PSF standard deviation in pixels
            loc_data_FinalRegisMOVING = localizeParticles_ASH(impars, locpars, registered);
                
            % 转换点的坐标到矩阵  
            % fixedPoints = [loc_data_FIXED.ctrsX+1, loc_data_FIXED.ctrsY+1];  
            RegismovingPoints = [loc_data_FinalRegisMOVING.ctrsX+1, loc_data_FinalRegisMOVING.ctrsY+1];  
            
            % 设置阈值  
            min_pixel = 1; % 两个通道间点所允许的最大距离 
            [pairedIndices3,val_distances] = PairLocalizations(fixedPoints,RegismovingPoints,min_pixel);
            
            % % 如果你想要在图像上显示配对结果，你可以这样做（但这里只是一个示例，可能需要根据你的具体需求调整）  
            % figure;  
            % imshow(FIXED, []); hold on;  
            % scatter(fixedPoints(pairedIndices3(:,1),1), fixedPoints(pairedIndices3(:,1),2), 'r','SizeData',60); % 显示固定点（红色）
            % scatter(RegismovingPoints(pairedIndices3(:,2),1), RegismovingPoints(pairedIndices3(:,2),2), 'g','SizeData',60); 
            % for i = 1:size(pairedIndices3, 1)  
            %     % 在图上绘制配对线（可选）  
            %     line([fixedPoints(pairedIndices3(i,1),1), RegismovingPoints(pairedIndices3(i,2),1)], ...  
            %          [fixedPoints(pairedIndices3(i,1),2), RegismovingPoints(pairedIndices3(i,2),2)], ...  
            %          'Color', 'y', 'LineWidth', 2);  
            % end  
            
            figure;
            histogram(val_distances*impars.PixelSize)
            ylabel('Counts')
            xlabel('displacement (um)')
            title(["Paired MTT detections between","FIXED and Final moving registered image"])
            mean_pair_dist = mean(val_distances*impars.PixelSize);

            %% Auxiliary function
            function [MOVINGREG] = registerImages(MOVING,FIXED)
            %registerImages  Register grayscale images using auto-generated code from Registration Estimator app.
            %  [MOVINGREG] = registerImages(MOVING,FIXED) Register grayscale images
            %  MOVING and FIXED using auto-generated code from the Registration
            %  Estimator app. The values for all registration parameters were set
            %  interactively in the app and result in the registered image stored in the
            %  structure array MOVINGREG.
            
            % Auto-generated by registrationEstimator app on 03-Nov-2023
            % For the meaning of affine transformation, see matlab help doc at
            % "Matrix Representation of Geometric Transformations".
            %-----------------------------------------------------------
            
            
            % Default spatial referencing objects
            fixedRefObj = imref2d(size(FIXED));
            movingRefObj = imref2d(size(MOVING));
            
            % Phase correlation
            tform = imregcorr(MOVING,movingRefObj,FIXED,fixedRefObj,'transformtype','similarity','Window',true);
            MOVINGREG.Transformation = tform;
            MOVINGREG.RegisteredImage = imwarp(MOVING, movingRefObj, tform, 'OutputView', fixedRefObj, 'SmoothEdges', true);
            
            % Store spatial referencing object
            MOVINGREG.SpatialRefObj = fixedRefObj;
            
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp = mainapp;
            app.mainapp.Ch2_loc_table.regis_Xpos = NaN(height(app.mainapp.Ch2_loc_table),1);
            app.mainapp.Ch2_loc_table.regis_Ypos = NaN(height(app.mainapp.Ch2_loc_table),1);
        end

        % Button pushed function: AlignmentButton
        function AlignmentButtonPushed(app, event)
            total_frame = max(app.mainapp.Ch2_loc_table.Frame);

            t1 = registerImages_separateBeadsImage2(app,app.BeadsimagefullpathbeforeEditField.Value);
            t2 = registerImages_separateBeadsImage2(app,app.BeadsimagefullpathafterEditField.Value);

            Scale = linspace(t1.Scale, t2.Scale, total_frame);
            RotationAngle = linspace(t1.RotationAngle, t2.RotationAngle, total_frame);
            Translation_1 = linspace(t1.Translation(1), t2.Translation(1), total_frame);
            Translation_2 = linspace(t1.Translation(2), t2.Translation(2), total_frame);

            % tformCell = cell(total_frame,1);
            progressbarText(0);
            for i = 1:total_frame
                tform = simtform2d(Scale(i),RotationAngle(i),[Translation_1(i) Translation_2(i)]);
                row_idx = app.mainapp.Ch2_loc_table.Frame == i;

                [u,v] = ...
                    transformPointsForward(tform,app.mainapp.Ch2_loc_table.xPix(row_idx),app.mainapp.Ch2_loc_table.yPix(row_idx));

                app.mainapp.Ch2_loc_table.regis_Xpos(row_idx) = u*app.mainapp.camPixelSize_Ch1.Value;                
                app.mainapp.Ch2_loc_table.regis_Ypos(row_idx) = v*app.mainapp.camPixelSize_Ch1.Value;
                progressbarText(i/total_frame);                               
            end
            disp('Alignment finished!')

            app.mainapp.ImageRegistrationStatus_Ch2.Color = [0 1 0];
            app.mainapp.PALMBinaryButton_Ch2.Enable = true;


        end

        % Button pushed function: AlignmentButton_2
        function AlignmentButton_2Pushed(app, event)
            %real_frame_idx = round(linespace(1,total_frame,app.frameNum_inuse.Value));
            [real_frame_idx,cell_tform] = registerImages_beadsOnSample(app);

            RotationAngle = cellfun(@(x) x.RotationAngle,cell_tform);
            Scale = cellfun(@(x) x.Scale,cell_tform);
            Translation_1 = cellfun(@(x) x.Translation(1),cell_tform);
            Translation_2 = cellfun(@(x) x.Translation(2),cell_tform);

            gRotationAngle = fitlm(real_frame_idx,RotationAngle);
            gScale = fitlm(real_frame_idx,Scale);
            gTranslation_1 = fitlm(real_frame_idx,Translation_1);
            gTranslation_2 = fitlm(real_frame_idx,Translation_2);

            % Interpolate to make every frame transform matrix
            total_frame = max(app.mainapp.Ch2_loc_table.Frame);            
            allRotationAngle = predict(gRotationAngle,(1:total_frame)');
            allScale = predict(gScale,(1:total_frame)');
            allTranslation_1 = predict(gTranslation_1,(1:total_frame)');
            allTranslation_2 = predict(gTranslation_2,(1:total_frame)');
            
            progressbarText(0);
            for i = 1:total_frame
                tform = simtform2d(allScale(i),allRotationAngle(i),[allTranslation_1(i) allTranslation_2(i)]);
                row_idx = app.mainapp.Ch2_loc_table.Frame == i;

                [u,v] = ...
                    transformPointsForward(tform,app.mainapp.Ch2_loc_table.xPix(row_idx),app.mainapp.Ch2_loc_table.yPix(row_idx));

                app.mainapp.Ch2_loc_table.regis_Xpos(row_idx) = u*app.mainapp.camPixelSize_Ch1.Value;                
                app.mainapp.Ch2_loc_table.regis_Ypos(row_idx) = v*app.mainapp.camPixelSize_Ch1.Value;
                progressbarText(i/total_frame);                               
            end
            disp('Alignment finished!')

            app.mainapp.ImageRegistrationStatus_Ch2.Color = [0 1 0];
            app.mainapp.PALMBinaryButton_Ch2.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Twocamalignmentcase1yougotseparatebeadsimagesPanel = uipanel(app.UIFigure);
            app.Twocamalignmentcase1yougotseparatebeadsimagesPanel.Title = 'Two cam alignment case1: you got separate beads images';
            app.BeadsimagefullpathbeforeEditFieldLabel = uilabel(app.Twocamalignmentcase1yougotseparatebeadsimagesPanel);
            app.BeadsimagefullpathbeforeEditFieldLabel.Text = 'Beads image full path (before)';
            app.BeadsimagefullpathbeforeEditField = uieditfield(app.Twocamalignmentcase1yougotseparatebeadsimagesPanel, 'text');
            app.BeadsimagefullpathbeforeEditField.Value = 'H:\2024\20240423_U2OS_RPB1Halo-H2BmEosEM_dualColor_MPALM\beads_L110R160_1545PM.tif';
            app.MPALMChEditFieldLabel = uilabel(app.Twocamalignmentcase1yougotseparatebeadsimagesPanel);
            app.MPALMChEditFieldLabel.Text = 'MPALM Ch#';
            app.MPALMChEditField = uieditfield(app.Twocamalignmentcase1yougotseparatebeadsimagesPanel, 'numeric');
            app.MPALMChEditField.Value = 1;
            app.PALMChEditFieldLabel = uilabel(app.Twocamalignmentcase1yougotseparatebeadsimagesPanel);
            app.PALMChEditFieldLabel.Text = 'PALM Ch#';
            app.PALMChEditField = uieditfield(app.Twocamalignmentcase1yougotseparatebeadsimagesPanel, 'numeric');
            app.PALMChEditField.Value = 2;
            app.LocalizationErrorLabel = uilabel(app.Twocamalignmentcase1yougotseparatebeadsimagesPanel);
            app.LocalizationErrorLabel.Text = 'Localization Error';
            app.LocalizationErrorEditField = uieditfield(app.Twocamalignmentcase1yougotseparatebeadsimagesPanel, 'numeric');
            app.LocalizationErrorEditField.Value = -16;
            app.RadiusToleranceEditFieldLabel = uilabel(app.Twocamalignmentcase1yougotseparatebeadsimagesPanel);
            app.RadiusToleranceEditFieldLabel.Text = 'Radius Tolerance';
            app.RadiusToleranceEditField = uieditfield(app.Twocamalignmentcase1yougotseparatebeadsimagesPanel, 'numeric');
            app.RadiusToleranceEditField.Value = 20;
            app.BeadsimagefullpathafterEditFieldLabel = uilabel(app.Twocamalignmentcase1yougotseparatebeadsimagesPanel);
            app.BeadsimagefullpathafterEditFieldLabel.Text = 'Beads image full path (after)';
            app.BeadsimagefullpathafterEditField = uieditfield(app.Twocamalignmentcase1yougotseparatebeadsimagesPanel, 'text');
            app.BeadsimagefullpathafterEditField.Value = 'H:\2024\20240423_U2OS_RPB1Halo-H2BmEosEM_dualColor_MPALM\beads_L110R160_1717PM.tif';
            app.AlignmentButton = uibutton(app.Twocamalignmentcase1yougotseparatebeadsimagesPanel, 'push');
            app.AlignmentButton.ButtonPushedFcn = createCallbackFcn(app, @AlignmentButtonPushed, true);
            app.AlignmentButton.Text = 'Alignment!';
            app.MinIntensityEditFieldLabel = uilabel(app.Twocamalignmentcase1yougotseparatebeadsimagesPanel);
            app.MinIntensityEditFieldLabel.Text = 'Min Intensity';
            app.MinIntensityEditField = uieditfield(app.Twocamalignmentcase1yougotseparatebeadsimagesPanel, 'numeric');
            app.MinIntensityEditField.Value = 100000;
            app.Twocamalignmentcase2youcoatbeadswithyoursamplePanel = uipanel(app.UIFigure);
            app.Twocamalignmentcase2youcoatbeadswithyoursamplePanel.Title = 'Two cam alignment case2: you coat beads with your sample';
            app.imagepathEditFieldLabel = uilabel(app.Twocamalignmentcase2youcoatbeadswithyoursamplePanel);
            app.imagepathEditFieldLabel.Text = 'image path';
            app.image_path = uieditfield(app.Twocamalignmentcase2youcoatbeadswithyoursamplePanel, 'text');
            app.image_path.Value = 'H:\2024\20240623_U2OS_FOXA2Halo-H2BmEosEM_dualColor_MPALM\20240623_Clust01_FOXA2-H2B_25nMPA646_30p5ms_10kframe_01.nd2';
            app.AlignmentButton_2 = uibutton(app.Twocamalignmentcase2youcoatbeadswithyoursamplePanel, 'push');
            app.AlignmentButton_2.ButtonPushedFcn = createCallbackFcn(app, @AlignmentButton_2Pushed, true);
            app.AlignmentButton_2.Text = 'Alignment!';
            app.UseframeLabel = uilabel(app.Twocamalignmentcase2youcoatbeadswithyoursamplePanel);
            app.UseframeLabel.Text = 'Use frame #';
            app.frameNum_inuse = uieditfield(app.Twocamalignmentcase2youcoatbeadswithyoursamplePanel, 'numeric');
            app.frameNum_inuse.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeadsAlign(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end