classdef CheckUNetDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        VisualizeTab          matlab.ui.container.Tab
        DualchannelCheckBox   matlab.ui.control.CheckBox
        LUTmax                matlab.ui.control.NumericEditField
        LUTmaxEditFieldLabel  matlab.ui.control.Label
        LUTmin                matlab.ui.control.NumericEditField
        LUTminEditFieldLabel  matlab.ui.control.Label
        NucleusID             matlab.ui.control.NumericEditField
        NucleusIDLabel        matlab.ui.control.Label
        LoadCellPSFButton     matlab.ui.control.Button
        cellPSFState          matlab.ui.control.Lamp
        camPixelSize          matlab.ui.control.NumericEditField
        CamerapixelsizenmEditFieldLabel  matlab.ui.control.Label
        VisualizeButton       matlab.ui.control.Button
        FrameSpinner          matlab.ui.control.Spinner
        FrameSpinnerLabel     matlab.ui.control.Label
        LoadnucleusROIButton  matlab.ui.control.Button
        nucleusROIState       matlab.ui.control.Lamp
        LoadThunderlocButton  matlab.ui.control.Button
        ThunderLocState       matlab.ui.control.Lamp
        ND2state              matlab.ui.control.Lamp
        LoadND2Button         matlab.ui.control.Button
        Tab2                  matlab.ui.container.Tab
    end

    
    properties (Access = private)
        ND2_file % Description
        ND2_path % Description
        nd2_matrix % Description
        thunderLoc_file % Description
        thunderLoc_path % Description
        thunderLocTable % Description
        cellPSF_path % Description
        cellPSF_file % Description
        cell_PSF % Description
        nucleusROI_path % Description
        nucleusROI_file % Description
        roi_info_nuc % Description
        fig1 % Description
        loc_data_nuclear_table % Description
    end
    
    methods (Access = private)
        
        function FIG_EXPORT_UMBX_largeFOV_v2(app,i_plane,  I,par_locTable, PSF_label, PSF_boundLabel,NucleusBoundary)
            % Find the bounding box
            xmin = min(NucleusBoundary(:, 1));
            ymin = min(NucleusBoundary(:, 2));
            width = max(NucleusBoundary(:, 1)) - xmin;
            height = max(NucleusBoundary(:, 2)) - ymin;
            
            int_xmin = floor(xmin);int_ymin = floor(ymin);int_width = ceil(width);int_height = ceil(height);
            
            crop_I = I(max(1,int_ymin):min(int_ymin+int_height,size(I,1)),max(1,int_xmin):min(int_xmin+int_width,size(I,2)));
            crop_NucleusBoundary = [NucleusBoundary(:,1)-xmin+1, NucleusBoundary(:,2)-ymin+1];
            crop_PSF_label = PSF_label(max(1,int_ymin):min(int_ymin+int_height,size(I,1)),max(1,int_xmin):min(int_xmin+int_width,size(I,2))); 
            crop_PSF_boundLabel = PSF_boundLabel(max(1,int_ymin):min(int_ymin+int_height,size(I,1)),max(1,int_xmin):min(int_xmin+int_width,size(I,2)));
            crop_loc_data_X = par_locTable.xPix - xmin + 0.5;
            crop_loc_data_Y = par_locTable.yPix - ymin + 0.5;
            
            % clf(app.fig1);
            app.fig1 = figure('Name',sprintf('Frame %d',i_plane));
            % locNum = loc_data.ctrsN(i_plane);
            % loc_idx_range = loc_start_idx:loc_start_idx+locNum-1;
            
            tiledlayout(2,2,'TileSpacing','none','Padding','compact')
                                                    
            %Plot raw image 
            nexttile;
            ax = gca(); 
            hold(ax, 'on');
            imshow(crop_I, 'Parent', ax, 'Border', 'tight');
            plot(crop_NucleusBoundary(:,1), crop_NucleusBoundary(:,2), '--g', 'LineWidth', 2);
            set(ax, 'YDir', 'reverse');
            hold(ax, 'off');
            title('Original Image');
            h = imcontrast(ax);
            uiwait(h);
            CLim = [ax.CLim(1) ax.CLim(2)];
            
            %Plot raw image with MTT localization
            nexttile;
            ax = gca(); hold(ax, 'on');
            imshow(crop_I, 'Parent', ax, 'Border', 'tight');
            scatter(ax, crop_loc_data_X+1,crop_loc_data_Y+1,10,'r','filled');
            plot(crop_NucleusBoundary(:,1), crop_NucleusBoundary(:,2), '--g', 'LineWidth', 2);
            set(ax, 'YDir', 'reverse');
            hold(ax, 'off');
            title('Original Image with MTT detections');
            ax.CLim =  CLim;
            
            adjusted_I = imadjust(uint16(crop_I), [min(CLim)/65535 max(CLim)/65535]);
            %Plot Mask Overlay over Original Image
            nexttile;
            ax = gca(); hold(ax, 'on');
            imshow(labeloverlay(adjusted_I,crop_PSF_label, 'Transparency',0.9),'Parent',ax, 'Border', 'tight');%BWnoboard 'Colormap',[0 1 0]
            scatter(ax, crop_loc_data_X+1,crop_loc_data_Y+1,10,'r','filled');
            plot(crop_NucleusBoundary(:,1), crop_NucleusBoundary(:,2), '--g', 'LineWidth', 2);

            stats = regionprops(crop_PSF_boundLabel,'Centroid');

            for k = 1 : size(stats,1)
               text(ax, stats(k).Centroid(1), stats(k).Centroid(2), num2str(k), 'Color', 'y', 'FontSize', 8);
            end

            set(ax, 'YDir', 'reverse');
            hold(ax, 'off');
            title('Mask Over Original Image');
            ax.CLim =  CLim;
            
            %Plot Outlined Masks over Original Image
            nexttile;
            ax = gca(); hold(ax, 'on');
            % BWoutline = bwperim(BWnoboard);
            imshow(labeloverlay(adjusted_I, crop_PSF_boundLabel),'Parent',ax, 'Border', 'tight');
            scatter(ax, crop_loc_data_X+1,crop_loc_data_Y+1,10,'r','filled'); %BWnoboard
            plot(crop_NucleusBoundary(:,1), crop_NucleusBoundary(:,2), '--g', 'LineWidth', 2);
            set(ax, 'YDir', 'reverse');
            hold(ax, 'off');
            title('Outlined Original Image');
            ax.CLim =  CLim;
            app.LUTmin.Value = CLim(1);
            app.LUTmin.Value = CLim(2);
                
            set(app.fig1, 'Position', [666,184,712,719], 'Units', 'pixels');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadND2Button
        function loadND2CallBack(app, event)
            close all; clc;
            app.ND2state.Color = [1 0 0];
            app.ThunderLocState.Color = [1 0 0];
            app.nucleusROIState.Color = [1 0 0];
            app.cellPSFState.Color = [1 0 0];
            app.FrameSpinner.Enable = false;
            app.VisualizeButton.Enable = false;
            
            [app.ND2_file,app.ND2_path] = uigetfile({'*.nd2'},...
                          'Select ND2 image file...');
                        
            if ~isempty(app.ND2_file) && ~app.DualchannelCheckBox.Value
                app.nd2_matrix = MemoryEfficientND2reader(fullfile(app.ND2_path,app.ND2_file));
                app.ND2state.Color = [0 1 0];
            elseif ~isempty(app.ND2_file) && app.DualchannelCheckBox.Value
                app.nd2_matrix = MemoryEfficientND2reader_2colors_selectOne(fullfile(app.ND2_path,app.ND2_file),2);
                app.ND2state.Color = [0 1 0];
            end
        end

        % Button pushed function: LoadThunderlocButton
        function loadThunderStormLocCallback(app, event)
            [app.thunderLoc_file,app.thunderLoc_path] = uigetfile({'*.csv'},...
                          'Select ThunderStorm localization file...');
                     
            if app.thunderLoc_file ~= 0 
                app.thunderLocTable = readtable(fullfile(app.thunderLoc_path,app.thunderLoc_file),'VariableNamingRule','preserve');
                app.thunderLocTable.xPix = app.thunderLocTable.("x [nm]")/app.camPixelSize.Value+1;
                app.thunderLocTable.yPix = app.thunderLocTable.("y [nm]")/app.camPixelSize.Value+1;
                cd(app.thunderLoc_path)
                app.ThunderLocState.Color = [0 1 0];
            end

        end

        % Button pushed function: LoadCellPSFButton
        function LoadCellPSFCallback(app, event)
            [app.cellPSF_file,app.cellPSF_path] = uigetfile({'*.mat'},...
                          'Select cell_PSF mat file...');
                     
            if app.cellPSF_file ~= 0 
                temp = load(fullfile(app.cellPSF_path,app.cellPSF_file),'cell_PSF');
                app.cell_PSF = temp.cell_PSF;clear temp;
                app.cellPSFState.Color = [0 1 0];
                app.FrameSpinner.Enable = true;
                app.VisualizeButton.Enable = true;
                cd(app.cellPSF_path)
            end
        end

        % Button pushed function: LoadnucleusROIButton
        function LoadnucleusROICallback(app, event)
            [app.nucleusROI_file,app.nucleusROI_path] = uigetfile({'*.mat'},...
                          'Select nucleus ROI mat file...');
                     
            if app.nucleusROI_file ~= 0 
                temp = load(fullfile(app.nucleusROI_path,app.nucleusROI_file),'roi_info_nuc');
                app.roi_info_nuc = temp.roi_info_nuc; clear temp;                             
                
                % in_roi_array = zeros(height(app.thunderLocTable),1);
                nuclear_mask = logical(poly2mask(app.roi_info_nuc{app.NucleusID.Value}(:,1),app.roi_info_nuc{app.NucleusID.Value}(:,2),...
                    size(app.nd2_matrix,1),size(app.nd2_matrix,2)));
                % for detection = 1:height(app.thunderLocTable) % this is to only get the localization within the mask.
                %     % Currently we keep mask fixed across the frames
                %     if nuclear_mask(round(app.thunderLocTable.yPix(detection)),round(app.thunderLocTable.xPix(detection))) == 1
                %         in_roi_array(detection) = 1;
                % 
                %     end
                % end
                % app.loc_data_nuclear_table = app.thunderLocTable(logical(in_roi_array),:);
                
                yPix_rounded = round(app.thunderLocTable.yPix);
                xPix_rounded = round(app.thunderLocTable.xPix);
                linear_indices = sub2ind(size(nuclear_mask), yPix_rounded, xPix_rounded);
                in_roi_array = nuclear_mask(linear_indices);
                app.loc_data_nuclear_table = app.thunderLocTable(logical(in_roi_array),:);

                app.nucleusROIState.Color = [0 1 0];
                cd(app.nucleusROI_path)
            end
        end

        % Button pushed function: VisualizeButton
        function visualizeCallback(app, event)
            
            % cell_PSF_label = app.cell_PSF.label; % can use to draw masks
            % cell_PSF_boundLabel = app.cell_PSF.boundLabel; % can use to draw mask outline
            % in_roi_array = zeros(height(app.thunderLocTable),1);
            % nuclear_mask = logical(poly2mask(app.roi_info_nuc{app.NucleusID.Value}(:,1),app.roi_info_nuc{app.NucleusID.Value}(:,2),...
            %     size(app.nd2_matrix,1),size(app.nd2_matrix,2)));
            % for detection = 1:height(app.thunderLocTable) % this is to only get the localization within the mask.
            %     % Currently we keep mask fixed across the frames
            %     if nuclear_mask(round(app.thunderLocTable.yPix(detection)),round(app.thunderLocTable.xPix(detection))) == 1
            %         in_roi_array(detection) = 1;
            % 
            %     end
            % end
            % loc_data_nuclear_table = app.thunderLocTable(logical(in_roi_array),:);
            par_locTable = app.loc_data_nuclear_table(app.loc_data_nuclear_table.frame == app.FrameSpinner.Value,:);
            temp_roi_info_nuc = app.roi_info_nuc{app.NucleusID.Value};
            I = app.nd2_matrix(:,:,app.FrameSpinner.Value);
            FIG_EXPORT_UMBX_largeFOV_v2(app,app.FrameSpinner.Value, I,par_locTable, ...
                app.cell_PSF.label{app.FrameSpinner.Value}, app.cell_PSF.boundLabel{app.FrameSpinner.Value},temp_roi_info_nuc);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VisualizeTab = uitab(app.TabGroup);
            app.VisualizeTab.Title = 'Visualize';
            app.LoadND2Button = uibutton(app.VisualizeTab, 'push');
            app.LoadND2Button.ButtonPushedFcn = createCallbackFcn(app, @loadND2CallBack, true);
            app.LoadND2Button.Text = 'Load ND2';
            app.ND2state = uilamp(app.VisualizeTab);
            app.ThunderLocState = uilamp(app.VisualizeTab);
            app.LoadThunderlocButton = uibutton(app.VisualizeTab, 'push');
            app.LoadThunderlocButton.ButtonPushedFcn = createCallbackFcn(app, @loadThunderStormLocCallback, true);
            app.LoadThunderlocButton.Text = 'Load Thunder loc';
            app.nucleusROIState = uilamp(app.VisualizeTab);
            app.LoadnucleusROIButton = uibutton(app.VisualizeTab, 'push');
            app.LoadnucleusROIButton.ButtonPushedFcn = createCallbackFcn(app, @LoadnucleusROICallback, true);
            app.LoadnucleusROIButton.Text = 'Load nucleus ROI';
            app.FrameSpinnerLabel = uilabel(app.VisualizeTab);
            app.FrameSpinnerLabel.Enable = 'off';
            app.FrameSpinnerLabel.Text = 'Frame';
            app.FrameSpinner = uispinner(app.VisualizeTab);
            app.FrameSpinner.Enable = 'off';
            app.FrameSpinner.Value = 1;
            app.VisualizeButton = uibutton(app.VisualizeTab, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @visualizeCallback, true);
            app.VisualizeButton.Enable = 'off';
            app.VisualizeButton.Text = 'Visualize!';
            app.CamerapixelsizenmEditFieldLabel = uilabel(app.VisualizeTab);
            app.CamerapixelsizenmEditFieldLabel.Text = 'Camera pixel size (nm)';
            app.camPixelSize = uieditfield(app.VisualizeTab, 'numeric');
            app.camPixelSize.Value = 110;
            app.cellPSFState = uilamp(app.VisualizeTab);
            app.LoadCellPSFButton = uibutton(app.VisualizeTab, 'push');
            app.LoadCellPSFButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCellPSFCallback, true);
            app.LoadCellPSFButton.Text = 'Load cell_PSF';
            app.NucleusIDLabel = uilabel(app.VisualizeTab);
            app.NucleusIDLabel.Text = 'Nucleus ID';
            app.NucleusID = uieditfield(app.VisualizeTab, 'numeric');
            app.NucleusID.Value = 1;
            app.LUTminEditFieldLabel = uilabel(app.VisualizeTab);
            app.LUTminEditFieldLabel.Text = 'LUT min';
            app.LUTmin = uieditfield(app.VisualizeTab, 'numeric');
            app.LUTmin.Value = 8000;
            app.LUTmaxEditFieldLabel = uilabel(app.VisualizeTab);
            app.LUTmaxEditFieldLabel.Text = 'LUT max';
            app.LUTmax = uieditfield(app.VisualizeTab, 'numeric');
            app.LUTmax.Value = 10000;
            app.DualchannelCheckBox = uicheckbox(app.VisualizeTab);
            app.DualchannelCheckBox.Text = 'Dual channel';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CheckUNetDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end