classdef SeqDriftCorrectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SequantialMPALMDriftCorrectionUIFigure  matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Method1Tab                     matlab.ui.container.Tab
        totalFrame_1                   matlab.ui.control.NumericEditField
        totalframesLabel               matlab.ui.control.Label
        smoothrange                    matlab.ui.control.NumericEditField
        SmoothrangeEditFieldLabel      matlab.ui.control.Label
        padding                        matlab.ui.control.NumericEditField
        PaddingEditFieldLabel          matlab.ui.control.Label
        sigma_blur                     matlab.ui.control.NumericEditField
        SigmablurEditFieldLabel        matlab.ui.control.Label
        image_pixel_size               matlab.ui.control.NumericEditField
        ImagepixelsizeEditFieldLabel   matlab.ui.control.Label
        bulk_path                      matlab.ui.control.EditField
        BulkimagefolderpathEditFieldLabel  matlab.ui.control.Label
        bulk_prefix                    matlab.ui.control.EditField
        BulkimageprefixEditFieldLabel  matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        ParametersofdriftcorrectionsforsequantialMPALMLabel  matlab.ui.control.Label
        Method2Tab                     matlab.ui.container.Tab
        gap_frame_num                  matlab.ui.control.NumericEditField
        totalframesLabel_2             matlab.ui.control.Label
        bulk_path_2                    matlab.ui.control.EditField
        BulkimagefolderpathEditFieldLabel_2  matlab.ui.control.Label
        bulk_prefix_2                  matlab.ui.control.EditField
        BulkimageprefixEditFieldLabel_2  matlab.ui.control.Label
        RunButton_2                    matlab.ui.control.Button
        ParametersofdriftcorrectionsforsequantialMPALMWesteretalLabel  matlab.ui.control.Label
        Method3Tab                     matlab.ui.container.Tab
        Label                          matlab.ui.control.Label
        totalFrame_3                   matlab.ui.control.NumericEditField
        totalframesLabel_3             matlab.ui.control.Label
        RunButton_3                    matlab.ui.control.Button
        drift_Smoothrange              matlab.ui.control.NumericEditField
        SmoothrangeEditFieldLabel_2    matlab.ui.control.Label
        drift_Padding                  matlab.ui.control.NumericEditField
        PaddingEditFieldLabel_2        matlab.ui.control.Label
        drift_Sigmablur                matlab.ui.control.NumericEditField
        SigmablurEditFieldLabel_2      matlab.ui.control.Label
        drift_Binpixelsize             matlab.ui.control.NumericEditField
        BinpixelsizeEditFieldLabel     matlab.ui.control.Label
        drift_Gapframe                 matlab.ui.control.NumericEditField
        GapframeEditFieldLabel         matlab.ui.control.Label
        drift_Binframe                 matlab.ui.control.NumericEditField
        BinframeEditFieldLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainapp % Main app object
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function ScatterDriftCorrection4(app)
            % params.binFrame; % default: 7,000
            params.image_pixel_size = app.image_pixel_size.Value; % default: 160nm/pixel
            params.sigma_blur = app.sigma_blur.Value; % default: 11
            params.padding = app.padding.Value; % default: 15
            params.totalFrame = app.totalFrame_1.Value;            
            params.smoothrange = app.smoothrange.Value;            
            root_path = app.bulk_path.Value;
            image_seq = dir(fullfile(root_path, sprintf('%s*.nd2',app.bulk_prefix.Value)));
            
            % parameter initiation
            
            totalFrame = params.totalFrame; % default: 30000
            image_pixel_size = app.mainapp.camPixelSize_Ch1.Value;% params.image_pixel_size; % unit: nm/pixel
            sigma_blur = params.sigma_blur; % default: 11
            padding = params.padding; % default: 15
            smoothrange = params.smoothrange; % default: 1

            % drift_frame = [1:1000:totalFrame totalFrame];
            drift_frame = linspace(1,totalFrame+1,length(image_seq));
                        
            % Gaussian fitting, following params kept fixed
            xfit0 = [100,0,50,0,50,0];
            [xx,yy] = meshgrid(-padding:padding);
            xdata = zeros(size(xx,1),size(yy,2),2);
            xdata(:,:,1) = xx;
            xdata(:,:,2) = yy;
            lb = [0,-padding,0,-padding,0,-pi/4];
            ub = [realmax('double'),padding,padding^2,padding,padding^2,pi/4];
            opt = optimset('Display','off');
            
            drift1 = zeros(length(image_seq), 2); % (drift_x, drift_y), 1 <=> N
            image0 = bfopen(fullfile(image_seq(1).folder,image_seq(1).name));
            image0 = image0{1}{1,1};
            
            for iSlice = 2:length(image_seq)
                
                image_next = bfopen(fullfile(image_seq(iSlice).folder,image_seq(iSlice).name));
                image_next = image_next{1}{1,1};
            
                % cross-correlation between stack 1 and stack N
                c = normxcorr2(image0, image_next); 
                cblur = imgaussfilt(c,sigma_blur);
                % if ~app.is_brightfield.Value
                %     cblur = imgaussfilt(c,sigma_blur);
                % else
                %     cblur = c;
                % end
            
                % find local center max centered by last frame peak
                if iSlice == 2
                    [ypeak,xpeak] = find(cblur==max(cblur(:)));
                    temp_ypeak = ypeak; temp_xpeak = xpeak;

                    fig1 = figure;imshow(cblur,[]);hold on;scatter(xpeak,ypeak,'red');
                    title('Cross-correlation map, adjust sigma_blur to make it close to center','Interpreter','none')

                    answer = questdlg('Do you satisfy the peak position', ...
	                    'Confirmation');
                    % Handle response
                    switch answer
                        case 'Yes'
                            app.mainapp.SeqDriftState = true;
                        case 'No'
                            app.mainapp.SeqDriftState = false;
                            return;                       
                    end

                else 
                    % Define the region centered by temp_xpeak and temp_ypeak with padding
                    half_width = padding;  % replace 'padding' with the actual value.
                    
                    % Ensure indices are within image boundaries
                    x1 = max(1, temp_xpeak - half_width);
                    x2 = min(size(cblur, 2), temp_xpeak + half_width);
                    y1 = max(1, temp_ypeak - half_width);
                    y2 = min(size(cblur, 1), temp_ypeak + half_width);
                
                    % Take a subregion of cblur
                    sub_cblur = cblur(y1:y2, x1:x2);
                
                    % Find the max pixel in subregion
                    [sub_ypeak,sub_xpeak] = find(sub_cblur==max(sub_cblur(:)));
                
                    % Correct the coordinates for the offset due to subregioning
                    ypeak = y1 + sub_ypeak - 1; 
                    xpeak = x1 + sub_xpeak - 1;
                    temp_ypeak = ypeak; temp_xpeak = xpeak;
                end
                % gaussian fitting
                ccrop = cblur((ypeak-padding):(ypeak+padding), (xpeak-padding):(xpeak+padding));
                ccrop = ccrop./max(ccrop(:)).*100;
                [xfit,~,~,~] = lsqcurvefit(@D2GaussFunctionRot,xfit0,xdata,ccrop,lb,ub,opt);
                drift1(iSlice, :) = size(image_next, [2, 1]) - [xpeak+xfit(2), ypeak+xfit(4)]; % unit: pixel (default: 30nm)
            
            end
            
            drift1 = horzcat(drift_frame', drift1*image_pixel_size); % (frame, drift_x, drift_y)
            
            % Local Polynomial Regression
            drift1_predict = zeros(totalFrame, 3); % (frame, drift_x, drift_y) 
            Xq = (1:totalFrame)'; drift1_predict(:, 1) = Xq; 
            drift1_predict(:, 2) = locpoly(drift1(:, 1), drift1(:, 2), Xq, smoothrange, 1);
            drift1_predict(:, 3) = locpoly(drift1(:, 1), drift1(:, 3), Xq, smoothrange, 1);

            app.mainapp.Ch1_drift1_predict = drift1_predict;
            app.mainapp.driftCorrectionStatus_Ch1.Color = [0 1 0];
            
            axismin = min([drift1_predict(:, 2); drift1_predict(:, 3)])-20; 
            axismax = max([drift1_predict(:, 2); drift1_predict(:, 3)])+20;
            
            fig2 = figure('Visible','on','Name','Estimated lateral drift');
            hold on;
            plot(drift1(:, 1), drift1(:, 2), 'o');
            plot(drift1(:, 1), drift1(:, 3), 'x');
            e1 = plot(drift1_predict(:, 1), drift1_predict(:, 2));
            e2 = plot(drift1_predict(:, 1), drift1_predict(:, 3));
            hold off;
            xlabel('Frame');
            ylabel('Dirft (nm)');
            legend([e1, e2], {'x drift', 'y drift'});
            title('Relative drift to the first frame', 'FontWeight','bold');
            ylim([axismin, axismax]);
            
            %% AUXILIARY FUNCTION
            function F = D2GaussFunctionRot(x,xdata)
            % This function is from Gero Nootz (2023). Fit 2D gaussian function to data
            % (https://www.mathworks.com/matlabcentral/fileexchange/37087-fit-2d-gaussian-function-to-data),
            % MATLAB Central File Exchange. Retrieved October 17, 2023.
                %% x = [Amp, x0, wx, y0, wy, fi]
                %[X,Y] = meshgrid(x,y) 
                %  xdata(:,:,1) = X
                %  xdata(:,:,2) = Y           
                % Mrot = [cos(fi) -sin(fi); sin(fi) cos(fi)]
                %%
                xdatarot(:,:,1)= xdata(:,:,1)*cos(x(6)) - xdata(:,:,2)*sin(x(6));
                xdatarot(:,:,2)= xdata(:,:,1)*sin(x(6)) + xdata(:,:,2)*cos(x(6));
                x0rot = x(2)*cos(x(6)) - x(4)*sin(x(6));
                y0rot = x(2)*sin(x(6)) + x(4)*cos(x(6));
                F = x(1)*exp(   -((xdatarot(:,:,1)-x0rot).^2/(2*x(3)^2) + (xdatarot(:,:,2)-y0rot).^2/(2*x(5)^2) )    );
                % figure(3)
                % alpha(0)
                % imagesc(F)
                % colormap('gray')
                % figure(gcf)%bring current figure to front
                % drawnow
                % beep
                % pause %Wait for keystroke
            end
            
            function [yhat] = locpoly(x,y,x0,h,d)
            % LOCPOLY Local polynomial regression.
            %   YHAT = LOCPOLY(X,Y,X0,H,D) returns the value of the local polynomial
            %   regression of degree D at X0 using a Gaussian kernel with bandwidth H.
            %
            %   X and Y are vectors of equal length containing the data points.
            %
            %   Example:
            %      x = linspace(-3,3,100)';
            %      y = exp(-x.^2) + randn(size(x))*0.3;
            %      yhat = locpoly(x,y,x,0.5,2);
            %      plot(x,y,'.',x,yhat,'-')
            %
            %   See also LOWESS.
            
                n = length(x);
                yhat = zeros(size(x0));
                for i=1:length(x0)
                    w = exp(-(x-x0(i)).^2/(2*h^2));
                    X = [ones(n,1) x-x0(i)];
                    for j=2:d
                        X(:,j+1) = (x-x0(i)).^j;
                    end
                    b = (X'*diag(w)*X)\(X'*diag(w)*y);
                    yhat(i) = b(1);
            %         for j=2:d+1
            %             yhat(i) = yhat(i) + b(j)*(x0(i)-x).^(j-1);
            %         end
                end
            end

        end
        
        function [PixelOffset, SubPixelOffset, CorrData, MaxOffset] = ...
            findStackOffset(app,Stack1, Stack2, MaxOffset, FitOffset, ...
            BinaryMask, PlotFlag, UseGPU,requireZ)            
            %findStackOffset estimates a sub-pixel offset between two stacks of images.
            % findStackoffset() will estimate the offset between two 3D stacks of
            % images.  This method computes an integer pixel offset between the two
            % stacks via a cross-correlation and then fits 2nd order polynomials to the 
            % resulting cross-correlation.  An estimate of a sub-pixel offset is then 
            % produced by determining the location of the peaks of the three (x, y, z)
            % 2nd order polynomial fits.
            %
            % NOTE: The convention used here for the offset is based on indices as
            %       follows: If Stack is a 3D stack of images, and
            %       Stack1 = Stack(m:n, m:n, m:n) 
            %       Stack2 = Stack((m:n)+x, (m:n)+y, (m:n)+z)
            %       then PixelOffset = findStackOffset(Stack1, Stack2) == [x; y; z]
            % NOTE: All inputs besides Stack1 and Stack2 are optional and can be
            %       replaced by [] (an empty array).
            % NOTE: Stack1 and Stack2 must be the same size in all 3 dimensions (x, y,
            %       and z)
            %
            % INPUTS:
            %   Stack1: (mxnxo) The stack to which Stack2 is compared to, i.e. 
            %           Stack1 is the reference stack.
            %   Stack2: (mxnxo) The stack for which the offset relative to Stack1 
            %           is to be determined.
            %   MaxOffset: (3x1 or 1x3)(default = [2; 2; 2]) Maximum offset between 
            %           Stack1 and Stack2 to be considered in the calculation of
            %           PixelOffset and SubPixelOffset.
            %   FitOffset: (3x1 or 1x3)(default = [2; 2; 2]) Maximum offset from the
            %           peak of the cross-correlation curve for which data will be fit
            %           to determine SubPixelOffset.
            %   BinaryMask: (mxnxo)(default = ones(m, n, o)) Mask to multiply the
            %           stacks with before computing to cross-correlation.
            %   PlotFlag: (boolean)(default = true) Specifies whether or not the 1D 
            %           line plots through the peak of the xcorr will be shown.  
            %           PlotFlag = true will allow the plots to be shown, 
            %           PlotFlag = false will not allow plots to be displayed.
            %   UseGPU: (boolean)(default = logical(gpuDeviceCount()))
            %
            % OUTPUTS:
            %   PixelOffset: (3x1)(integer) The integer pixel offset of Stack2 relative
            %           to Stack1, determined based on the location of the peak of the
            %           xcorr coefficient field between the two stacks.
            %   SubPixelOffset: (3x1)(float) The sub-pixel offset of Stack2 relative to
            %           Stack1, approximated based on a 2nd order polynomial fit(s) to 
            %           the cross-correlation.
            %   CorrData: (struct) Structured array containing the scaled
            %             cross-correlation corresponding to MaxOffset as well as the
            %             fitting results which were used to determine SubPixelOffset.
            %   MaxOffset: (3x1 or 1x3) Maximum offset between Stack1 and Stack2 
            %           considered in the calculation of PixelOffset and 
            %           SubPixelOffset.  This is returned because the user input value
            %           of MaxOffset is truncated if too large. 
            % 
            % REQUIRES:
            %   MATLAB Parallel Computing Toolbox (if setting UseGPU = 1)
            %   Supported NVIDIA CUDA GPU (if setting UseGPU = 1)
            %
            % CITATION:
            
            % Created by:
            %   David J. Schodt (LidkeLab 2018)
            
            
            % Set default parameter values if needed.
            if ~exist('MaxOffset', 'var') || isempty(MaxOffset)
                MaxOffset = [5; 5; 5];
            end
            if ~exist('FitOffset', 'var') || isempty(FitOffset)
                FitOffset = [2; 2; 2];
            end
            if ~exist('PlotFlag', 'var') || isempty(PlotFlag)
                PlotFlag = false;
            end
            if ~exist('BinaryMask', 'var') || isempty(BinaryMask)
                BinaryMask = ones(size(Stack1));
                if (size(Stack1, 3) == 1) || (size(Stack2, 3) == 1)
                    % One or both of the stacks are just a single image, so we need to
                    % change the size of the BinaryMask to account for that (single
                    % images are converted to a 3D stack later on by copying the image
                    % twice in the z dimension).
                    BinaryMask = repmat(BinaryMask, [1, 1, 2]);
                end
            end
            if ~exist('UseGPU', 'var') || isempty(UseGPU)
                UseGPU = logical(gpuDeviceCount());
            end
            
            if ~exist('requireZ', 'var') || isempty(requireZ)
                requireZ = false;
            end
            
            % Ensure MaxOffset is a column vector for consistency.
            if size(MaxOffset, 1) < size(MaxOffset, 2)
                MaxOffset = MaxOffset.';
            end
            
            % Check if the stacks are actually stacks (i.e. multiple images in each
            % stack).  If they are not, copy the given image to match the stack size of
            % the other stack.
            NImagesStack1 = size(Stack1, 3);
            NImagesStack2 = size(Stack2, 3);
            if (NImagesStack1 == 1) || (NImagesStack2 == 1)    
                % One of the two stacks is just a single image, copy that image to
                % match the size of the other stack.
                if NImagesStack1 < NImagesStack2
                    % Stack1 is a single image: create copies of this image to ensure
                    % Stack1 and Stack2 are the same size.
                    Stack1 = repmat(Stack1, [1, 1, NImagesStack2]);
                elseif NImagesStack1 > NImagesStack2
                    % Stack2 is a single image: create copies of this image to ensure
                    % Stack1 and Stack2 are the same size.
                    Stack2 = repmat(Stack2, [1, 1, NImagesStack1]);
                else
                    % Both stacks contain only a single image.  Make these stacks of
                    % two images so that we can still proceed with the shift finding in
                    % the x and y dimensions.
                    Stack1 = repmat(Stack1, [1, 1, 2]);
                    Stack2 = repmat(Stack2, [1, 1, 2]);
                end
                
                % We no longer care about the z shift, so change the third element of
                % MaxOffset to 0 to reduce computation time.
                MaxOffset(3) = 0;
            end
            
            % Convert the stacks to gpuArrays if needed.
            if UseGPU
                Stack1 = gpuArray(Stack1);
                Stack2 = gpuArray(Stack2);
                BinaryMask = gpuArray(BinaryMask);
            end
            
            % Ensure the stacks are floating point arrays.
            % NOTE: If using the GPU, we should convert to single after sending the
            %       stacks to the GPU with gpuArray() (it's faster this way).
            Stack1 = single(Stack1);
            Stack2 = single(Stack2);
            
            % Determine dimensions relevant to the problem to improve code readability.
            Stack1Size = size(Stack1).';
            Stack2Size = size(Stack2).';
            SizeOfFullXCorr = Stack1Size + Stack2Size - 1; % size of a full xcorr stack
            
            % Ensure that the MaxOffset input is valid, modifying it's values if
            % needed.
            % NOTE: This is just ensuring that the MaxOffset corresponds to shifts
            %       between the two stacks that still maintain some overlap.
            IndicesToModify = find(MaxOffset > floor(SizeOfFullXCorr / 2)).';
            for ii = IndicesToModify
                warning('MaxOffset(%i) = %g is too big and was reset to %i', ...
                    ii, MaxOffset(ii), floor(SizeOfFullXCorr(ii) / 2))
                MaxOffset(ii) = floor(SizeOfFullXCorr(ii) / 2);
            end
            
            % Scale each image in each stack by intensity to reduce linear trends in 
            % the cross-correlation.
            for ii = 1:Stack1Size(3)
                Stack1(:, :, ii) = Stack1(:, :, ii) / sum(sum(Stack1(:, :, ii)));
            end
            for ii = 1:Stack2Size(3)
                Stack2(:, :, ii) = Stack2(:, :, ii) / sum(sum(Stack2(:, :, ii)));
            end
            
            % Define the indices within a full cross-correlation (size SizeOfFullXCorr)
            % that we wish to inspect.
            CorrOffsetIndicesX = max(ceil(SizeOfFullXCorr(1)/2) - MaxOffset(1), 1) ...
                : ceil(SizeOfFullXCorr(1)/2) + MaxOffset(1);
            CorrOffsetIndicesY = max(ceil(SizeOfFullXCorr(2)/2) - MaxOffset(2), 1) ...
                : ceil(SizeOfFullXCorr(2)/2) + MaxOffset(2);
            CorrOffsetIndicesZ = max(ceil(SizeOfFullXCorr(3)/2) - MaxOffset(3), 1) ...
                : ceil(SizeOfFullXCorr(3)/2) + MaxOffset(3);
            
            % Whiten each image in the stack with respect to the entire stack, ignoring
            % the parts which are covered by the BinaryMask when computing mean, std., 
            % etc.
            Stack1Masked = Stack1(logical(BinaryMask));
            Stack2Masked = Stack2(logical(BinaryMask));
            Stack1Whitened = (Stack1 - mean(Stack1Masked)) ...
                / (std(Stack1Masked) * sqrt(numel(Stack1Masked) - 1));
            Stack2Whitened = (Stack2 - mean(Stack2Masked)) ...
                / (std(Stack2Masked) * sqrt(numel(Stack2Masked) - 1));
            
            % Re-apply the binary mask to ensure the masked points cannot contribute to
            % the cross-correlation.
            Stack1Whitened = BinaryMask .* Stack1Whitened;
            Stack2Whitened = BinaryMask .* Stack2Whitened;
            
            % Compute the 3D FFT's of each stack, padding with zeros before computing.
            % The padding size selected such that the result is approximately 
            % equivalent to the brute forced cross-correlation.
            % NOTE: Typically, we would pad to 2*size(Stack)-1, however using
            %       2*size(Stack) will improve the performance of the FFT when the 
            %       dimensions of Stack are powers of 2.
            Stack1PaddedFFT = fftn(Stack1Whitened, 2 * size(Stack1Whitened));
            Stack2PaddedFFT = fftn(Stack2Whitened, 2 * size(Stack2Whitened));
            
            % Compute the 3D cross-correlation in the Fourier domain.
            XCorr3D = ifftn(conj(Stack1PaddedFFT) .* Stack2PaddedFFT);
            
            % Compute the binary cross-correlation for later use in scaling.
            Stack1Binary = BinaryMask .* ones(size(Stack1Whitened));
            Stack2Binary = BinaryMask .* ones(size(Stack2Whitened));
            Stack1BinaryFFT = fftn(Stack1Binary, 2 * size(Stack1Whitened));
            Stack2BinaryFFT = fftn(Stack2Binary, 2 * size(Stack2Whitened));
            XCorr3DBinary = ifftn(conj(Stack1BinaryFFT) .* Stack2BinaryFFT);
            
            % Scale the 3D cross-correlation by the cross-correlation of the
            % zero-padded binary images (an attempt to reduce the bias to a [0, 0, 0]
            % offset introduced by the zero-padded edge effects), scaling by
            % max(XCorr3DBinary(:)) to re-convert to a correlation coefficient.
            XCorr3D = (XCorr3D ./ XCorr3DBinary) * max(XCorr3DBinary(:));
            
            % Shift the cross-correlation image such that an auto-correlation image 
            % will have it's energy peak at the center of the 3D image.
            XCorr3D = circshift(XCorr3D, size(Stack1Whitened) - 1);
            
            % Isolate the central chunk of the cross-correlation.
            XCorr3D = real(XCorr3D(CorrOffsetIndicesX, ...
                CorrOffsetIndicesY, ...
                CorrOffsetIndicesZ));
            
            % Fetch the cross-correlation result from the GPU (if needed).
            if UseGPU
                XCorr3D = gather(XCorr3D);
            end
            
            % Stack the 3D xcorr cube into a 1D array.  
            % NOTE: MATLAB stacks columns in each 2D array (dimensions 1 and 2) then 
            %       stacks the resulting columns along the third dimension, e.g. when
            %       Array(:, :, 1) = [1, 3; 2, 4] and Array(:, :, 2) = [5, 7; 6, 8], 
            %       Array(:) = [1, 2, 3, 4, 5, 6, 7, 8].' 
            StackedCorrCube = XCorr3D(:);
            
            % Determine the integer offset between the two stacks.
            [~, IndexOfMax] = max(StackedCorrCube);
            [PeakRow, PeakColumn, PeakHeight] = ind2sub(size(XCorr3D), IndexOfMax);
            RawOffsetIndices = [PeakRow; PeakColumn; PeakHeight];
            
            % Compute the integer offset between the two stacks.  The additional minus
            % sign was just a chosen convention for the output of this code (see note 
            % at top of this code).
            % NOTE: We subtract MaxOffset+1 because that is the location of the 
            %       [0, 0, 0] offset (the center of the cross-correlation).
            PixelOffset = -(RawOffsetIndices - MaxOffset - 1);
            
            % Fit a second order polynomial through a line varying with x at the peak
            % of the cross-correlation in y, z, and use that polynomial to predict an
            % offset.  If possible, center the fit around the integer peak of the
            % cross-correlation.
            XArray = (max(1, RawOffsetIndices(1)-FitOffset(1)) ...
                : min(2*MaxOffset(1)+1, RawOffsetIndices(1)+FitOffset(1))).';
            XData = XCorr3D(XArray, RawOffsetIndices(2), RawOffsetIndices(3));
            X = [ones(numel(XArray), 1), XArray, XArray.^2];
            Beta = ((X.'*X) \ X.') * XData; % this is just least-squares fitting
            RawOffsetFitX = -Beta(2) / (2 * Beta(3)); % x at peak of the 2D polynomial
            PolyFitFunctionX = @(R) Beta(1) + Beta(2)*R + Beta(3)*R.^2;
            
            % Fit a second order polynomial through a line varying with y
            % at the peak of the cross-correlation in x, z.
            YArray = (max(1, RawOffsetIndices(2)-FitOffset(2)) ...
                : min(2*MaxOffset(2)+1, RawOffsetIndices(2)+FitOffset(2))).';
            YData = ...
                XCorr3D(RawOffsetIndices(1), YArray, RawOffsetIndices(3)).';
            X = [ones(numel(YArray), 1), YArray, YArray.^2];
            Beta = ((X.'*X) \ X.') * YData; % this is just least-squares fitting
            RawOffsetFitY = -Beta(2) / (2 * Beta(3)); % y at peak of the 2D polynomial
            PolyFitFunctionY = @(R) Beta(1) + Beta(2)*R + Beta(3)*R.^2;
            
            % ZW add to check if Z is available
            if requireZ
                % Fit a second order polynomial through a line varying with z
                % at the peak of the cross-correlation in x, y.
                ZArray = (max(1, RawOffsetIndices(3)-FitOffset(3)) ...
                    : min(2*MaxOffset(3)+1, RawOffsetIndices(3)+FitOffset(3))).';
                ZData = squeeze(...
                    XCorr3D(RawOffsetIndices(1), RawOffsetIndices(2), ZArray));
                X = [ones(numel(ZArray), 1), ZArray, ZArray.^2];
                Beta = ((X.'*X) \ X.') * ZData; % this is just least-squares fitting
                RawOffsetFitZ = -Beta(2) / (2 * Beta(3)); % z at peak of the 2D polynomial
                PolyFitFunctionZ = @(R) Beta(1) + Beta(2)*R + Beta(3)*R.^2;
                
                % Create arrays of the polynomial fits to use for visualization later on.
                XArrayDense = linspace(XArray(1), XArray(end), size(Stack1, 1));
                YArrayDense = linspace(YArray(1), YArray(end), size(Stack1, 1));
                ZArrayDense = linspace(ZArray(1), ZArray(end), size(Stack1, 1));
                XFitAtPeak = PolyFitFunctionX(XArrayDense);
                YFitAtPeak = PolyFitFunctionY(YArrayDense);
                ZFitAtPeak = PolyFitFunctionZ(ZArrayDense);
                
            else  
                % Create arrays of the polynomial fits to use for visualization later on.
                XArrayDense = linspace(XArray(1), XArray(end), size(Stack1, 1));
                YArrayDense = linspace(YArray(1), YArray(end), size(Stack1, 1));    
                XFitAtPeak = PolyFitFunctionX(XArrayDense);
                YFitAtPeak = PolyFitFunctionY(YArrayDense);
                ZFitAtPeak = YFitAtPeak; % duplicate Y data to Z
                RawOffsetFitZ = RawOffsetFitY;
                ZArrayDense = YArrayDense;
            end
            
            % Compute the predicted offset based on the polynomial fits.
            RawOffsetFit = [RawOffsetFitX; RawOffsetFitY; RawOffsetFitZ];
                    
            % Determine the predicted offset between the stack.  The additional minus
            % sign was just a chosen convention for the output of this code (see note 
            % at top of this code).
            % NOTE: We subtract MaxOffset+1 because that is the location of the 
            %       [0, 0, 0] offset (the center of the cross-correlation).
            SubPixelOffset = -(RawOffsetFit - MaxOffset - 1);
            
            % Populate the CorrData struct with information that we might wish to use
            % later.
            CorrData.XCorr3D = XCorr3D;
            CorrData.XFitAtPeak = XFitAtPeak;
            CorrData.YFitAtPeak = YFitAtPeak;
            CorrData.ZFitAtPeak = ZFitAtPeak;
            
            % Display line sections through the integer location of the
            % cross-correlation, overlain on the fit along those lines.
            if PlotFlag
                PlotFigure = findobj('Tag', 'CorrWindow');
                if isempty(PlotFigure)
                    PlotFigure = figure('Tag', 'CorrWindow');
                end
                clf(PlotFigure); % clear the figure window
                figure(PlotFigure); % ensure we plot into the correct figure
                subplot(3, 1, 1)
                plot(-MaxOffset(1):MaxOffset(1), ...
                    XCorr3D(:, RawOffsetIndices(2), RawOffsetIndices(3)), 'x')
                hold('on')
                plot(XArrayDense-MaxOffset(1)-1, XFitAtPeak)
                title('X Correlation')
                subplot(3, 1, 2)
                plot(-MaxOffset(2):MaxOffset(2), ...
                    XCorr3D(RawOffsetIndices(1), :, RawOffsetIndices(3)), 'x')
                hold('on')
                plot(YArrayDense-MaxOffset(2)-1, YFitAtPeak)
                title('Y Correlation')
                ylabel('Correlation Coefficient')
                if requireZ
                    subplot(3, 1, 3)
                    plot(-MaxOffset(3):MaxOffset(3), ...
                        squeeze(XCorr3D(RawOffsetIndices(1), RawOffsetIndices(2), :)), 'x')
                    hold('on')
                    plot(ZArrayDense-MaxOffset(3)-1, ZFitAtPeak)
                    title('Z Correlation')
                    xlabel('Pixel Offset')
                end
            end            
        end % end function
        
        function [drift1, drift1_predict,fig1] = ScatterDriftCorrection3(app,data, params)
            % [drift1, drift1_predict, drift2, drift2_predict,fig1] = ScatterDriftCorrection3(data, params)
            % This function is a MATLAB realization of cross correlation drift
            % correction based on Mlodzianoski's paper (Mlodzianoski et al. Optics Express, 2011).
            % Written by: Wang Bo, May, 2022.
            % Modified by: Zuhui Wang, 2023/10/18: find local maximal peak in cblur
                % from a region from last frame peak instead of global, this can increase
                % the robustness and make cross correlation less affected by irregular
                % pattern. Also include all dependent functions.
            
                % parameter initiation
                binFrame = params.binFrame; % default: 7,000
                gapFrame = params.gapFrame; % default: 500
                totalFrame = params.totalFrame; % default: 30000
                image_pixel_size = params.image_pixel_size; % default: 160nm/pixel
                bin_pixel_size = params.bin_pixel_size; % default: 30nm
                sigma_blur = params.sigma_blur; % default: 11
                padding = params.padding; % default: 15
                smoothrange = params.smoothrange; % default: 1
            
                % Gaussian fitting, following params kept fixed
                xfit0 = [100,0,50,0,50,0];
                [X,Y] = meshgrid(-padding:padding);
                xdata = zeros(size(X,1),size(Y,2),2);
                xdata(:,:,1) = X;
                xdata(:,:,2) = Y;
                lb = [0,-padding,0,-padding,0,-pi/4];
                ub = [realmax('double'),padding,padding^2,padding,padding^2,pi/4];
                opt = optimset('Display','off');
            
                x = (data.ctrsX - floor(min(data.ctrsX))) * image_pixel_size/bin_pixel_size;
                y = (data.ctrsY - floor(min(data.ctrsY)))* image_pixel_size/bin_pixel_size;
            
                data.frame = data.frame - min(data.frame)+1;
            
                temp_pts_idx = data.frame<=binFrame;
                temp_x = x(temp_pts_idx); temp_y = y(temp_pts_idx);
                imageStack0 = zeros(ceil(max(y)), ceil(max(x)));
            
                for j = 1:length(temp_x)
                    imageStack0(ceil(temp_y(j)), ceil(temp_x(j))) = imageStack0(ceil(temp_y(j)), ceil(temp_x(j))) + 1;
                end
            
                imageStackFrame = (ceil(binFrame/2):gapFrame:ceil(totalFrame - binFrame/2));
                totalStack = length(imageStackFrame);
            %     last_imageStack = imageStack0;
                drift1 = zeros(totalStack, 2); % (drift_x, drift_y), 1 <=> N
            %     drift2 = zeros(totalStack, 2); % (drift_x, drift_y), N-1 <=> N
                
                progressbarText(0);
                for i = 2:totalStack
                    temp_imageStack = zeros(size(imageStack0));
            
                    if i == totalStack % use all the left locs if it is the last stack
                        temp_pts_idx = (data.frame > gapFrame*i);
                    else
                        temp_pts_idx = (data.frame > gapFrame*i) & (data.frame <= gapFrame*i+binFrame);
                    end
                         
                    temp_x = x(temp_pts_idx); temp_y = y(temp_pts_idx);
                    for j = 1:length(temp_x)
                        temp_imageStack(ceil(temp_y(j)), ceil(temp_x(j))) = temp_imageStack(ceil(temp_y(j)), ceil(temp_x(j))) + 1;
                    end
            
                    % cross-correlation between stack 1 and stack N
                    c = normxcorr2(imageStack0, temp_imageStack); cblur = imgaussfilt(c,sigma_blur);
                    
                    % find local center max centered by last frame peak
                    if i == 2
                        [ypeak,xpeak] = find(cblur==max(cblur(:)));
                        temp_ypeak = ypeak; temp_xpeak = xpeak;
                        fig1 = figure;imshow(c);hold on;scatter(xpeak,ypeak,'red');
                        title('Cross-correlation map, adjust sigma_blur to make it close to center','Interpreter','none')
                    else 
                        % Define the region centered by temp_xpeak and temp_ypeak with padding
                        half_width = padding;  % replace 'padding' with the actual value.
                        
                        % Ensure indices are within image boundaries
                        x1 = max(1, temp_xpeak - half_width);
                        x2 = min(size(cblur, 2), temp_xpeak + half_width);
                        y1 = max(1, temp_ypeak - half_width);
                        y2 = min(size(cblur, 1), temp_ypeak + half_width);
                    
                        % Take a subregion of cblur
                        sub_cblur = cblur(y1:y2, x1:x2);
                    
                        % Find the max pixel in subregion
                        [sub_ypeak,sub_xpeak] = find(sub_cblur==max(sub_cblur(:)));
                    
                        % Correct the coordinates for the offset due to subregioning
                        ypeak = y1 + sub_ypeak - 1; 
                        xpeak = x1 + sub_xpeak - 1;
                        temp_ypeak = ypeak; temp_xpeak = xpeak;
                    end
                    % gaussian fitting
                    ccrop = cblur((ypeak-padding):(ypeak+padding), (xpeak-padding):(xpeak+padding));
                    ccrop = ccrop./max(ccrop(:)).*100;
                    [xfit,~,~,~] = lsqcurvefit(@D2GaussFunctionRot,xfit0,xdata,ccrop,lb,ub,opt);
                    drift1(i, :) = size(temp_imageStack, [2, 1]) - [xpeak+xfit(2), ypeak+xfit(4)]; % unit: pixel (default: 30nm)
            
                    progressbarText(i/(totalStack-2));
            
            %         % cross-correlation between stack N-1 and stack N
            %         c = normxcorr2(last_imageStack, temp_imageStack); cblur = imgaussfilt(c,sigma_blur);
            % 
            %         % find local center max centered by last frame peak
            %         if i == 2
            %             [ypeak,xpeak] = find(cblur==max(cblur(:)));
            %             temp_ypeak = ypeak; temp_xpeak = xpeak;
            %         else 
            %             % Define the region centered by temp_xpeak and temp_ypeak with padding
            %             half_width = padding;  % replace 'padding' with the actual value.
            %             
            %             % Ensure indices are within image boundaries
            %             x1 = max(1, temp_xpeak - half_width);
            %             x2 = min(size(cblur, 2), temp_xpeak + half_width);
            %             y1 = max(1, temp_ypeak - half_width);
            %             y2 = min(size(cblur, 1), temp_ypeak + half_width);
            %         
            %             % Take a subregion of cblur
            %             sub_cblur = cblur(y1:y2, x1:x2);
            %         
            %             % Find the max pixel in subregion
            %             [sub_ypeak,sub_xpeak] = find(sub_cblur==max(sub_cblur(:)));
            %         
            %             % Correct the coordinates for the offset due to subregioning
            %             ypeak = y1 + sub_ypeak - 1; 
            %             xpeak = x1 + sub_xpeak - 1;
            %             temp_ypeak = ypeak; temp_xpeak = xpeak;
            %         end
            %         % gaussian fitting
            %         ccrop = cblur((ypeak-padding):(ypeak+padding), (xpeak-padding):(xpeak+padding));
            %         ccrop = ccrop./max(ccrop(:)).*100;
            %         [xfit,~,~,~] = lsqcurvefit(@D2GaussFunctionRot,xfit0,xdata,ccrop,lb,ub);
            %         drift2(i, :) = size(temp_imageStack, [2, 1]) - [xpeak+xfit(2), ypeak+xfit(4)]; % unit: pixel (default: 30nm)
            %         last_imageStack = temp_imageStack;
                end
                drift1 = horzcat(imageStackFrame', drift1*bin_pixel_size); % (frame, drift_x, drift_y)
            %     drift2 = cumsum(drift2);
            %     drift2 = horzcat(imageStackFrame', drift2*bin_pixel_size); % (frame, drift_x, drift_y)
            
                % Local Polynomial Regression
                drift1_predict = zeros(totalFrame, 3); % (frame, drift_x, drift_y) 
            %     drift2_predict = zeros(totalFrame, 3); % (frame, drift_x, drift_y)
                Xq = (1:totalFrame)'; drift1_predict(:, 1) = Xq; drift2_predict(:, 1) = Xq;
                drift1_predict(:, 2) = locpoly(drift1(:, 1), drift1(:, 2), Xq, gapFrame*smoothrange, 1);
                drift1_predict(:, 3) = locpoly(drift1(:, 1), drift1(:, 3), Xq, gapFrame*smoothrange, 1);
            %     drift2_predict(:, 2) = locpoly(drift2(:, 1), drift2(:, 2), Xq, gapFrame*smoothrange, 1);
            %     drift2_predict(:, 3) = locpoly(drift2(:, 1), drift2(:, 3), Xq, gapFrame*smoothrange, 1);
            
            %     DO NOT USE Kernel Smooth
            %     drift1_predict(:, 2) = kernelSmooth(Xq, drift1(:, 1), drift1(:, 2), gapFrame*smoothrange, 'gaussian');
            %     drift1_predict(:, 3) = kernelSmooth(Xq, drift1(:, 1), drift1(:, 3), gapFrame*smoothrange, 'gaussian');
            %     drift2_predict(:, 2) = kernelSmooth(Xq, drift2(:, 1), drift2(:, 2), gapFrame*smoothrange, 'gaussian');
            %     drift2_predict(:, 3) = kernelSmooth(Xq, drift2(:, 1), drift2(:, 3), gapFrame*smoothrange, 'gaussian');
                
            %     axismin = min([drift1_predict(:, 2); drift1_predict(:, 3); drift2_predict(:, 2); drift2_predict(:, 3)])-20; 
            %     axismax = max([drift1_predict(:, 2); drift1_predict(:, 3); drift2_predict(:, 2); drift2_predict(:, 3)])+20;
            
                axismin = min([drift1_predict(:, 2); drift1_predict(:, 3)])-20; 
                axismax = max([drift1_predict(:, 2); drift1_predict(:, 3)])+20;
            
                fig2 = figure('Visible','on','Name','Estimated lateral drift');
            %     tiledlayout(1, 2,'TileSpacing','compact', 'Padding','compact');
            %     fig1.Units = 'inches';
            %     fig1.OuterPosition = [7.5, 6.1, 11, 5.66];
            
            %     nexttile;
                hold on
                plot(drift1(:, 1), drift1(:, 2), 'o');
                plot(drift1(:, 1), drift1(:, 3), 'x');
                e1 = plot(drift1_predict(:, 1), drift1_predict(:, 2));
                e2 = plot(drift1_predict(:, 1), drift1_predict(:, 3));
                hold off
                xlabel('Frame');
                ylabel('Dirft (nm)');
                legend([e1, e2], {'x drift', 'y drift'});
                title('Relative drift to the first frame', 'FontWeight','bold');
                ylim([axismin, axismax]);
            
            %     nexttile;
            %     hold on
            %     plot(drift2(:, 1), drift2(:, 2), 'o');
            %     plot(drift2(:, 1), drift2(:, 3), 'x');
            %     e1 = plot(drift2_predict(:, 1), drift2_predict(:, 2));
            %     e2 = plot(drift2_predict(:, 1), drift2_predict(:, 3));
            %     hold off
            %     xlabel('Frame');
            %     ylabel('Dirft (nm)');
            %     legend([e1, e2], {'x drift', 'y drift'});
            %     title('N-1 to N', 'FontWeight','bold');
            %     ylim([axismin, axismax]);
            
            
            %% AUXILIARY FUNCTION
            function F = D2GaussFunctionRot(x,xdata)
            % This function is from Gero Nootz (2023). Fit 2D gaussian function to data
            % (https://www.mathworks.com/matlabcentral/fileexchange/37087-fit-2d-gaussian-function-to-data),
            % MATLAB Central File Exchange. Retrieved October 17, 2023.
                %% x = [Amp, x0, wx, y0, wy, fi]
                %[X,Y] = meshgrid(x,y) 
                %  xdata(:,:,1) = X
                %  xdata(:,:,2) = Y           
                % Mrot = [cos(fi) -sin(fi); sin(fi) cos(fi)]
                %%
                xdatarot(:,:,1)= xdata(:,:,1)*cos(x(6)) - xdata(:,:,2)*sin(x(6));
                xdatarot(:,:,2)= xdata(:,:,1)*sin(x(6)) + xdata(:,:,2)*cos(x(6));
                x0rot = x(2)*cos(x(6)) - x(4)*sin(x(6));
                y0rot = x(2)*sin(x(6)) + x(4)*cos(x(6));
                F = x(1)*exp(   -((xdatarot(:,:,1)-x0rot).^2/(2*x(3)^2) + (xdatarot(:,:,2)-y0rot).^2/(2*x(5)^2) )    );
                % figure(3)
                % alpha(0)
                % imagesc(F)
                % colormap('gray')
                % figure(gcf)%bring current figure to front
                % drawnow
                % beep
                % pause %Wait for keystroke
            end
            
            function [yhat] = locpoly(x,y,x0,h,d)
            % LOCPOLY Local polynomial regression.
            %   YHAT = LOCPOLY(X,Y,X0,H,D) returns the value of the local polynomial
            %   regression of degree D at X0 using a Gaussian kernel with bandwidth H.
            %
            %   X and Y are vectors of equal length containing the data points.
            %
            %   Example:
            %      x = linspace(-3,3,100)';
            %      y = exp(-x.^2) + randn(size(x))*0.3;
            %      yhat = locpoly(x,y,x,0.5,2);
            %      plot(x,y,'.',x,yhat,'-')
            %
            %   See also LOWESS.
            
                n = length(x);
                yhat = zeros(size(x0));
                for i=1:length(x0)
                    w = exp(-(x-x0(i)).^2/(2*h^2));
                    X = [ones(n,1) x-x0(i)];
                    for j=2:d
                        X(:,j+1) = (x-x0(i)).^j;
                    end
                    b = (X'*diag(w)*X)\(X'*diag(w)*y);
                    yhat(i) = b(1);
            %         for j=2:d+1
            %             yhat(i) = yhat(i) + b(j)*(x0(i)-x).^(j-1);
            %         end
                end
            end                      
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.mainapp = mainapp;
        end

        % Button pushed function: RunButton
        function RunCallBack(app, event)
            % Call main app's public function
            ScatterDriftCorrection4(app);
           
            % Delete the dialog box  
            delete(app)      
        end

        % Close request function: SequantialMPALMDriftCorrectionUIFigure
        function SequantialMPALMDriftCorrectionUIFigureCloseRequest(app, event)
        
            delete(app)
        end

        % Button pushed function: RunButton_2
        function BrightFieldRegisFcn(app, event)
            %% Brightfield registration based on Wester et al. Sci Report 2021.
            root_path = app.bulk_path_2.Value;
            stackImgList = dir(fullfile(root_path, sprintf('%s*.nd2',app.bulk_prefix_2.Value)));
            
            % Initialize arrays to store translations
            numFrames = length(stackImgList);
            txArray = zeros(numFrames - 1, 1);
            tyArray = zeros(numFrames - 1, 1);
            
            % Loop through and calculate drift between consecutive frames
            for i = 2:numFrames
                % Load consecutive frame pairs
                PreviousStack = double(MemoryEfficientND2reader_oneFrame(fullfile(stackImgList(i-1).folder, stackImgList(i-1).name)));
                CurrentStack = double(MemoryEfficientND2reader_oneFrame(fullfile(stackImgList(i).folder, stackImgList(i).name)));
            
                % Find the offset between consecutive frames
                [PixelOffset, SubPixelOffset, ~, ~] = findStackOffset(app,PreviousStack, CurrentStack);
            
                % Compute total translation offsets
                txArray(i-1) = PixelOffset(2) + SubPixelOffset(2);
                tyArray(i-1) = PixelOffset(1) + SubPixelOffset(1);
            end                       
            
            % Accumulate corrections and apply them
            cumulativeTx = 0;
            cumulativeTy = 0;

            cumulativeTx_result = nan(1,numFrames-1);
            cumulativeTy_result = nan(1,numFrames-1);
                        
            CorrectedImageStack = nan([size(CurrentStack),numFrames]);
            CorrectedImageStack(:,:,1) = MemoryEfficientND2reader_oneFrame(fullfile(stackImgList(1).folder, stackImgList(1).name));
            
            for i = 2:numFrames
                % Update cumulative drift
                cumulativeTx = cumulativeTx + txArray(i-1); cumulativeTx_result(i-1) = cumulativeTx;
                cumulativeTy = cumulativeTy + tyArray(i-1); cumulativeTy_result(i-1) = cumulativeTy;          
            
                % Load current frame
                CurrentStack = double(MemoryEfficientND2reader_oneFrame(fullfile(stackImgList(i).folder, stackImgList(i).name)));
            
                % Apply transformation to correct the drift relative to the first frame
                CorrectedImage = imtranslate(CurrentStack, [cumulativeTx, cumulativeTy]);
            
                % Optional: Display or save the corrected image
                CorrectedImageStack(:,:,i) =  uint16(CorrectedImage);                
            end

            fig1 = figure('Name', 'After correction stack viewer');            
            ax = axes(fig1);
            imshow(max(CorrectedImageStack,[],3), 'Parent', ax);h1 = imcontrast(ax);
            title('Max projection of image stacks after registration');
            uiwait(h1);

            answer = questdlg('Do you satisfy with the corrected BF results?','Confirmation');            
            % Handle response
            switch answer
                case 'Yes'
                    gap_frame = app.gap_frame_num.Value;
                    app.mainapp.Ch1_loc_table.Frame
                    for frameBin = 2:numFrames
                        now_frame = app.mainapp.Ch1_loc_table.Frame >= (frameBin-1)*gap_frame+1 & app.mainapp.Ch1_loc_table.Frame <= frameBin*gap_frame;
                        app.mainapp.Ch1_loc_table.Xpos(now_frame) = app.mainapp.Ch1_loc_table.Xpos(now_frame) + cumulativeTx_result(frameBin-1)*app.mainapp.camPixelSize_Ch1.Value;
                        app.mainapp.Ch1_loc_table.Ypos(now_frame) = app.mainapp.Ch1_loc_table.Ypos(now_frame) + cumulativeTy_result(frameBin-1)*app.mainapp.camPixelSize_Ch1.Value;
                    end
                    
                    fig2 = figure('Visible','on','Name','Estimated lateral drift');           
                    hold on
                    frame_idx = [1, (1:(numFrames-1))*gap_frame];
                    e1 = plot(frame_idx, [0,cumulativeTx_result]*app.mainapp.camPixelSize_Ch1.Value, '--o');
                    e2 = plot(frame_idx, [0,cumulativeTy_result]*app.mainapp.camPixelSize_Ch1.Value, '--x');
                    hold off
                    xlabel('Frame');
                    ylabel('Dirft (nm)');
                    legend([e1, e2], {'x drift', 'y drift'});
                    title('Relative drift to the first frame', 'FontWeight','bold');                    

                    app.mainapp.driftCorrectionStatus_Ch1.Color = [0 1 0];
                    app.mainapp.saveCorrectedTable_Ch1.Enable = true;
                    % Delete the dialog box  
                    delete(app)
                case 'No'
                    app.mainapp.driftCorrectionStatus_Ch1.Color = [1 0 0];   
                    app.mainapp.saveCorrectedTable_Ch1.Enable = false;
                    % Delete the dialog box  
                    delete(app)
            end

            % % Local Polynomial Regression
            % drift1_predict = zeros(totalFrame, 3); % (frame, drift_x, drift_y) 
            % Xq = (1:totalFrame)'; drift1_predict(:, 1) = Xq; 
            % drift1_predict(:, 2) = locpoly(drift1(:, 1), drift1(:, 2), Xq, gapFrame*smoothrange, 1);
            % drift1_predict(:, 3) = locpoly(drift1(:, 1), drift1(:, 3), Xq, gapFrame*smoothrange, 1);
            % 
            % app.mainapp.Ch1_drift1_predict = drift1_predict;
            % app.mainapp.seqDriftCorrectionStatus.Color = [0 1 0];
        end

        % Button pushed function: RunButton_3
        function PALMCorssCorrelateFcn(app, event)
            fprintf('Drift correction ...\n');
            data.ctrsX = app.mainapp.Ch1_loc_table.Xpos/app.mainapp.camPixelSize_Ch1.Value;
            data.ctrsY = app.mainapp.Ch1_loc_table.Ypos/app.mainapp.camPixelSize_Ch1.Value;
            data.frame = app.mainapp.Ch1_loc_table.Frame;

            driftCorrectParams.binFrame = app.drift_Binframe.Value;
            driftCorrectParams.gapFrame = app.drift_Gapframe.Value;
            driftCorrectParams.totalFrame = app.totalFrame_3.Value;
            driftCorrectParams.image_pixel_size = app.mainapp.camPixelSize_Ch1.Value; % unit: nm/pixel
            driftCorrectParams.bin_pixel_size = app.drift_Binpixelsize.Value; % default: 30nm
            driftCorrectParams.sigma_blur = app.drift_Sigmablur.Value; % default: 11
            driftCorrectParams.padding = app.drift_Padding.Value; % default: 15
            driftCorrectParams.smoothrange = app.drift_Smoothrange.Value; % default: 2
            
            % based on WangBo's feedback, drift1 gets better result than
            % drift2
            [~, drift1_predict, drift_fig] = ScatterDriftCorrection3(app,data, driftCorrectParams);
            drift = drift1_predict - drift1_predict(1, :);
            % exportgraphics(drift_fig, fullfile(input_dir, 'ThunderStormLocs', filesep, filename(1:(end-4)), '_drift.png'));

            app.mainapp.Ch1_loc_table.Xpos = app.mainapp.Ch1_loc_table.Xpos + drift(app.mainapp.Ch1_loc_table.Frame, 2);
            app.mainapp.Ch1_loc_table.Ypos = app.mainapp.Ch1_loc_table.Ypos + drift(app.mainapp.Ch1_loc_table.Frame, 3);
            % app.Ch1_loc_XYLim = [min(app.Ch1_loc_table.Xpos),max(app.Ch1_loc_table.Xpos),min(app.Ch1_loc_table.Ypos),max(app.Ch1_loc_table.Ypos)];

            app.mainapp.driftCorrectionStatus_Ch1.Color = [0 1 0];
            app.mainapp.saveCorrectedTable_Ch1.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SequantialMPALMDriftCorrectionUIFigure = uifigure('Visible', 'off');
            app.SequantialMPALMDriftCorrectionUIFigure.Name = 'Sequantial MPALM Drift Correction';
            app.SequantialMPALMDriftCorrectionUIFigure.CloseRequestFcn = createCallbackFcn(app, @SequantialMPALMDriftCorrectionUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.SequantialMPALMDriftCorrectionUIFigure);
            app.Method1Tab = uitab(app.TabGroup);
            app.Method1Tab.Title = 'Method1';
            app.ParametersofdriftcorrectionsforsequantialMPALMLabel = uilabel(app.Method1Tab);
            app.ParametersofdriftcorrectionsforsequantialMPALMLabel.Text = 'Parameters of drift corrections for sequantial MPALM';
            app.RunButton = uibutton(app.Method1Tab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunCallBack, true);
            app.RunButton.Text = 'Run!';
            app.BulkimageprefixEditFieldLabel = uilabel(app.Method1Tab);
            app.BulkimageprefixEditFieldLabel.Text = 'Bulk image prefix';
            app.bulk_prefix = uieditfield(app.Method1Tab, 'text');
            app.bulk_prefix.Value = 'Clust';
            app.BulkimagefolderpathEditFieldLabel = uilabel(app.Method1Tab);
            app.BulkimagefolderpathEditFieldLabel.Text = 'Bulk image folder path';
            app.bulk_path = uieditfield(app.Method1Tab, 'text');
            app.bulk_path.Value = 'H:\2024\20240125_U2OS_HaloRPB1_HMSiR_PA646\HMSiR10nM_TMR\Clust02';
            app.ImagepixelsizeEditFieldLabel = uilabel(app.Method1Tab);
            app.ImagepixelsizeEditFieldLabel.Text = 'Image pixel size';
            app.image_pixel_size = uieditfield(app.Method1Tab, 'numeric');
            app.image_pixel_size.Value = 110;
            app.SigmablurEditFieldLabel = uilabel(app.Method1Tab);
            app.SigmablurEditFieldLabel.Text = 'Sigma blur';
            app.sigma_blur = uieditfield(app.Method1Tab, 'numeric');
            app.sigma_blur.Tooltip = {'Sigma of Gaussian kernel that applied to cross-correlation map'};
            app.sigma_blur.Value = 11;
            app.PaddingEditFieldLabel = uilabel(app.Method1Tab);
            app.PaddingEditFieldLabel.Text = 'Padding';
            app.padding = uieditfield(app.Method1Tab, 'numeric');
            app.padding.Tooltip = {'The maximal drift will find in this padding region around the center of cross-correlation map'};
            app.padding.Value = 15;
            app.SmoothrangeEditFieldLabel = uilabel(app.Method1Tab);
            app.SmoothrangeEditFieldLabel.Text = 'Smooth range';
            app.smoothrange = uieditfield(app.Method1Tab, 'numeric');
            app.smoothrange.Tooltip = {'Frame bandwith that polynomial fitting of drift estimation'};
            app.smoothrange.Value = 1000;
            app.totalframesLabel = uilabel(app.Method1Tab);
            app.totalframesLabel.Text = 'total frames';
            app.totalFrame_1 = uieditfield(app.Method1Tab, 'numeric');
            app.totalFrame_1.ValueDisplayFormat = '%.0f';
            app.totalFrame_1.Tooltip = {'Total frame number of the dataset'};
            app.totalFrame_1.Value = 10000;
            app.Method2Tab = uitab(app.TabGroup);
            app.Method2Tab.Title = 'Method2';
            app.ParametersofdriftcorrectionsforsequantialMPALMWesteretalLabel = uilabel(app.Method2Tab);
            app.ParametersofdriftcorrectionsforsequantialMPALMWesteretalLabel.Text = 'Parameters of drift corrections for sequantial MPALM (Wester et al.)';
            app.RunButton_2 = uibutton(app.Method2Tab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrightFieldRegisFcn, true);
            app.RunButton_2.Text = 'Run!';
            app.BulkimageprefixEditFieldLabel_2 = uilabel(app.Method2Tab);
            app.BulkimageprefixEditFieldLabel_2.Text = 'Brightfield image prefix';
            app.bulk_prefix_2 = uieditfield(app.Method2Tab, 'text');
            app.bulk_prefix_2.Value = 'U2OS';
            app.BulkimagefolderpathEditFieldLabel_2 = uilabel(app.Method2Tab);
            app.BulkimagefolderpathEditFieldLabel_2.Text = 'Brightfield image folder path';
            app.bulk_path_2 = uieditfield(app.Method2Tab, 'text');
            app.bulk_path_2.Value = 'F:\2024\20240602_U2OS_FOXA2_MPALM\BF\Clust13';
            app.totalframesLabel_2 = uilabel(app.Method2Tab);
            app.totalframesLabel_2.Text = 'Gap frames between bulk image';
            app.gap_frame_num = uieditfield(app.Method2Tab, 'numeric');
            app.gap_frame_num.ValueDisplayFormat = '%.0f';
            app.gap_frame_num.Value = 2000;
            app.Method3Tab = uitab(app.TabGroup);
            app.Method3Tab.Title = 'Method3';
            app.BinframeEditFieldLabel = uilabel(app.Method3Tab);
            app.BinframeEditFieldLabel.Text = 'Bin frame';
            app.drift_Binframe = uieditfield(app.Method3Tab, 'numeric');
            app.drift_Binframe.Value = 2000;
            app.GapframeEditFieldLabel = uilabel(app.Method3Tab);
            app.GapframeEditFieldLabel.Text = 'Gap frame';
            app.drift_Gapframe = uieditfield(app.Method3Tab, 'numeric');
            app.drift_Gapframe.Value = 500;
            app.BinpixelsizeEditFieldLabel = uilabel(app.Method3Tab);
            app.BinpixelsizeEditFieldLabel.Text = 'Bin pixel size';
            app.drift_Binpixelsize = uieditfield(app.Method3Tab, 'numeric');
            app.drift_Binpixelsize.Value = 30;
            app.SigmablurEditFieldLabel_2 = uilabel(app.Method3Tab);
            app.SigmablurEditFieldLabel_2.Text = 'Sigma blur';
            app.drift_Sigmablur = uieditfield(app.Method3Tab, 'numeric');
            app.drift_Sigmablur.Value = 11;
            app.PaddingEditFieldLabel_2 = uilabel(app.Method3Tab);
            app.PaddingEditFieldLabel_2.Text = 'Padding';
            app.drift_Padding = uieditfield(app.Method3Tab, 'numeric');
            app.drift_Padding.Value = 15;
            app.SmoothrangeEditFieldLabel_2 = uilabel(app.Method3Tab);
            app.SmoothrangeEditFieldLabel_2.Text = 'Smooth range';
            app.drift_Smoothrange = uieditfield(app.Method3Tab, 'numeric');
            app.drift_Smoothrange.Value = 2;
            app.RunButton_3 = uibutton(app.Method3Tab, 'push');
            app.RunButton_3.ButtonPushedFcn = createCallbackFcn(app, @PALMCorssCorrelateFcn, true);
            app.RunButton_3.Text = 'Run!';
            app.totalframesLabel_3 = uilabel(app.Method3Tab);
            app.totalframesLabel_3.Text = 'total frames';
            app.totalFrame_3 = uieditfield(app.Method3Tab, 'numeric');
            app.totalFrame_3.ValueDisplayFormat = '%.0f';
            app.totalFrame_3.Tooltip = {'Total frame number of the dataset'};
            app.totalFrame_3.Value = 10000;
            app.Label = uilabel(app.Method3Tab);
            app.Label.Text = 'Parameters of split localization into bins and use rendered images to cross';
            % Show the figure after all components are created
            app.SequantialMPALMDriftCorrectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SeqDriftCorrectionApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SequantialMPALMDriftCorrectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SequantialMPALMDriftCorrectionUIFigure)
        end
    end
end