classdef coarse_rendering < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MDFButton                    matlab.ui.control.Button
        RDFButton                    matlab.ui.control.Button
        ZoomROIGoButton              matlab.ui.control.Button
        ROIcoord_4                   matlab.ui.control.NumericEditField
        ROIcoord_3                   matlab.ui.control.NumericEditField
        ROIcoord_2                   matlab.ui.control.NumericEditField
        ROIcoord_1                   matlab.ui.control.NumericEditField
        ROIcoordinateEditFieldLabel  matlab.ui.control.Label
        DdensitycorrButton           matlab.ui.control.Button
        FinerrenderButton            matlab.ui.control.Button
        LineProfile_mpalm            matlab.ui.control.Button
        ZoomROIReset                 matlab.ui.control.StateButton
        SelectZoomROI                matlab.ui.control.StateButton
        palm_DensityMax              matlab.ui.control.Spinner
        DensityMaxSpinner_2Label     matlab.ui.control.Label
        palm_DensityMin              matlab.ui.control.Spinner
        DensityMinSpinner_2Label     matlab.ui.control.Label
        PALMLabel                    matlab.ui.control.Label
        ExporttifButton_3            matlab.ui.control.StateButton
        clear_loc_palm               matlab.ui.control.Button
        overlay_loc_palm             matlab.ui.control.Button
        DiffusionmapLabel            matlab.ui.control.Label
        MPALMLabel                   matlab.ui.control.Label
        OverlayCh2Button_2           matlab.ui.control.Button
        Ch2opacityEditField_2        matlab.ui.control.NumericEditField
        Ch2opacityEditField_2Label   matlab.ui.control.Label
        ExporttifButton_2            matlab.ui.control.StateButton
        Ch2opacityEditField          matlab.ui.control.NumericEditField
        Ch2opacityEditFieldLabel     matlab.ui.control.Label
        OverlayCh2Button             matlab.ui.control.Button
        ExporttifButton              matlab.ui.control.StateButton
        clear_loc_mpalm              matlab.ui.control.Button
        overlay_loc_mpalm            matlab.ui.control.Button
        mpalm_DensityMax             matlab.ui.control.Spinner
        DensityMaxSpinnerLabel       matlab.ui.control.Label
        mpalm_DensityMin             matlab.ui.control.Spinner
        DensityMinSpinnerLabel       matlab.ui.control.Label
        ax_img_palm                  matlab.ui.control.UIAxes
        ax_img_mobility              matlab.ui.control.UIAxes
        ax_img_mpalm                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        hsv_image % Description
        scatter_dot_mpalm % Description
        scatter_dot_palm % Description
        img_mpalm_D % Description
    end
    
    properties (Access = public)
        img_mpalm % Description
        roi_Ch1_loc_table % Description
        mainapp % Main app object
    end
    
    methods (Access = private)
        
        function [K, L] = RipleyK(app,pos,mask,roi,r)
            %% RipleyK
            % pos: [x, y]
            % mask: mask region used to calculate average particle density
            % roi: roi object that generate the mask
            % r: step to measure the RipleyK, e.g.: 0:10:500 
 
            max_r = max(r); % unit rendered pixel
            area = sum(mask,'all');

            % Calculate number of bins in x and y directions
            [num_bins_y,num_bins_x] = size(app.mainapp.Ch1Img_filt_densityPALM);

            % Initialize K and L arrays
            K = zeros(size(r(1:end-1)));            
        
            grid_indices_x = discretize(pos(:,1),1:num_bins_x+1);
            grid_indices_y = discretize(pos(:,2),1:num_bins_y+1);
        
            % Loop through each point to compute K and L
            inroi_pos = inROI(roi,pos(:,1),pos(:,2));
            inroi_idx = find(inroi_pos);
            tot_inroi = length(inroi_idx);
            fprintf("Calculating Ripley's H function for the selected ROI ...")
            progressbarText(0);
            for it = 1:tot_inroi
                inroi_it = inroi_idx(it);
                % Find neighboring points within the same or adjacent grid cells
                x_idx = grid_indices_x(inroi_it);
                y_idx = grid_indices_y(inroi_it);
        
                % Define search range based on current point's grid indices
                x_range = max(1, x_idx - max_r):min(num_bins_x, x_idx + max_r);
                y_range = max(1, y_idx - max_r):min(num_bins_y, y_idx + max_r);
        
                % Get indices of points within the same or adjacent grid cells
                neighbor_indices = find(ismember(grid_indices_x, x_range) & ismember(grid_indices_y, y_range));
        
                if ~isempty(neighbor_indices)
                    % Compute distances within the defined radius
                    distances = sqrt((pos(neighbor_indices, 1) - pos(inroi_it, 1)).^2 + (pos(neighbor_indices, 2) - pos(inroi_it, 2)).^2);
        
                    % Compute distances in each bin
                    [h, ~] = histcounts(distances, r);
        
                    % Update K using cumulative sum
                    K = K + cumsum(h)*area/tot_inroi^2; %Ripley2s K
                end
                progressbarText(it/tot_inroi);
            end
        
            % Calculate L
            L = sqrt(K/pi); %Ripley’s             
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.mainapp = mainapp;           
            app.mpalm_DensityMin.Value = app.mainapp.render_minDensity_Ch1.Value;
            app.mpalm_DensityMax.Value = app.mainapp.render_maxDensity_Ch1.Value;
            app.hsv_image = rgb2hsv(app.mainapp.rgbImOut_mobility);
            % lets construct the HSV coded mobility-PALM image
            ax_CLim = [app.mainapp.render_minDensity_Ch1.Value app.mainapp.render_maxDensity_Ch1.Value];
            rescale_ax_CLim = [ax_CLim(1)/max(app.mainapp.Ch1Img_filt_densityPALM,[],'all'), ax_CLim(2)/max(app.mainapp.Ch1Img_filt_densityPALM,[],'all')];
            if min(rescale_ax_CLim) < 0 || max(rescale_ax_CLim) > 1
                rescale_ax_CLim = [0 1];
            end
            rescale_ImOut_density = rescale(app.mainapp.Ch1Img_filt_densityPALM);
            scaled_ImOut_density = imadjust(rescale_ImOut_density,rescale_ax_CLim);

            % >>>> Option1: black background for MPALM
            app.hsv_image(:,:,3) = scaled_ImOut_density; % Value for PALM localization density
            % <<<<

            % >>>> Option2: white background for MPALM
            % app.hsv_image(:,:,2) = scaled_ImOut_density;
            % app.hsv_image(:,:,3) = ones(size(scaled_ImOut_density));
            % <<<<

            app.img_mpalm = hsv2rgb(app.hsv_image);
            imshow(app.img_mpalm,'Parent',app.ax_img_mpalm);

            imshow(app.mainapp.rgbImOut_mobility,'Parent',app.ax_img_mobility)
            app.palm_DensityMin.Value = app.mainapp.render_minDensity_Ch1.Value;
            app.palm_DensityMax.Value = app.mainapp.render_maxDensity_Ch1.Value;
            imshow(app.mainapp.Ch1Img_filt_densityPALM,'Parent',app.ax_img_palm,Colormap=hot)
            app.img_mpalm_D = app.mainapp.img_mobility;
            % app.img_mpalm_D = (app.mainapp.img_mobility./app.mainapp.D_SRArea_const.Value).^2; % Convert area in mpalm into D

            if app.mainapp.MDFButton.Enable == true
                app.MDFButton.Enable = true;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: mpalm_DensityMax
        function mpalm_DensityMaxValueChanged(app, event)
            % ax_CLim = [app.mpalm_DensityMin.Value, app.mpalm_DensityMax.Value];
            % rescale_ImOut_density = rescale(app.mainapp.Ch1Img_filt_densityPALM);
            % scaled_ImOut_density = imadjust(rescale_ImOut_density,[ax_CLim(1)/max(app.mainapp.Ch1Img_filt_densityPALM,[],'all'), ax_CLim(2)/max(app.mainapp.Ch1Img_filt_densityPALM,[],'all')]);
            % % >>>> Option1: black background for MPALM
            % app.hsv_image(:,:,3) = scaled_ImOut_density; % Value for PALM localization density
            % % <<<<
            % % >>>> Option2: white background for MPALM
            % % app.hsv_image(:,:,2) = scaled_ImOut_density; % Value for PALM localization density
            % % app.hsv_image(:,:,3) = ones(size(scaled_ImOut_density));
            % % <<<<
            % app.img_mpalm = hsv2rgb(app.hsv_image);
            % imshow(app.img_mpalm,'Parent',app.ax_img_mpalm);

            % >>>> Option3: bug fixed to allow image continue dimmer after
            % input max exceed density max
            ax_CLim = [app.mpalm_DensityMin.Value, app.mpalm_DensityMax.Value];
    
            % Rescale the image
            rescale_ImOut_density = rescale(app.mainapp.Ch1Img_filt_densityPALM);
            
            % Calculate the low and high input values for imadjust
            maxDensity = max(app.mainapp.Ch1Img_filt_densityPALM, [], 'all');
            lowIn = ax_CLim(1) / maxDensity;
            highIn = ax_CLim(2) / maxDensity;
            
            % Clamp lowIn and highIn to the range [0, 1]
            lowIn = max(0, min(lowIn, 1));
            highIn = max(0, min(highIn, 1));
            
            % Adjust the image brightness using clamped values
            scaled_ImOut_density = imadjust(rescale_ImOut_density, [lowIn, highIn]);
            
            % Option1: black background for MPALM
            app.hsv_image(:,:,3) = scaled_ImOut_density; % Value for PALM localization density
            
            % Option2: white background for MPALM (commented out)
            % app.hsv_image(:,:,2) = scaled_ImOut_density; % Value for PALM localization density
            % app.hsv_image(:,:,3) = ones(size(scaled_ImOut_density));
            
            % Convert the HSV image back to RGB and display it
            app.img_mpalm = hsv2rgb(app.hsv_image);
            imshow(app.img_mpalm, 'Parent', app.ax_img_mpalm);
        end

        % Value changed function: mpalm_DensityMin
        function mpalm_DensityMinValueChanged(app, event)
            ax_CLim = [app.mpalm_DensityMin.Value, app.mpalm_DensityMax.Value];
            rescale_ImOut_density = rescale(app.mainapp.Ch1Img_filt_densityPALM);
            scaled_ImOut_density = imadjust(rescale_ImOut_density,[ax_CLim(1)/max(app.mainapp.Ch1Img_filt_densityPALM,[],'all'), ax_CLim(2)/max(app.mainapp.Ch1Img_filt_densityPALM,[],'all')]);
            % >>>> Option1: black background for MPALM
            app.hsv_image(:,:,3) = scaled_ImOut_density; % Value for PALM localization density
            % <<<<
            % >>>> Option2: white background for MPALM
            % app.hsv_image(:,:,2) = scaled_ImOut_density; % Value for PALM localization density
            % app.hsv_image(:,:,3) = ones(size(scaled_ImOut_density));
            % <<<<
            app.img_mpalm = hsv2rgb(app.hsv_image);
            imshow(app.img_mpalm,'Parent',app.ax_img_mpalm);
        end

        % Button pushed function: overlay_loc_mpalm
        function overlay_loc_mpalmPushed(app, event)
            dxy = app.mainapp.render_pixelSize.Value;
            Xmin = app.mainapp.Ch1_loc_XYLim(1);Ymin = app.mainapp.Ch1_loc_XYLim(3);
            Xpos_rescale = (app.mainapp.filtD_Ch1_loc_table.Xpos-Xmin)./dxy+0.5 ;
            Ypos_rescale = (app.mainapp.filtD_Ch1_loc_table.Ypos-Ymin)./dxy+0.5 ;
            
            
            cidx = discretize(app.mainapp.filtD_Ch1_loc_table.D,app.mainapp.D_edge); 
            cidx = cidx +1; 
            cidx(app.mainapp.filtD_Ch1_loc_table.D < 10^app.mainapp.render_minlogD.Value & app.mainapp.filtD_Ch1_loc_table.D > 0) = 2;
            cidx(app.mainapp.filtD_Ch1_loc_table.D > 10^app.mainapp.render_maxlogD.Value) = 256;
            
            % overlay detection localization
            hold(app.ax_img_mpalm,'on');
            app.scatter_dot_mpalm = scatter(app.ax_img_mpalm,Xpos_rescale,Ypos_rescale,15,app.mainapp.local_cmap_mpalm(cidx,:),'filled');                
            hold(app.ax_img_mpalm,'off');
        end

        % Button pushed function: clear_loc_mpalm
        function clear_loc_mpalmPushed(app, event)
            delete(app.scatter_dot_mpalm);
        end

        % Value changed function: ExporttifButton
        function ExporttifButtonValueChanged(app, event)
            [file,location] = uiputfile('MPALM.tif','Saving densityMPALM PDF image');
% %             imwrite(app.img_mpalm, fullfile(location, file));
%             exportgraphics(app.ax_img_mpalm, fullfile(location, file))
            % Get the children of the axes (the image object)
            imgObj = get(app.ax_img_mpalm, 'Children');            
            % Get the image data from the CData property
            imageData = imgObj.CData;
            % Save the image data using imwrite
            imwrite(imageData, fullfile(location, file));
            fprintf("DensityMPALM exported!\n")
        end

        % Button pushed function: OverlayCh2Button
        function OverlayCh2ButtonPushed(app, event)
            if ~isempty(app.mainapp.simple_partition_Ch2)
               FG = app.mainapp.simple_partition_Ch2; FG = cat(3, FG, FG, FG);
               BG = app.img_mpalm;
               R = imblend(FG,BG,1,'scale add',app.Ch2opacityEditField.Value);
%                 a = app.Ch2opacityEditField.Value; % foreground opacity
%                 segfusedRgbImOut_mobility = a*app.mainapp.simple_partition_Ch2 + (1-a)*app.img_mpalm;
                imshow(R,'Parent',app.ax_img_mpalm);
            else
                fprintf('Ch2 segmentation is not available.\n');
            end
        end

        % Button pushed function: OverlayCh2Button_2
        function OverlayCh2Button_2Pushed(app, event)
            if ~isempty(app.mainapp.simple_partition_Ch2)
                FG = app.mainapp.simple_partition_Ch2; FG = cat(3, FG, FG, FG); 
                BG = app.mainapp.rgbImOut_mobility;
                R = imblend(FG,BG,1,'scale add',app.Ch2opacityEditField_2.Value);
                imshow(R,'Parent',app.ax_img_mobility);
            else
                fprintf('Ch2 segmentation is not available.\n');
            end
        end

        % Value changed function: ExporttifButton_2
        function ExporttifButton_2ValueChanged(app, event)
            [file,location] = uiputfile('MobilityMap.tif','Saving MPALM TIF image');
            imgObj = get(app.ax_img_mobility, 'Children');            
            imageData = imgObj.CData;
            imwrite(imageData, fullfile(location, file));
            fprintf("MPALM exported!\n")
            
        end

        % Value changed function: palm_DensityMin
        function palm_DensityMinValueChanged(app, event)
            app.ax_img_palm.CLim = [app.palm_DensityMin.Value, app.palm_DensityMax.Value];
            % ax_CLim = [app.palm_DensityMin.Value, app.palm_DensityMax.Value];
            % rescale_ImOut_density = rescale(app.mainapp.Ch1Img_filt_densityPALM);
            % scaled_ImOut_density = imadjust(rescale_ImOut_density,[ax_CLim(1)/max(app.mainapp.Ch1Img_filt_densityPALM,[],'all'), ax_CLim(2)/max(app.mainapp.Ch1Img_filt_densityPALM,[],'all')]);
            % app.hsv_image(:,:,3) = scaled_ImOut_density; % Value for PALM localization density
            % app.img_mpalm = hsv2rgb(app.hsv_image);
            % imshow(app.img_mpalm,'Parent',app.ax_img_mpalm);
        end

        % Value changed function: palm_DensityMax
        function palm_DensityMaxValueChanged(app, event)
            app.ax_img_palm.CLim = [app.palm_DensityMin.Value, app.palm_DensityMax.Value];
        end

        % Button pushed function: overlay_loc_palm
        function overlay_loc_palmPushed(app, event)
            dxy = app.mainapp.render_pixelSize.Value;
            Xmin = app.mainapp.Ch1_loc_XYLim(1);Ymin = app.mainapp.Ch1_loc_XYLim(3);
            Xpos_rescale = (app.mainapp.filtD_Ch1_loc_table.Xpos-Xmin)./dxy+0.5 ;
            Ypos_rescale = (app.mainapp.filtD_Ch1_loc_table.Ypos-Ymin)./dxy+0.5 ;
            
            % overlay detection localization
            hold(app.ax_img_palm,'on');
            app.scatter_dot_palm = scatter(app.ax_img_palm,Xpos_rescale,Ypos_rescale,15,'g','filled');                
            hold(app.ax_img_palm,'off');
        end

        % Button pushed function: clear_loc_palm
        function clear_loc_palmPushed(app, event)
            delete(app.scatter_dot_palm)
        end

        % Value changed function: ExporttifButton_3
        function ExporttifButton_3ValueChanged(app, event)
            [file,location] = uiputfile('PALM_Ch1.tif','Saving Ch1 densityPALM TIF image');
            % exportgraphics(app.ax_img_palm, fullfile(location, file))
            imgObj = get(app.ax_img_palm, 'Children');            
            % Get the image data from the CData property
            imageData = imgObj.CData;
            % Normalize the image data according to the specified axes limits
            normalizedImage = mat2gray(imageData, app.ax_img_palm.CLim);   
            % Apply the colormap 'hot' to the normalized image
            colormapData = colormap(hot(256)); % Generate a 256-level hot colormap
            rgbImage = ind2rgb(im2uint8(normalizedImage), colormapData);
            % Convert the RGB image to uint8
            rgbImage = im2uint8(rgbImage);
            
            % Save the image data using imwrite
            imwrite(rgbImage, fullfile(location, file));
            imwrite(uint16(app.mainapp.Ch1Img_filt_densityPALM), fullfile(location, sprintf('raw_%s',file)), 'WriteMode', 'overwrite');
            fprintf("Ch1 density PALM exported!\n")
        end

        % Value changed function: SelectZoomROI
        function SelectZoomROIValueChanged(app, event)
            % % Create a draggable rectangle ROI
            % h = imrect(app.ax_img_palm); % This enables you to draw a rectangle by clicking and dragging
            % % Wait for the user to finish drawing the rectangle
            % wait(h);
            % Get the position of the selected rectangle
            % rectPosition = getPosition(h);

            roi = drawrectangle(app.ax_img_palm,'FaceAlpha',0,'Color','g');
            rectPosition = roi.Position;           
            
            % Get the x and y limits of the selected region
            xLimits = [rectPosition(1), rectPosition(1)+rectPosition(3)];
            yLimits = [rectPosition(2), rectPosition(2)+rectPosition(4)];
            
            % Zoom into the selected region
            xlim(app.ax_img_palm, xLimits);
            ylim(app.ax_img_palm, yLimits);

            xlim(app.ax_img_mpalm, xLimits);
            ylim(app.ax_img_mpalm, yLimits);

            xlim(app.ax_img_mobility, xLimits);
            ylim(app.ax_img_mobility, yLimits);

            app.ROIcoord_1.Value = rectPosition(1);
            app.ROIcoord_2.Value = rectPosition(2);
            app.ROIcoord_3.Value = rectPosition(3);
            app.ROIcoord_4.Value = rectPosition(4);

            delete(roi);

        end

        % Value changed function: ZoomROIReset
        function ZoomROIResetValueChanged(app, event)
            axis(app.ax_img_palm,'auto');
            axis(app.ax_img_mobility,'auto');
            axis(app.ax_img_mpalm,'auto');
        end

        % Button pushed function: LineProfile_mpalm
        function LineProfile_mpalmButtonPushed(app, event)
            figure; ax1 = axes('Position',[0 0 1 1]);
            imshow(app.mainapp.rgbImOut_mobility,'Parent',ax1);
            title('Click two points to draw a line for D profile');
            
            % Let the user draw a line by clicking two points on the image
            line_points = ginput(2);
            
            % Get intensity profile values along the line ignoring NaN values
            [cx, cy, c] = improfile(app.img_mpalm_D, line_points(:,1), line_points(:,2));
            valid_indices = ~isnan(c);
            cx_valid = cx(valid_indices);
            cy_valid = cy(valid_indices);
            c_valid = c(valid_indices);
            
            % Calculate the distance between endpoints of the line
            distance = sqrt((line_points(2,1) - line_points(1,1))^2 + (line_points(2,2) - line_points(1,2))^2);
            
            % Plot the intensity profile across the distance
            figure;
            plot(linspace(0, distance, length(c_valid)).*app.mainapp.render_pixelSize.Value, c_valid);
            xlabel('Distance along the line (nm)');
            ylabel('D Value');
            title('D Profile along the Line');
        end

        % Button pushed function: FinerrenderButton
        function FinerrenderButtonPushed(app, event)
            dxy = app.mainapp.render_pixelSize.Value;
            Xmin = app.mainapp.Ch1_loc_XYLim(1);Ymin = app.mainapp.Ch1_loc_XYLim(3);
            Xpos_rescale = (app.mainapp.filtD_Ch1_loc_table.Xpos-Xmin)./dxy+0.5 ;
            Ypos_rescale = (app.mainapp.filtD_Ch1_loc_table.Ypos-Ymin)./dxy+0.5 ;
            
            % % overlay detection localization
            % hold(app.ax_img_palm,'on');
            % app.scatter_dot_palm = scatter(app.ax_img_palm,Xpos_rescale,Ypos_rescale,15,'g','filled');                
            % hold(app.ax_img_palm,'off');
            
            roi = drawrectangle(app.ax_img_palm,"FaceAlpha",0);
            
            roi_pos = roi.Position;
            
            selected_indices = find(Xpos_rescale >= roi_pos(1) & Xpos_rescale <= roi_pos(1) + roi_pos(3) & Ypos_rescale >= roi_pos(2) & Ypos_rescale <= roi_pos(2) + roi_pos(4));
            app.roi_Ch1_loc_table = app.mainapp.filtD_Ch1_loc_table(selected_indices,:);

            finer_rendering(app);

            % dxy = 2; % unit nm, The spatial bins for calculating the Gaussian must be at least as small as half the size of the smallest calculated σxy, but do not otherwise affect the resolution of the final image
            % sigma = 10;
            % Xmin = min(app.roi_Ch1_loc_table.Xpos);Ymin = min(app.roi_Ch1_loc_table.Ypos); % unit nm
            % % Xpos_rescale = (app.roi_Ch1_loc_table.Xpos-Xmin)./dxy+0.5 ; % unit grid pixel
            % % Ypos_rescale = (app.roi_Ch1_loc_table.Ypos-Ymin)./dxy+0.5 ;
            % 
            % Xpos = app.roi_Ch1_loc_table.Xpos;
            % Ypos = app.roi_Ch1_loc_table.Ypos;
            % Xmin = min(Xpos);Xmax=max(Xpos);Ymin = min(Ypos);Ymax=max(Ypos);
            % 
            % 
            % [Xgrid,Ygrid] = meshgrid(Xmin:dxy:Xmax, Ymin:dxy:Ymax);
            % f = zeros(size(Xgrid));
            % A = 1;
            % 
            % tic;
            % progressbarText(0);
            % for i = 1:length(Xpos)
            % 
            %     x0 = Xpos(i);
            %     y0 = Ypos(i);
            % 
            %     f = f + A*exp(-((Xgrid-x0).^2+(Ygrid-y0).^2)./(2*sigma^2));
            %     progressbarText(i/length(Xpos));
            % end
            % toc;
            % 
            % sum_f = sum(f,'all');
            % pdf_f = f/sum_f;
            % 
            % figure
            % imagesc(pdf_f);axis image;colormap('hot')
        end

        % Button pushed function: DdensitycorrButton
        function DdensitycorrButtonPushed(app, event)
            roi = drawrectangle(app.ax_img_palm,"FaceAlpha",0);            
            
            roi_palm = GetRoiImage(roi,app.mainapp.Ch1Img_filt_densityPALM);
            roi_mpalm = GetRoiImage(roi,app.img_mpalm_D);

            % Reshape the matrices to a column vector for easier comparison
            roi_palm_vec = roi_palm(:);
            roi_mpalm_vec = roi_mpalm(:);

            % Find indices of NaN values in both vectors
            nan_indices = isnan(roi_palm_vec) | isnan(roi_mpalm_vec);
            
            % Remove NaN values from both vectors
            roi_palm_vec_clean = roi_palm_vec(~nan_indices);
            roi_mpalm_vec_clean = roi_mpalm_vec(~nan_indices);
            
            % Calculate the correlation coefficient for non-NaN values
            correlation_values = corrcoef(roi_palm_vec_clean, roi_mpalm_vec_clean);
            
            % Plot the correlation values
            figure;ax = gca;
            scatter(roi_palm_vec_clean, log10(roi_mpalm_vec_clean));
            xlabel('Molecule localization density (um^-2)');
            ylabel('Molecule mobility (um^2/s)');
            title(['Pixel Value Correlation: ', num2str(correlation_values(1, 2))]);
            
            % Optionally, add a regression line to the scatter plot
            hold on;
            p = polyfit(roi_palm_vec_clean, log10(roi_mpalm_vec_clean), 1);
            x = linspace(min(roi_palm_vec_clean),max(roi_palm_vec_clean),100);
            f = polyval(p, x);
            plot(x, f, 'r--');
            legend('Pixel Values', 'Regression Line');


            % % Calculate the correlation coefficient for each pair of pixels
            % correlation_values = corrcoef(roi_palm_vec, roi_mpalm_vec);
            % 
            % % Plot the correlation values
            % figure;
            % scatter(roi_palm_vec, roi_mpalm_vec);
            % xlabel('ROI Palm Pixel Values');
            % ylabel('ROI Modified Palm Pixel Values');
            % title(['Pixel Value Correlation: ', num2str(correlation_values(1, 2))]);
            % 
            % % Optionally, you can also add a regression line to the scatter plot
            % hold on;
            % p = polyfit(roi_palm_vec, roi_mpalm_vec, 1);
            % f = polyval(p, roi_palm_vec);
            % plot(roi_palm_vec, f, 'r--');
            % legend('Pixel Values', 'Regression Line');
            
            function roi_img = GetRoiImage(roi,img)
                roi_pos = roi.Position;
                % Obtain matrix
                % Get the corner coordinates of the ROI
                x1 = round(roi_pos(1));
                y1 = round(roi_pos(2));
                x2 = round(roi_pos(1) + roi_pos(3));
                y2 = round(roi_pos(2) + roi_pos(4));
                
                % Ensure that the coordinates are within the image boundaries
                x1 = max(1, x1);
                y1 = max(1, y1);
                x2 = min(size(img, 2), x2);
                y2 = min(size(img, 1), y2);
                
                % Extract the pixel region inside the ROI
                roi_img = img(y1:y2, x1:x2, :);
            end


        end

        % Button pushed function: ZoomROIGoButton
        function ZoomROIGoButtonPushed(app, event)
            rectPosition(1) = app.ROIcoord_1.Value;
            rectPosition(2) = app.ROIcoord_2.Value;
            rectPosition(3) = app.ROIcoord_3.Value;
            rectPosition(4) = app.ROIcoord_4.Value;

            % Get the x and y limits of the selected region
            xLimits = [rectPosition(1), rectPosition(1)+rectPosition(3)];
            yLimits = [rectPosition(2), rectPosition(2)+rectPosition(4)];
                        
            % Zoom into the selected region
            xlim(app.ax_img_palm, xLimits);
            ylim(app.ax_img_palm, yLimits);

            xlim(app.ax_img_mpalm, xLimits);
            ylim(app.ax_img_mpalm, yLimits);

            xlim(app.ax_img_mobility, xLimits);
            ylim(app.ax_img_mobility, yLimits);
            
        end

        % Button pushed function: RDFButton
        function RDFButtonPushed(app, event)
            params.dr = app.mainapp.render_pixelSize.Value;
            params.calib = 0.2*app.mainapp.render_pixelSize.Value;            
            dxy = app.mainapp.render_pixelSize.Value;            
            params.num_pts = 15;
            
            Xmin = app.mainapp.Ch1_loc_XYLim(1);Xmax=app.mainapp.Ch1_loc_XYLim(2);Ymin = app.mainapp.Ch1_loc_XYLim(3);Ymax=app.mainapp.Ch1_loc_XYLim(4);
            data1_x = (app.mainapp.filtD_Ch1_loc_table.Xpos-Xmin)./dxy+0.5 ; % unit rendered pixel
            data1_y = (app.mainapp.filtD_Ch1_loc_table.Ypos-Ymin)./dxy+0.5 ; % unit rendered pixel

            roi = drawrectangle(app.ax_img_palm);

            [m,n] = size(app.mainapp.Ch1Img_filt_densityPALM);
            bw = createMask(roi,m,n);

            fprintf("Calculating RDF for the selected ROI ...")
            [corrs, params] = ParCalculateXcorrApp(data1_x, data1_y, bw, params); 
            
            % calculating Ripley'K, L, H function
            pos = [data1_x, data1_y];
            r = 0:(params.dr/params.calib):params.max_r; % unit rendered pixel
            [K, L] = RipleyK(app,pos,bw,roi,r);
            H = L-params.radii/params.calib;

            % Plot results
            figure('Units','inches','Position',[7.1562 3.8854 5.8333 2.7812]); tiledlayout(1,2);
            nexttile; hold on;
            errorbar(params.radii,corrs.C_11,corrs.dC_11,'s-','MarkerFaceColor','b','MarkerEdgeColor','b','Color','b','MarkerSize',2)
            plot([0 max(params.radii)], [1 1], 'k--', 'LineWidth', 1, 'HandleVisibility','off');
            ylabel('G(r)');xlabel('r(nm)');
            ylim([1 3.5])
            %xlim([0 300])
            axis square

            nexttile; hold on;
            plot(params.radii,H);
            plot([0 max(params.radii)], [0 0], 'k--', 'LineWidth', 1, 'HandleVisibility','off');
            ylabel("Ripley's \it H(r)")
            xlabel('r(nm)')
            %ylim([0 20])
            axis square
        end

        % Button pushed function: MDFButton
        function MDFButtonPushed(app, event)
               
            calib = app.mainapp.render_pixelSize.Value;
            dxy = app.mainapp.render_pixelSize.Value;
            dr = app.mainapp.render_pixelSize.Value;
            num_pts = 30;

            Xmin = app.mainapp.Ch1_loc_XYLim(1);Xmax=app.mainapp.Ch1_loc_XYLim(2);Ymin = app.mainapp.Ch1_loc_XYLim(3);Ymax=app.mainapp.Ch1_loc_XYLim(4);
            data1_x = (app.mainapp.filtD_Ch1_loc_table.Xpos-Xmin)./dxy+0.5 ; % unit rendered pixel
            data1_y = (app.mainapp.filtD_Ch1_loc_table.Ypos-Ymin)./dxy+0.5 ; % unit rendered pixel
            pos = [data1_x, data1_y, app.mainapp.filtD_Ch1_loc_table.D];

            roi = drawrectangle(app.ax_img_palm);
            max_r = num_pts * (dr/calib); % unit rendered pixel
            r = 0:(dr/calib):max_r; % unit rendered pixel
            
            rad_dist_bins = 0:(dr/calib):max_r; % the 1D spatial bins to histogram radii
            inner_rads = rad_dist_bins(1:end-1); % inner radius for each ring
            outer_rads = rad_dist_bins(2:end); %outer radius for each ring
            mean_rads = (inner_rads+outer_rads)/2; %center point of ring 
            
            params.radii = mean_rads*calib; 
            
            %% MDF
            % pos: [x, y]
            % mask: mask region used to calculate average particle density
            % roi: roi object that generate the mask
            % r: step to measure the RipleyK, e.g.: 0:10:500
            
            % Calculate number of bins in x and y directions
            [num_bins_y,num_bins_x] = size(app.mainapp.Ch1Img_filt_densityPALM);
            
            grid_indices_x = discretize(pos(:,1),1:num_bins_x+1);
            grid_indices_y = discretize(pos(:,2),1:num_bins_y+1);
            
            % Loop through each point to compute K and L
            inroi_pos = inROI(roi,pos(:,1),pos(:,2));

            [m,n] = size(app.mainapp.Ch1Img_filt_densityPALM);
            bw = createMask(roi,m,n);

            % create a mask to get only locs in the high density            
            dense_roi_mask = bw & logical(app.mainapp.simple_partition_Ch1);

            % Convert points to subscripts
            sub = round(pos); % rounding to get integer subscripts
            ind = sub2ind(size(bw), sub(:, 2), sub(:, 1)); % assuming points is in format [x y]
            
            % Query if points are inside the binary mask
            points_inside_bw = dense_roi_mask(ind) == 1;
            % points_inside_simple_partition = app.simple_partition_Ch1(ind) > 0;
            % inroi_densePos = pos(points_inside_bw,:);

            inroi_idx = find(inroi_pos);
            tot_inroi = length(inroi_idx);
            mdf = zeros(num_pts,tot_inroi);
            
            fprintf("Calculating mobility distribution function (mdf) for the selected ROI ...\n")
            progressbarText(0);
            for it = 1:tot_inroi
                inroi_it = inroi_idx(it);
                % Find neighboring points within the same or adjacent grid cells
                x_idx = grid_indices_x(inroi_it);
                y_idx = grid_indices_y(inroi_it);
            
                % Define search range based on current point's grid indices
                x_range = max(1, x_idx - max_r):min(num_bins_x, x_idx + max_r);
                y_range = max(1, y_idx - max_r):min(num_bins_y, y_idx + max_r);
            
                % Get indices of points within the same or adjacent grid  cells 
                neighbor_indices = find(ismember(grid_indices_x, x_range) & ismember(grid_indices_y, y_range));
            
                % Compute distances within the defined radius
                distances = sqrt((pos(neighbor_indices, 1) - pos(inroi_it, 1)).^2 + (pos(neighbor_indices, 2) - pos(inroi_it, 2)).^2);
        
                % Compute distances in each bin
                [~, ~, binIdx] = histcounts(distances, r);
        
                % inroi_pos = pos(inroi_it,:);
                neighbor_pos = pos(neighbor_indices,:);
                valid_binIdx = binIdx(binIdx~=0);
                valid_neighbor_pos = neighbor_pos(binIdx ~= 0,:);
                
                % Count the number of elements in each bin
                num_elements = accumarray(valid_binIdx, 1); 
                
                % Calculate MDF using vectorized operations
                temp_mdf = accumarray(valid_binIdx, valid_neighbor_pos(:,3)) ./ num_elements;
                mdf(:,it) = temp_mdf;                                               
                progressbarText(it/tot_inroi);
            end
            
            mean_mdf = mean(mdf,2,"omitnan");
            std_mdf = std(mdf,0,2,"omitnan");
            
            fprintf("Calculating mobility distribution function of dense locs (dense-mdf) for the selected ROI ...\n")

            inroiDense_idx = find(points_inside_bw);
            tot_inroi = length(inroiDense_idx);
            dense_mdf = zeros(num_pts,tot_inroi);

            progressbarText(0);
            for it = 1:tot_inroi
                inroi_it = inroiDense_idx(it);
                % Find neighboring points within the same or adjacent grid cells
                x_idx = grid_indices_x(inroi_it);
                y_idx = grid_indices_y(inroi_it);
            
                % Define search range based on current point's grid indices
                x_range = max(1, x_idx - max_r):min(num_bins_x, x_idx + max_r);
                y_range = max(1, y_idx - max_r):min(num_bins_y, y_idx + max_r);
            
                % Get indices of points within the same or adjacent grid
                % cells and also outside high density region
                neighbor_indices = find(ismember(grid_indices_x, x_range) & ismember(grid_indices_y, y_range));
            
                % Compute distances within the defined radius
                distances = sqrt((pos(neighbor_indices, 1) - pos(inroi_it, 1)).^2 + (pos(neighbor_indices, 2) - pos(inroi_it, 2)).^2);
        
                % Compute distances in each bin
                [~, ~, binIdx] = histcounts(distances, r);
        
                neighbor_pos = pos(neighbor_indices,:);
                valid_binIdx = binIdx(binIdx~=0);
                valid_neighbor_pos = neighbor_pos(binIdx ~= 0,:);
                
                % Count the number of elements in each bin
                num_elements = accumarray(valid_binIdx, 1); 
                
                % Calculate MDF using vectorized operations
                temp_mdf = accumarray(valid_binIdx, valid_neighbor_pos(:,3)) ./ num_elements;
                dense_mdf(:,it) = temp_mdf;                                               
                progressbarText(it/tot_inroi);
            end

            mean_dense_mdf = mean(dense_mdf,2,"omitnan");
            std_dense_mdf = std(dense_mdf,0,2,"omitnan");
            
            
            % invalid = sum(isnan(dense_mdf),1) > 0;
            % nonan_dense_mdf = dense_mdf(:,invalid == 0);
            % diff_dense_mdf = diff(nonan_dense_mdf,1,1);
            % mean_diffdense_mdf = mean(diff_dense_mdf,2);
            % std_diffdense_mdf = std(diff_dense_mdf,0,2);

            % plot the results
            figure('Units','inches','Position',[7.1562 3.8854 8.6 2.7812]); tiledlayout(1,3);
            nexttile;
            errorbar(params.radii,mean_mdf,std_mdf,'s-','MarkerFaceColor','b','MarkerEdgeColor','b','Color','b','MarkerSize',2)
            ylabel('MDF(r)')
            xlabel('r(nm)')            
            axis square

            nexttile;
            errorbar(params.radii,mean_dense_mdf,std_dense_mdf,'s-','MarkerFaceColor','b','MarkerEdgeColor','b','Color','b','MarkerSize',2)
            ylabel("Dense MDF(r)")
            xlabel('r(nm)')
            axis square

            nexttile;
            x = params.radii(2:end);
            y = diff(mean_dense_mdf);
            yy = smooth(x,y);
            plot(x,y,'b.',x,yy,'r-')
            ylabel("\Delta dense MDF(r)")
            xlabel('r(nm)')
            axis square
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ax_img_mpalm = uiaxes(app.UIFigure);
            zlabel(app.ax_img_mpalm, 'Z')
            app.ax_img_mpalm.XLimitMethod = 'tight';
            app.ax_img_mpalm.YLimitMethod = 'tight';
            app.ax_img_mpalm.XTick = [];
            app.ax_img_mpalm.YTick = [];
            app.ax_img_mobility = uiaxes(app.UIFigure);
            zlabel(app.ax_img_mobility, 'Z')
            app.ax_img_mobility.XLimitMethod = 'tight';
            app.ax_img_mobility.YLimitMethod = 'tight';
            app.ax_img_mobility.XTick = [];
            app.ax_img_mobility.YTick = [];
            app.ax_img_palm = uiaxes(app.UIFigure);
            zlabel(app.ax_img_palm, 'Z')
            app.ax_img_palm.XLimitMethod = 'tight';
            app.ax_img_palm.YLimitMethod = 'tight';
            app.ax_img_palm.XTick = [];
            app.ax_img_palm.YTick = [];
            app.DensityMinSpinnerLabel = uilabel(app.UIFigure);
            app.DensityMinSpinnerLabel.Text = 'Density Min';
            app.mpalm_DensityMin = uispinner(app.UIFigure);
            app.mpalm_DensityMin.Limits = [0 Inf];
            app.mpalm_DensityMin.ValueChangedFcn = createCallbackFcn(app, @mpalm_DensityMinValueChanged, true);
            app.DensityMaxSpinnerLabel = uilabel(app.UIFigure);
            app.DensityMaxSpinnerLabel.Text = 'Density Max';
            app.mpalm_DensityMax = uispinner(app.UIFigure);
            app.mpalm_DensityMax.Limits = [0 Inf];
            app.mpalm_DensityMax.ValueChangedFcn = createCallbackFcn(app, @mpalm_DensityMaxValueChanged, true);
            app.overlay_loc_mpalm = uibutton(app.UIFigure, 'push');
            app.overlay_loc_mpalm.ButtonPushedFcn = createCallbackFcn(app, @overlay_loc_mpalmPushed, true);
            app.overlay_loc_mpalm.Text = 'Overlay localizations';
            app.clear_loc_mpalm = uibutton(app.UIFigure, 'push');
            app.clear_loc_mpalm.ButtonPushedFcn = createCallbackFcn(app, @clear_loc_mpalmPushed, true);
            app.clear_loc_mpalm.Text = 'Clear localizations';
            app.ExporttifButton = uibutton(app.UIFigure, 'state');
            app.ExporttifButton.ValueChangedFcn = createCallbackFcn(app, @ExporttifButtonValueChanged, true);
            app.ExporttifButton.Text = 'Export tif';
            app.OverlayCh2Button = uibutton(app.UIFigure, 'push');
            app.OverlayCh2Button.ButtonPushedFcn = createCallbackFcn(app, @OverlayCh2ButtonPushed, true);
            app.OverlayCh2Button.Text = 'Overlay Ch2';
            app.Ch2opacityEditFieldLabel = uilabel(app.UIFigure);
            app.Ch2opacityEditFieldLabel.Text = 'Ch2 opacity';
            app.Ch2opacityEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ch2opacityEditField.Value = 0.5;
            app.ExporttifButton_2 = uibutton(app.UIFigure, 'state');
            app.ExporttifButton_2.ValueChangedFcn = createCallbackFcn(app, @ExporttifButton_2ValueChanged, true);
            app.ExporttifButton_2.Text = 'Export tif';
            app.Ch2opacityEditField_2Label = uilabel(app.UIFigure);
            app.Ch2opacityEditField_2Label.Text = 'Ch2 opacity';
            app.Ch2opacityEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Ch2opacityEditField_2.Value = 0.8;
            app.OverlayCh2Button_2 = uibutton(app.UIFigure, 'push');
            app.OverlayCh2Button_2.ButtonPushedFcn = createCallbackFcn(app, @OverlayCh2Button_2Pushed, true);
            app.OverlayCh2Button_2.Text = 'Overlay Ch2';
            app.MPALMLabel = uilabel(app.UIFigure);
            app.MPALMLabel.Text = 'MPALM';
            app.DiffusionmapLabel = uilabel(app.UIFigure);
            app.DiffusionmapLabel.Text = 'Diffusion map';
            app.overlay_loc_palm = uibutton(app.UIFigure, 'push');
            app.overlay_loc_palm.ButtonPushedFcn = createCallbackFcn(app, @overlay_loc_palmPushed, true);
            app.overlay_loc_palm.Text = 'Overlay localizations';
            app.clear_loc_palm = uibutton(app.UIFigure, 'push');
            app.clear_loc_palm.ButtonPushedFcn = createCallbackFcn(app, @clear_loc_palmPushed, true);
            app.clear_loc_palm.Text = 'Clear localizations';
            app.ExporttifButton_3 = uibutton(app.UIFigure, 'state');
            app.ExporttifButton_3.ValueChangedFcn = createCallbackFcn(app, @ExporttifButton_3ValueChanged, true);
            app.ExporttifButton_3.Text = 'Export tif';
            app.PALMLabel = uilabel(app.UIFigure);
            app.PALMLabel.Text = 'PALM';
            app.DensityMinSpinner_2Label = uilabel(app.UIFigure);
            app.DensityMinSpinner_2Label.Text = 'Density Min';
            app.palm_DensityMin = uispinner(app.UIFigure);
            app.palm_DensityMin.Limits = [0 Inf];
            app.palm_DensityMin.ValueChangedFcn = createCallbackFcn(app, @palm_DensityMinValueChanged, true);
            app.DensityMaxSpinner_2Label = uilabel(app.UIFigure);
            app.DensityMaxSpinner_2Label.Text = 'Density Max';
            app.palm_DensityMax = uispinner(app.UIFigure);
            app.palm_DensityMax.Limits = [0 Inf];
            app.palm_DensityMax.ValueChangedFcn = createCallbackFcn(app, @palm_DensityMaxValueChanged, true);
            app.SelectZoomROI = uibutton(app.UIFigure, 'state');
            app.SelectZoomROI.ValueChangedFcn = createCallbackFcn(app, @SelectZoomROIValueChanged, true);
            app.SelectZoomROI.Text = 'ZoomROI-Draw';
            app.ZoomROIReset = uibutton(app.UIFigure, 'state');
            app.ZoomROIReset.ValueChangedFcn = createCallbackFcn(app, @ZoomROIResetValueChanged, true);
            app.ZoomROIReset.Text = 'Reset ROI';
            app.LineProfile_mpalm = uibutton(app.UIFigure, 'push');
            app.LineProfile_mpalm.ButtonPushedFcn = createCallbackFcn(app, @LineProfile_mpalmButtonPushed, true);
            app.LineProfile_mpalm.Text = 'Line Profile';
            app.FinerrenderButton = uibutton(app.UIFigure, 'push');
            app.FinerrenderButton.ButtonPushedFcn = createCallbackFcn(app, @FinerrenderButtonPushed, true);
            app.FinerrenderButton.Text = 'Finer render';
            app.DdensitycorrButton = uibutton(app.UIFigure, 'push');
            app.DdensitycorrButton.ButtonPushedFcn = createCallbackFcn(app, @DdensitycorrButtonPushed, true);
            app.DdensitycorrButton.Text = 'D-density corr';
            app.ROIcoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.ROIcoordinateEditFieldLabel.Text = 'ROI coordinate';
            app.ROIcoord_1 = uieditfield(app.UIFigure, 'numeric');
            app.ROIcoord_2 = uieditfield(app.UIFigure, 'numeric');
            app.ROIcoord_3 = uieditfield(app.UIFigure, 'numeric');
            app.ROIcoord_4 = uieditfield(app.UIFigure, 'numeric');
            app.ZoomROIGoButton = uibutton(app.UIFigure, 'push');
            app.ZoomROIGoButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomROIGoButtonPushed, true);
            app.ZoomROIGoButton.Text = 'ZoomROI-Go';
            app.RDFButton = uibutton(app.UIFigure, 'push');
            app.RDFButton.ButtonPushedFcn = createCallbackFcn(app, @RDFButtonPushed, true);
            app.RDFButton.Text = 'RDF';
            app.MDFButton = uibutton(app.UIFigure, 'push');
            app.MDFButton.ButtonPushedFcn = createCallbackFcn(app, @MDFButtonPushed, true);
            app.MDFButton.Enable = 'off';
            app.MDFButton.Text = 'MDF';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coarse_rendering(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end