classdef finer_rendering < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        overlay_loc_mpalm         matlab.ui.control.Button
        clear_loc_mpalm           matlab.ui.control.Button
        MPALMLabel_2              matlab.ui.control.Label
        PALMLabel_2               matlab.ui.control.Label
        ScatterplotLabel          matlab.ui.control.Label
        render_min_mpalm          matlab.ui.control.Spinner
        DensityminSpinner_2Label  matlab.ui.control.Label
        render_max_mpalm          matlab.ui.control.Spinner
        DensitymaxSpinner_2Label  matlab.ui.control.Label
        export_img_mobility       matlab.ui.control.Button
        export_img_mpalm          matlab.ui.control.Button
        render_max_palm           matlab.ui.control.Spinner
        DensitymaxSpinnerLabel    matlab.ui.control.Label
        render_min_palm           matlab.ui.control.Spinner
        DensityminSpinnerLabel    matlab.ui.control.Label
        clear_loc_palm            matlab.ui.control.Button
        export_img_palm           matlab.ui.control.Button
        overlay_loc_palm          matlab.ui.control.Button
        RefreshButton             matlab.ui.control.Button
        gauss_sigma               matlab.ui.control.NumericEditField
        GausssigmaEditFieldLabel  matlab.ui.control.Label
        pixel_size                matlab.ui.control.NumericEditField
        GridsizeEditFieldLabel    matlab.ui.control.Label
        DiffusionmapLabel         matlab.ui.control.Label
        MPALMLabel                matlab.ui.control.Label
        PALMLabel                 matlab.ui.control.Label
        ax_img_mobility           matlab.ui.control.UIAxes
        ax_img_mpalm              matlab.ui.control.UIAxes
        ax_img_palm               matlab.ui.control.UIAxes
        ax_plot_scatter           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        roi_Ch1_loc_table % Description
        coarse_rendering % Description
        img_mobility % Description
        local_cmap_mpalm % Description
        rgbImOut_mobility % Description
        Im_density % Description
        hsv_image % Description
        img_mpalm % Description
        scatter_dot_palm % Description        
        scatter_dot_mpalm % Description
        adjust_Im_density % Description
    end
    
    methods (Access = private)
        
        function RenderAll(app)
            dxy = app.pixel_size.Value; % unit nm, The spatial bins for calculating the Gaussian must be at least as small as half the size of the smallest calculated σxy, but do not otherwise affect the resolution of the final image
            sigma = app.gauss_sigma.Value; % unit nm

            % Xmin = min(app.roi_Ch1_loc_table.Xpos);Ymin = min(app.roi_Ch1_loc_table.Ypos); % unit nm
            % Xpos_rescale = (app.roi_Ch1_loc_table.Xpos-Xmin)./dxy+0.5 ; % unit grid pixel
            % Ypos_rescale = (app.roi_Ch1_loc_table.Ypos-Ymin)./dxy+0.5 ;

            Xpos = app.roi_Ch1_loc_table.Xpos;
            Ypos = app.roi_Ch1_loc_table.Ypos;
            Xmin = min(Xpos);Xmax=max(Xpos);Ymin = min(Ypos);Ymax=max(Ypos);

            [Xgrid,Ygrid] = meshgrid(Xmin:dxy:Xmax, Ymin:dxy:Ymax);
            f = zeros(size(Xgrid));
            A = 1;

            % ---------- PALM render ---------- %
            tic;
            progressbarText(0);
            for i = 1:length(Xpos)

                x0 = Xpos(i);
                y0 = Ypos(i);

                f = f + A*exp(-((Xgrid-x0).^2+(Ygrid-y0).^2)./(2*sigma^2));
                progressbarText(i/length(Xpos));
            end
            toc;
            % Normalizing to get PDF
            sum_f = sum(f,'all');
            Im_density = f/sum_f;
            app.Im_density = Im_density;

            app.adjust_Im_density = imadjust(app.Im_density);

            imshow(app.adjust_Im_density,'Parent',app.ax_img_palm,Colormap=hot)
            % axis(app.ax_img_palm, 'tight');

            app.render_min_palm.Value = app.ax_img_palm.CLim(1);
            app.render_max_palm.Value = app.ax_img_palm.CLim(2);
            % imagesc(app.ax_img_palm,pdf_f);axis image;colormap('hot')

            % ---------- MPALM render ---------- %
            D = app.roi_Ch1_loc_table.D;
            [Im,~,~,binY,binX] = histcounts2(Ypos,Xpos,Ymin:dxy:Ymax+dxy,Xmin:dxy:Xmax+dxy); % bin every localizations into grid, similar to Im = hist3([Xpos',Ypos'],'Edges',Edges);
              
            % Calculate the average Cdata for each bin index
            matrix = accumarray([binY(:),binX(:)], D(:), size(Im), @mean);
            
            % Create a logical mask indicating the zero elements
            zeroMask = (matrix == 0);
            
            % Compute the weighted average of the Gaussian distribution for zero elements
            if any(zeroMask(:))
            
                % Get the size of the matrix
                [M, N] = size(matrix);
                
                % Initialize the filtered matrix
                filteredMatrix = matrix;
                
                % Loop over each element in the matrix
                for i = 1:M
                    for j = 1:N
                        if zeroMask(i, j) % if this pixel is zero
                            % Initialize total weight and weighted sum
                            totalWeight = 0;
                            weightedSum = 0;
                            
                            % Loop over neighbors (a 3x3 pixel square center by the current zero-value pixel when sigma_render equals dxy)
                            for dx = -round(1*sigma/dxy):round(1*sigma/dxy)  %-round(2*sigma_render/dxy):round(2*sigma_render/dxy)
                                for dy = -round(1*sigma/dxy):round(1*sigma/dxy) %-round(2*sigma_render/dxy):round(2*sigma_render/dxy)
                                    % Check if neighbor is within the matrix bounds
                                    if i+dx >= 1 && i+dx <= M && j+dy >= 1 && j+dy <= N
                                        % Check if neighbor is non-zero
                                        if ~zeroMask(i+dx, j+dy)
                                            % Compute weights based on Gaussian distribution
                                            weightX = exp(-0.5*(dx*dxy/sigma).^2); %exp(-0.5*(dx)^2);
                                            weightY = exp(-0.5*(dy*dxy/sigma).^2); %exp(-0.5*(dy)^2);
                                            
                                            % Accumulate weighted sum and total weight
                                            weightedSum = weightedSum + matrix(i+dx, j+dy) * weightX * weightY * Im_density(i+dx, j+dy);
                                            totalWeight = totalWeight + weightX * weightY * Im_density(i+dx, j+dy);
                                        end
                                    end
                                end
                            end
                            
                            % Compute the weighted average for the zero element
                            filteredMatrix(i, j) = weightedSum / totalWeight;
                        end
                    end
                end
            else
                % If there are no zero elements, the filtered matrix is the same as the original matrix
                filteredMatrix = matrix;
            end
            
            % % Create a binary mask to identify the non-zero values in A
            mask = filteredMatrix ~= 0;

            % % Define the kernel mask for smoothing, not as good as
            % cross-one kernel
            % kernel = [1, 1, 1;
            %           1, 1, 1;
            %           1, 1, 1];

            % cross-one kernel
            kernel = [0, 1, 0;
                      1, 1, 1;
                      0, 1, 0];

            % Normalize the kernel so that its sum is equal to 1
            kernel = kernel / sum(kernel(:));

            % Create a matrix with NaN values at zero positions and non-zero values at non-zero positions in A
            maskedA = filteredMatrix;
            maskedA(mask == 0) = NaN;

            % Perform kernel smoothing on the non-zero elements in A using nanconv
            ImOut_mobility = nanconv(maskedA, kernel,"nanout"); % this get sharper results


            app.img_mobility = ImOut_mobility;
            fprintf('mPALM render done \n')
            % imshow(app.img_mobility,[],'Parent',app.ax_img_mobility,Colormap=parula);
            
            % log scale D colormap
            MinLogD = app.coarse_rendering.mainapp.render_minlogD.Value; % Min of log10(D)
            MaxLogD = app.coarse_rendering.mainapp.render_maxlogD.Value; % Max of log10(D)
            rescale_ImOut_mobility = app.img_mobility;

            % areaEdge = app.areaEdge;
            rescale_ImOut_mobility(rescale_ImOut_mobility < 10^MinLogD & rescale_ImOut_mobility > 0 ) = 10^MinLogD;
            rescale_ImOut_mobility(rescale_ImOut_mobility >  10^MaxLogD) = 10^MaxLogD;
            app.local_cmap_mpalm = app.coarse_rendering.mainapp.local_cmap_mpalm;
            CData_rescale_ImOut_mobility = discretize(rescale_ImOut_mobility,app.coarse_rendering.mainapp.D_edge); 
            CData_rescale_ImOut_mobility = CData_rescale_ImOut_mobility +1;
            CData_rescale_ImOut_mobility(isnan(CData_rescale_ImOut_mobility)) = 1;
            
            %---------- PLOT: mobility map using HSV color scheme ------%
            app.rgbImOut_mobility = ind2rgb(CData_rescale_ImOut_mobility,app.local_cmap_mpalm);
            imshow(app.rgbImOut_mobility,[],'Parent',app.ax_img_mobility);
            % axis(app.ax_img_mobility, 'tight');

            %---------- PLOT: MPALM using HSV color scheme ------%
            app.render_min_mpalm.Value = app.render_min_palm.Value;
            app.render_max_mpalm.Value = app.render_max_palm.Value;
            app.hsv_image = rgb2hsv(app.rgbImOut_mobility);
            % lets construct the HSV coded mobility-PALM image
            % ax_CLim = [app.render_min_mpalm.Value  app.render_max_mpalm.Value];
            % rescale_ImOut_density = rescale(app.Im_density);
            % scaled_ImOut_density = imadjust(rescale_ImOut_density,[ax_CLim(1)/max(app.Im_density,[],'all'), ax_CLim(2)/max(app.Im_density,[],'all')]);
            scaled_ImOut_density = app.adjust_Im_density;


            % >>>> Option1: black background for MPALM
            app.hsv_image(:,:,3) = scaled_ImOut_density; % Value for PALM localization density
            % <<<<

            % >>>> Option2: white background for MPALM
            % app.hsv_image(:,:,2) = scaled_ImOut_density;
            % app.hsv_image(:,:,3) = ones(size(scaled_ImOut_density));
            % <<<<

            app.img_mpalm = hsv2rgb(app.hsv_image);
            imshow(app.img_mpalm,'Parent',app.ax_img_mpalm);
            % axis(app.ax_img_mpalm, 'tight');
            
            %---------- PLOT: scatter plot using HSV color scheme ------%
            dxy = app.pixel_size.Value;
            Xmin = min(app.roi_Ch1_loc_table.Xpos);Ymin = min(app.roi_Ch1_loc_table.Ypos); % unit nm
            Xpos_rescale = (app.roi_Ch1_loc_table.Xpos-Xmin)./dxy+0.5 ; % unit grid pixel
            Ypos_rescale = (app.roi_Ch1_loc_table.Ypos-Ymin)./dxy+0.5 ;

            cidx = discretize(app.roi_Ch1_loc_table.D,app.coarse_rendering.mainapp.D_edge); 
            cidx = cidx +1;
            cidx(app.roi_Ch1_loc_table.D < 10^app.coarse_rendering.mainapp.render_minlogD.Value & app.roi_Ch1_loc_table.D > 0) = 2;
            cidx(app.roi_Ch1_loc_table.D > 10^app.coarse_rendering.mainapp.render_maxlogD.Value) = 256;
            
            % overlay detection localization            
            scatter(app.ax_plot_scatter,app.roi_Ch1_loc_table.Xpos,app.roi_Ch1_loc_table.Ypos,15,app.coarse_rendering.mainapp.local_cmap_mpalm(cidx,:),'filled');
            axis(app.ax_plot_scatter, "image","ij")
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, coarse_rendering)
            app.coarse_rendering = coarse_rendering;
            app.roi_Ch1_loc_table = app.coarse_rendering.roi_Ch1_loc_table;
            RenderAll(app);          
        end

        % Button pushed function: overlay_loc_mpalm
        function overlay_loc_mpalmButtonPushed(app, event)
            dxy = app.pixel_size.Value;
            Xmin = min(app.roi_Ch1_loc_table.Xpos);Ymin = min(app.roi_Ch1_loc_table.Ypos); % unit nm
            Xpos_rescale = (app.roi_Ch1_loc_table.Xpos-Xmin)./dxy+0.5 ; % unit grid pixel
            Ypos_rescale = (app.roi_Ch1_loc_table.Ypos-Ymin)./dxy+0.5 ;

            cidx = discretize(app.roi_Ch1_loc_table.D,app.coarse_rendering.mainapp.D_edge); 
            cidx = cidx +1;
            cidx(app.roi_Ch1_loc_table.D < 10^app.coarse_rendering.mainapp.render_minlogD.Value & app.roi_Ch1_loc_table.D > 0) = 2;
            cidx(app.roi_Ch1_loc_table.D > 10^app.coarse_rendering.mainapp.render_maxlogD.Value) = 256;
            
            % overlay detection localization
            hold(app.ax_img_mpalm,'on');
            app.scatter_dot_mpalm = scatter(app.ax_img_mpalm,Xpos_rescale,Ypos_rescale,15,app.coarse_rendering.mainapp.local_cmap_mpalm(cidx,:),'filled');                
            hold(app.ax_img_mpalm,'off');

            
        end

        % Button pushed function: overlay_loc_palm
        function overlay_loc_palmButtonPushed(app, event)
            dxy = app.pixel_size.Value;
            Xmin = min(app.roi_Ch1_loc_table.Xpos);Ymin = min(app.roi_Ch1_loc_table.Ypos); % unit nm
            Xpos_rescale = (app.roi_Ch1_loc_table.Xpos-Xmin)./dxy+0.5 ; % unit grid pixel
            Ypos_rescale = (app.roi_Ch1_loc_table.Ypos-Ymin)./dxy+0.5 ;

            % overlay detection localization
            hold(app.ax_img_palm,'on');
            app.scatter_dot_palm = scatter(app.ax_img_palm,Xpos_rescale,Ypos_rescale,15,'g','filled');                
            hold(app.ax_img_palm,'off');
        end

        % Value changed function: render_min_palm
        function render_min_palmValueChanged(app, event)
            app.ax_img_palm.CLim = [app.render_min_palm.Value, app.render_max_palm.Value];
        end

        % Value changed function: render_max_palm
        function render_max_palmValueChanged(app, event)
            app.ax_img_palm.CLim = [app.render_min_palm.Value, app.render_max_palm.Value];
        end

        % Value changed function: render_min_mpalm
        function render_min_mpalmValueChanged(app, event)
            
            ax_CLim = [app.render_min_mpalm.Value, app.render_max_mpalm.Value];
            % rescale_ImOut_density = rescale(app.Im_density);
            % scaled_ImOut_density = imadjust(rescale_ImOut_density,[ax_CLim(1)/max(app.Im_density,[],'all'), ax_CLim(2)/max(app.Im_density,[],'all')]);
            
            rescale_ImOut_density = app.adjust_Im_density;

            if ax_CLim(2) < 1
                scaled_ImOut_density = imadjust(rescale_ImOut_density,[ax_CLim(1),ax_CLim(2)]);
            elseif ax_CLim(2) >= 1
                scaled_ImOut_density = imadjust(rescale_ImOut_density,[0 1],[ax_CLim(1),1/ax_CLim(2)]);
            end
            
            % >>>> Option1: black background for MPALM
            app.hsv_image(:,:,3) = scaled_ImOut_density; % Value for PALM localization density
            % <<<<
            app.img_mpalm = hsv2rgb(app.hsv_image);
            imshow(app.img_mpalm,'Parent',app.ax_img_mpalm);
        end

        % Value changed function: render_max_mpalm
        function render_max_mpalmValueChanged(app, event)
            
            ax_CLim = [app.render_min_mpalm.Value, app.render_max_mpalm.Value];
            % rescale_ImOut_density = rescale(app.Im_density);
            % scaled_ImOut_density = imadjust(rescale_ImOut_density,[ax_CLim(1)/max(app.Im_density,[],'all'), ax_CLim(2)/max(app.Im_density,[],'all')]);
            
            rescale_ImOut_density = app.adjust_Im_density;

            if ax_CLim(2) < 1
                scaled_ImOut_density = imadjust(rescale_ImOut_density,[ax_CLim(1),ax_CLim(2)]);
            elseif ax_CLim(2) >= 1
                scaled_ImOut_density = imadjust(rescale_ImOut_density,[0 1],[ax_CLim(1),1/ax_CLim(2)]);
            end

            % rescale_ImOut_density = app.adjust_Im_density;
            % scaled_ImOut_density = imadjust(rescale_ImOut_density,[min(app.adjust_Im_density,[],'all'),max(app.adjust_Im_density,[],'all')],[min(ax_CLim)/sum(ax_CLim),max(ax_CLim)/sum(ax_CLim)]);

            % >>>> Option1: black background for MPALM
            app.hsv_image(:,:,3) = scaled_ImOut_density; % Value for PALM localization density
            % <<<<
            app.img_mpalm = hsv2rgb(app.hsv_image);
            imshow(app.img_mpalm,'Parent',app.ax_img_mpalm);
            
        end

        % Button pushed function: clear_loc_palm
        function clear_loc_palmButtonPushed(app, event)
            delete(app.scatter_dot_palm)
        end

        % Button pushed function: clear_loc_mpalm
        function clear_loc_mpalmButtonPushed(app, event)
            delete(app.scatter_dot_mpalm)
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            RenderAll(app);     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax_plot_scatter = uiaxes(app.UIFigure);
            xlabel(app.ax_plot_scatter, 'X (nm)')
            ylabel(app.ax_plot_scatter, 'Y (nm)')
            zlabel(app.ax_plot_scatter, 'Z')
            app.ax_img_palm = uiaxes(app.UIFigure);
            zlabel(app.ax_img_palm, 'Z')
            app.ax_img_palm.XLimitMethod = 'tight';
            app.ax_img_palm.YLimitMethod = 'tight';
            app.ax_img_palm.XTick = [];
            app.ax_img_palm.YTick = [];
            app.ax_img_mpalm = uiaxes(app.UIFigure);
            zlabel(app.ax_img_mpalm, 'Z')
            app.ax_img_mpalm.XLimitMethod = 'tight';
            app.ax_img_mpalm.YLimitMethod = 'tight';
            app.ax_img_mpalm.XTick = [];
            app.ax_img_mpalm.YTick = [];
            app.ax_img_mobility = uiaxes(app.UIFigure);
            zlabel(app.ax_img_mobility, 'Z')
            app.ax_img_mobility.XLimitMethod = 'tight';
            app.ax_img_mobility.YLimitMethod = 'tight';
            app.ax_img_mobility.XTick = [];
            app.ax_img_mobility.YTick = [];
            app.PALMLabel = uilabel(app.UIFigure);
            app.PALMLabel.Text = 'PALM';
            app.MPALMLabel = uilabel(app.UIFigure);
            app.MPALMLabel.Text = 'MPALM';
            app.DiffusionmapLabel = uilabel(app.UIFigure);
            app.DiffusionmapLabel.Text = 'Diffusion map';
            app.GridsizeEditFieldLabel = uilabel(app.UIFigure);
            app.GridsizeEditFieldLabel.Text = 'Grid size';
            app.pixel_size = uieditfield(app.UIFigure, 'numeric');
            app.pixel_size.Value = 2;
            app.GausssigmaEditFieldLabel = uilabel(app.UIFigure);
            app.GausssigmaEditFieldLabel.Text = 'Gauss sigma';
            app.gauss_sigma = uieditfield(app.UIFigure, 'numeric');
            app.gauss_sigma.Value = 10;
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.overlay_loc_palm = uibutton(app.UIFigure, 'push');
            app.overlay_loc_palm.ButtonPushedFcn = createCallbackFcn(app, @overlay_loc_palmButtonPushed, true);
            app.overlay_loc_palm.Text = 'Overlay localizations';
            app.export_img_palm = uibutton(app.UIFigure, 'push');
            app.export_img_palm.Text = 'Export tif';
            app.clear_loc_palm = uibutton(app.UIFigure, 'push');
            app.clear_loc_palm.ButtonPushedFcn = createCallbackFcn(app, @clear_loc_palmButtonPushed, true);
            app.clear_loc_palm.Text = 'Clear localizations';
            app.DensityminSpinnerLabel = uilabel(app.UIFigure);
            app.DensityminSpinnerLabel.Text = 'Density min';
            app.render_min_palm = uispinner(app.UIFigure);
            app.render_min_palm.Step = 0.1;
            app.render_min_palm.Limits = [0 Inf];
            app.render_min_palm.ValueDisplayFormat = '%.2f';
            app.render_min_palm.ValueChangedFcn = createCallbackFcn(app, @render_min_palmValueChanged, true);
            app.DensitymaxSpinnerLabel = uilabel(app.UIFigure);
            app.DensitymaxSpinnerLabel.Text = 'Density max';
            app.render_max_palm = uispinner(app.UIFigure);
            app.render_max_palm.Step = 0.1;
            app.render_max_palm.Limits = [0 Inf];
            app.render_max_palm.ValueDisplayFormat = '%.2f';
            app.render_max_palm.ValueChangedFcn = createCallbackFcn(app, @render_max_palmValueChanged, true);
            app.export_img_mpalm = uibutton(app.UIFigure, 'push');
            app.export_img_mpalm.Text = 'Export tif';
            app.export_img_mobility = uibutton(app.UIFigure, 'push');
            app.export_img_mobility.Text = 'Export tif';
            app.DensitymaxSpinner_2Label = uilabel(app.UIFigure);
            app.DensitymaxSpinner_2Label.Text = 'Density max';
            app.render_max_mpalm = uispinner(app.UIFigure);
            app.render_max_mpalm.Step = 0.1;
            app.render_max_mpalm.Limits = [0 Inf];
            app.render_max_mpalm.ValueDisplayFormat = '%.2f';
            app.render_max_mpalm.ValueChangedFcn = createCallbackFcn(app, @render_max_mpalmValueChanged, true);
            app.DensityminSpinner_2Label = uilabel(app.UIFigure);
            app.DensityminSpinner_2Label.Text = 'Density min';
            app.render_min_mpalm = uispinner(app.UIFigure);
            app.render_min_mpalm.Step = 0.1;
            app.render_min_mpalm.Limits = [0 1];
            app.render_min_mpalm.ValueDisplayFormat = '%.2f';
            app.render_min_mpalm.ValueChangedFcn = createCallbackFcn(app, @render_min_mpalmValueChanged, true);
            app.ScatterplotLabel = uilabel(app.UIFigure);
            app.ScatterplotLabel.Text = 'Scatter plot';
            app.PALMLabel_2 = uilabel(app.UIFigure);
            app.PALMLabel_2.Text = '------------------------------  PALM   -------------------------------';
            app.MPALMLabel_2 = uilabel(app.UIFigure);
            app.MPALMLabel_2.Text = '------------------------------  MPALM   -------------------------------';
            app.clear_loc_mpalm = uibutton(app.UIFigure, 'push');
            app.clear_loc_mpalm.ButtonPushedFcn = createCallbackFcn(app, @clear_loc_mpalmButtonPushed, true);
            app.clear_loc_mpalm.Text = 'Clear localizations';
            app.overlay_loc_mpalm = uibutton(app.UIFigure, 'push');
            app.overlay_loc_mpalm.ButtonPushedFcn = createCallbackFcn(app, @overlay_loc_mpalmButtonPushed, true);
            app.overlay_loc_mpalm.Text = 'Overlay localizations';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finer_rendering(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end