classdef LocalVersion_mobilityPALM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainAppUIFigure                 matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        LOADCh1Tab                      matlab.ui.container.Tab
        DrawROICheckBox                 matlab.ui.control.CheckBox
        LocalizationNumCutOff           matlab.ui.control.NumericEditField
        LocalizationEditField_2Label    matlab.ui.control.Label
        MovieinfoLabel                  matlab.ui.control.Label
        LoadpartialdataLabel            matlab.ui.control.Label
        loadPartialData2                matlab.ui.control.CheckBox
        D_SRArea_const                  matlab.ui.control.NumericEditField
        FastmobilemeanareaLabel_2       matlab.ui.control.Label
        minPhoton_Ch1                   matlab.ui.control.NumericEditField
        MinallowedphotonLabel           matlab.ui.control.Label
        saveCorrectedTable_Ch1          matlab.ui.control.Button
        driftCorrectionStatus_Ch1       matlab.ui.control.Lamp
        driftCorrectionButton_Ch1       matlab.ui.control.Button
        UseWhichAreaDropDown            matlab.ui.control.DropDown
        UsewhichareaDropDownLabel       matlab.ui.control.Label
        showLocTable                    matlab.ui.control.Button
        exposureTime                    matlab.ui.control.NumericEditField
        CamerapixelsizenmLabel_2        matlab.ui.control.Label
        ImHeight                        matlab.ui.control.NumericEditField
        ImageHeightLabel                matlab.ui.control.Label
        ImWidth                         matlab.ui.control.NumericEditField
        ImageWidthLabel                 matlab.ui.control.Label
        DriftcorrectionLabel            matlab.ui.control.Label
        Stopframe                       matlab.ui.control.NumericEditField
        StopframeLabel                  matlab.ui.control.Label
        Startframe                      matlab.ui.control.NumericEditField
        StartframeEditFieldLabel        matlab.ui.control.Label
        loadPartialData1                matlab.ui.control.CheckBox
        meanLocPerFrame                 matlab.ui.control.NumericEditField
        AveragelocsperframeLabel        matlab.ui.control.Label
        totalLocNumber                  matlab.ui.control.NumericEditField
        TotallocalizationnumberEditFieldLabel  matlab.ui.control.Label
        camPixelSize_Ch1                matlab.ui.control.NumericEditField
        CamerapixelsizenmLabel          matlab.ui.control.Label
        maxPhoton_Ch1                   matlab.ui.control.NumericEditField
        MaxallowedphotonEditFieldLabel  matlab.ui.control.Label
        loadDatasetStatus_Ch1           matlab.ui.control.Lamp
        loadDataset_Ch1                 matlab.ui.control.Button
        Ch1RenderTab                    matlab.ui.container.Tab
        mpalm_moleculeType_minLogD      matlab.ui.control.NumericEditField
        MaxlogDLabel                    matlab.ui.control.Label
        mpalm_moleculeType_maxLogD      matlab.ui.control.NumericEditField
        MinlogDLabel                    matlab.ui.control.Label
        ColorscaleSwitch                matlab.ui.control.RockerSwitch
        ColorscaleSwitchLabel           matlab.ui.control.Label
        locsTypeSwitch                  matlab.ui.control.DropDown
        MoleculetypeDropDownLabel       matlab.ui.control.Label
        MDFButton                       matlab.ui.control.Button
        QuantificationLabel             matlab.ui.control.Label
        RadialDistFcn                   matlab.ui.control.Button
        maxEllipticity                  matlab.ui.control.NumericEditField
        MaxellipticityEditFieldLabel    matlab.ui.control.Label
        minEllipticity                  matlab.ui.control.NumericEditField
        MinellipticityEditFieldLabel    matlab.ui.control.Label
        MobileAnglePALMButton           matlab.ui.control.Button
        render_maxlogD                  matlab.ui.control.NumericEditField
        FastmobilemeanareaLabel         matlab.ui.control.Label
        render_minlogD                  matlab.ui.control.NumericEditField
        ImmobilemeanareaLabel           matlab.ui.control.Label
        immobileAreaPercent             matlab.ui.control.NumericEditField
        ImmobileareapercentageEditFieldLabel  matlab.ui.control.Label
        minMobileD                      matlab.ui.control.NumericEditField
        minmobileDLabel                 matlab.ui.control.Label
        render_PALM_Ch1                 matlab.ui.control.Button
        render_maxDensity_Ch1           matlab.ui.control.NumericEditField
        maxDensityEditFieldLabel        matlab.ui.control.Label
        render_minDensity_Ch1           matlab.ui.control.NumericEditField
        minDensityEditFieldLabel        matlab.ui.control.Label
        PALMrenderingLabel              matlab.ui.control.Label
        PALMBinaryStatus_Ch1            matlab.ui.control.Lamp
        PALMBinaryButton_Ch1            matlab.ui.control.Button
        mPALMrenderingLabel             matlab.ui.control.Label
        SegmentationLabel               matlab.ui.control.Label
        TotalSegmentClassNumber         matlab.ui.control.NumericEditField
        TotalclassEditFieldLabel        matlab.ui.control.Label
        HMRFsegmentStatus               matlab.ui.control.Lamp
        HMRFsegmentButton               matlab.ui.control.Button
        drawROIButton                   matlab.ui.control.Button
        MobilityPALMButton              matlab.ui.control.Button
        render_sigma                    matlab.ui.control.NumericEditField
        RendersigmanmLabel              matlab.ui.control.Label
        render_pixelSize                matlab.ui.control.NumericEditField
        PixelsizenmLabel                matlab.ui.control.Label
        Ch2RenderTab                    matlab.ui.container.Tab
        saveCorrectedTableCh1and2       matlab.ui.control.Button
        BinarySensitivity_Ch2           matlab.ui.control.NumericEditField
        SensitivityEditFieldLabel       matlab.ui.control.Label
        PALMBinaryStatus_Ch2            matlab.ui.control.Lamp
        PALMBinaryButton_Ch2            matlab.ui.control.Button
        PALMRenderSwitch_Ch2            matlab.ui.control.RockerSwitch
        camPixelSize_Ch2                matlab.ui.control.NumericEditField
        CamerapixelsizenmLabel_3        matlab.ui.control.Label
        ImageRegistrationStatus_Ch2     matlab.ui.control.Lamp
        ImageRegistration_Ch2           matlab.ui.control.Button
        ImageRegistrationLabel_Ch2      matlab.ui.control.Label
        meanLocPerFrame_Ch2             matlab.ui.control.NumericEditField
        AveragelocsperframeLabel_2      matlab.ui.control.Label
        totalLocNumber_Ch2              matlab.ui.control.NumericEditField
        TotallocalizationnumberEditFieldLabel_2  matlab.ui.control.Label
        drift_Smoothrange_Ch2           matlab.ui.control.NumericEditField
        SmoothrangeEditFieldLabel_2     matlab.ui.control.Label
        drift_Padding_Ch2               matlab.ui.control.NumericEditField
        PaddingEditFieldLabel_2         matlab.ui.control.Label
        drift_Sigmablur_Ch2             matlab.ui.control.NumericEditField
        SigmablurEditFieldLabel_2       matlab.ui.control.Label
        drift_Binpixelsize_Ch2          matlab.ui.control.NumericEditField
        BinpixelsizeEditFieldLabel_2    matlab.ui.control.Label
        drift_Gapframe_Ch2              matlab.ui.control.NumericEditField
        GapframeEditFieldLabel_2        matlab.ui.control.Label
        drift_Binframe_Ch2              matlab.ui.control.NumericEditField
        BinframeEditFieldLabel_2        matlab.ui.control.Label
        DriftcorrectionLabel_Ch2        matlab.ui.control.Label
        driftCorrectionStatus_Ch2       matlab.ui.control.Lamp
        driftCorrection_Ch2             matlab.ui.control.Button
        render_maxDensity_Ch2           matlab.ui.control.NumericEditField
        maxDensityEditFieldLabel_2      matlab.ui.control.Label
        render_minDensity_Ch2           matlab.ui.control.NumericEditField
        minDensityEditFieldLabel_2      matlab.ui.control.Label
        loadDatasetStatus_Ch2           matlab.ui.control.Lamp
        render_PALM_Ch2                 matlab.ui.control.Button
        loadDataset_Ch2                 matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        Tab1                            matlab.ui.container.Tab
        MaxLogD_plot                    matlab.ui.control.NumericEditField
        MaxLogDEditFieldLabel           matlab.ui.control.Label
        MinLogD_plot                    matlab.ui.control.NumericEditField
        MinLogDEditFieldLabel           matlab.ui.control.Label
        ax_logD_Histogram               matlab.ui.control.UIAxes
        ax_locPerFrame                  matlab.ui.control.UIAxes
        ax_tcPALM                       matlab.ui.control.UIAxes
        ax_mobilityTime                 matlab.ui.control.UIAxes
        Tab2                            matlab.ui.container.Tab
        ax_Ch1_SNR                      matlab.ui.control.UIAxes
        ax_Ch1_photon_histogram         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Ch1_loc_path; % Description
        Ch1_loc_name;
        Ch1_info_path; % Description
        Ch1_info_name;
        
        densityPALMfig; % densityPALM figure object
        zoomScatterfig; % mobilityPALM figure object
        zoomAnglefig % Description
        in_ROI % logical that indicate which locs in corrent roi

        roi_raw_table % locs in roi
        roi_position % Description
        HMRFseg % HMRF segmented results
        full_partition % HMRF segmented chromatin partition using H2B localization density (all classes)
        

        Ch2LocPath % Description
        Ch2LocName % Description
        nucleusROI_path % Description
        nucleusROI_file % Description
        
        Ch2_loc_XYLim
        Ch2Img_densityPALM % Description
        roi_info_nuc % Description

        SeqDriftCorrectionApp % Description
        minMobileSRarea % Description
        cmap_mpalm % Description
        density_mPALM_imadjustApp % Description
        
        drift_fromCh2 % Description
    end
    
    properties (Access = public)
        Ch1_drift1_predict % Description
        Ch1_loc_table;
        filtD_Ch1_loc_table;
        Ch1_loc_XYLim % Description
        areaEdge; % area range for colormap mapping
        Ch1Img_densityPALM % Description
        rgbImOut_mobility % Description
        local_cmap_mpalm % Description
        simple_partition_Ch2 % Description
        img_mobility % Description
        Ch1Img_filt_densityPALM % Description
        logD_edge % Description
        D_edge % Description
        SeqDriftState % Description
        simple_partition_Ch1 % HMRF segmented chromatin partition using H2B localization density (CD and nonCD)

        Ch2_loc_table % Description
    end
    
    methods (Access = private)
        
        function count = DetectionCount(app)
            %Para_DetectionCount Calculate detection counts used in parfor loop
            %
            %   Output: X in unit of exposure time
            %           Y in unit of detection counts
            X = 1:max(app.Ch1_loc_table.Frame);
            Y = zeros(1,length(X));
            for i = 1:length(X)
                NumberOfDetections = sum(app.in_ROI & app.Ch1_loc_table.Frame <= X(i));
                Y(i) = NumberOfDetections;
            end
            Z = cumsum(Y);
            % lineX = [X;X];
            % lineY = [zeros(1,size(X,2));Y];
            
            count.X = X*app.exposureTime.Value;
            count.Y = Y;
            count.Z = Z;
            % count.lineX = lineX;
            % count.lineY = lineY;
        end


        function H2B_HMRF_segmentation(app)
            %% Modified HMRFsegmentation function to segment chromatin regions
            % Originally from WangBo

            dxy = app.render_pixelSize.Value; % rendered pixel size
            sigma_render = app.render_sigma.Value;
            Xmin = min(app.Ch1_loc_table.Xpos);Xmax=max(app.Ch1_loc_table.Xpos);Ymin = min(app.Ch1_loc_table.Ypos);Ymax=max(app.Ch1_loc_table.Ypos);
            Edges{1}=Xmin:dxy:Xmax+dxy; % make edge cover all data points
            Edges{2}=Ymin:dxy:Ymax+dxy;

            % HMRF segmentation (currently for H2B only) using immobile molecule
            immobile_table = app.Ch1_loc_table(app.Ch1_loc_table.Area <= app.minMobileSRarea,:);
            % bin every localizations into grid, similar to Im = hist3([Xpos',Ypos'],'Edges',Edges);
            [immobileIm,~,~,~,~] = histcounts2(immobile_table.Xpos,immobile_table.Ypos,Edges{1},Edges{2}); 
            
            % Im = hist3([Xpos',Ypos'],'Edges',Edges);
            
            TempX=-round(3*sigma_render/dxy)*dxy:dxy:round(3*sigma_render/dxy)*dxy;
            TempY=-round(3*sigma_render/dxy)*dxy:dxy:round(3*sigma_render/dxy)*dxy;
            
            ConVecX = exp(-0.5*(TempX/sigma_render).^2);
            ConVecX=ConVecX/sum(ConVecX);
            ConVecY = exp(-0.5*(TempY/sigma_render).^2);
            ConVecY=ConVecY/sum(ConVecY);
            
            immobileIm2 = conv2(ConVecX,ConVecY,immobileIm,'same');
            
            immobileImPALM = transpose(immobileIm2/dxy/dxy*10^6);
            
            nclust = app.TotalSegmentClassNumber.Value; % number of total chromatin segmentation, default 7.
            xylim = [Xmin, Xmax, Ymin, Ymax];
            
            [HMRFseg, setting] = HMRFsegmentation(immobile_table,xylim,[],nclust,dxy,sigma_render,0.1,immobileImPALM);
            app.HMRFseg = HMRFseg;


        end
        
        function ImOut_density = density_PALM(app,LocTable,LocTable_Xpos,LocTable_Ypos,loc_state,loc_XYLim)
            dxy = app.render_pixelSize.Value; 
            sigma_render = app.render_sigma.Value;
            Xmin = loc_XYLim(1);Xmax=loc_XYLim(2);Ymin = loc_XYLim(3);Ymax=loc_XYLim(4);
            Edges{1}=Xmin:dxy:Xmax+dxy; % make edge cover all data points
            Edges{2}=Ymin:dxy:Ymax+dxy;
            %% generate density based PALM image
            switch loc_state
                case 'all'
                    Xpos = LocTable_Xpos;
                    Ypos = LocTable_Ypos;
                case 'immobile'
                    Xpos = LocTable_Xpos(LocTable.Area <= app.minMobileSRarea);
                    Ypos = LocTable_Ypos(LocTable.Area <= app.minMobileSRarea);
            end
                        
            Edges{1}=Xmin:dxy:Xmax+dxy; % make edge cover all data points
            Edges{2}=Ymin:dxy:Ymax+dxy;
                        
            [Im,Xedges,Yedges,binX,binY] = histcounts2(Xpos,Ypos,Edges{1},Edges{2}); % bin every localizations into grid, similar to Im = hist3([Xpos',Ypos'],'Edges',Edges);
   
            TempX=-round(3*sigma_render/dxy)*dxy:dxy:round(3*sigma_render/dxy)*dxy;
            TempY=-round(3*sigma_render/dxy)*dxy:dxy:round(3*sigma_render/dxy)*dxy;
            
            ConVecX = exp(-0.5*(TempX/sigma_render).^2);
            ConVecX=ConVecX/sum(ConVecX);
            ConVecY = exp(-0.5*(TempY/sigma_render).^2);
            ConVecY=ConVecY/sum(ConVecY);
            % Cisse's verions of PALM rendering
%             Im2 = conv2(ConVecX,ConVecY,Im);
%             Im2=Im2/dxy/dxy*10^6; % convert unit of localizations in each bin from nm^-2 to um^-2
%             extra_pixels=(size(Im2)-size(Im))/2; % remove extra padding pixels
%             Im2=Im2((extra_pixels(1)+1):(end-extra_pixels(1)),(extra_pixels(2)+1):(end-extra_pixels(2)));
%             Im2=Im2(:,end:-1:1)';
%             if flipped
%                 ImOut_density = flip(Im2, 1);
%             else
%                 ImOut_density = Im2; 
%             end
            
            % ZW's updated shorten version, which gives same results
            Im2 = conv2(ConVecX,ConVecY,Im,'same');
            Im2=Im2/dxy/dxy*10^6; % convert unit of localizations in each bin from nm^-2 to um^-2
            ImOut_density = transpose(Im2); % x-y direction of histcounts2 are transposed with original image

        end
        
        function DrawROI(app)
            % hroi = drawrectangle(app.fig2.Children,'Color','r');
            hroi = drawrectangle(app.densityPALMfig.Children(2));app.roi_position = hroi.Position;

%             app.raw_table.Xpos_rescale = (app.raw_table.Xpos-min(app.raw_table.Xpos))./app.render_pixelSize.Value+0.5;
%             app.raw_table.Ypos_rescale = (app.raw_table.Ypos-min(app.raw_table.Ypos))./app.render_pixelSize.Value+0.5;
% 
%             app.in_ROI = inROI(hroi,app.raw_table.Xpos_rescale,app.raw_table.Ypos_rescale);

            app.Ch1_loc_table.Xpos_rescale = (app.Ch1_loc_table.Xpos-app.Ch1_loc_XYLim(1))./app.render_pixelSize.Value+0.5;
            app.Ch1_loc_table.Ypos_rescale = (app.Ch1_loc_table.Ypos-app.Ch1_loc_XYLim(3))./app.render_pixelSize.Value+0.5;

            app.in_ROI = inROI(hroi,app.Ch1_loc_table.Xpos_rescale,app.Ch1_loc_table.Ypos_rescale);
            
            app.roi_raw_table = app.Ch1_loc_table(app.in_ROI,:);
            
            % plot mobility over detection sequence
            % plot(app.ax_mobilityTime,app.roi_raw_table.Area)
            % A = ((app.roi_raw_table.Area*app.camPixelSize_Ch1.Value^2*1e-6)-0.4865)/0.09732;
            % plot(app.ax_mobilityTime,A);
            plot(app.ax_mobilityTime,app.roi_raw_table.D);
            
            % plot tcPALM of selected ROI
            count = DetectionCount(app);
            plot(app.ax_tcPALM,count.X,count.Y)

            % plot photon histogram
            histogram(app.ax_Ch1_photon_histogram,app.roi_raw_table.TotalPhoton,'Normalization','probability')            
        end
        
        function [K, L] = RipleyK(app,pos,mask,roi,r)
            %% RipleyK
            % pos: [x, y]
            % mask: mask region used to calculate average particle density
            % roi: roi object that generate the mask
            % r: step to measure the RipleyK, e.g.: 0:10:500 
 
            max_r = max(r); % unit rendered pixel
            area = sum(mask,'all');

            % Calculate number of bins in x and y directions
            [num_bins_y,num_bins_x] = size(app.Ch1Img_densityPALM);

            % Initialize K and L arrays
            K = zeros(size(r(1:end-1)));            
        
            grid_indices_x = discretize(pos(:,1),1:num_bins_x+1);
            grid_indices_y = discretize(pos(:,2),1:num_bins_y+1);
        
            % Loop through each point to compute K and L
            inroi_pos = inROI(roi,pos(:,1),pos(:,2));
            inroi_idx = find(inroi_pos);
            tot_inroi = length(inroi_idx);
            fprintf("Calculating Ripley's H function for the selected ROI ...")
            progressbarText(0);
            for it = 1:tot_inroi
                inroi_it = inroi_idx(it);
                % Find neighboring points within the same or adjacent grid cells
                x_idx = grid_indices_x(inroi_it);
                y_idx = grid_indices_y(inroi_it);
        
                % Define search range based on current point's grid indices
                x_range = max(1, x_idx - max_r):min(num_bins_x, x_idx + max_r);
                y_range = max(1, y_idx - max_r):min(num_bins_y, y_idx + max_r);
        
                % Get indices of points within the same or adjacent grid cells
                neighbor_indices = find(ismember(grid_indices_x, x_range) & ismember(grid_indices_y, y_range));
        
                if ~isempty(neighbor_indices)
                    % Compute distances within the defined radius
                    distances = sqrt((pos(neighbor_indices, 1) - pos(inroi_it, 1)).^2 + (pos(neighbor_indices, 2) - pos(inroi_it, 2)).^2);
        
                    % Compute distances in each bin
                    [h, ~] = histcounts(distances, r);
        
                    % Update K using cumulative sum
                    K = K + cumsum(h)*area/(tot_inroi*(tot_inroi-1)); %Ripley2s K
                end
                progressbarText(it/tot_inroi);
            end
        
            % Calculate L
            L = sqrt(K/pi); %Ripley’s 
        end


        function MOVINGREG = registerImages(app,MOVING,FIXED)
            % Default spatial referencing objects
            fixedRefObj = imref2d(size(FIXED));
            movingRefObj = imref2d(size(MOVING));
            
            % Phase correlation
            tform = imregcorr(MOVING,movingRefObj,FIXED,fixedRefObj,'transformtype','similarity','Window',true);
            MOVINGREG.Transformation = tform;
            MOVINGREG.RegisteredImage = imwarp(MOVING, movingRefObj, tform, 'OutputView', fixedRefObj, 'SmoothEdges', true);
            
            % Store spatial referencing object
            MOVINGREG.SpatialRefObj = fixedRefObj;
        end
        
        function mask_simple_partition = PALMBinaryFcn(app,LocTable,LocTable_Xpos,LocTable_Ypos,loc_state,loc_XYLim)
            %% Binary mask from immobile molecules based PALM image 
            ImOut_immobile_density = density_PALM(app,LocTable,LocTable_Xpos,LocTable_Ypos,loc_state,loc_XYLim);

            % scale ImOut_density linearly into [0, 1] using imcontrast is more easier
            app.densityPALMfig = figure('Visible','on','Units','inches',...
                'Position',[2.1 1.2 10 7],'Name','Immobile molecules generated PALM','Color','k');
            ax1 = axes(...
                'Units','normalized',...
                'Position', [0 0 1 1],... % stretch to fill axes 
                'XTickLabel','',...
                'YTickLabel','');
            imshow(ImOut_immobile_density,'InitialMagnification',1000,'colormap',hot,'Parent',ax1);
            h=colorbar;
            h.Position = [0.9,0.04,0.02,0.9];
            h.Units = 'normalized';
            xlabel(h,'Localizations/\mum^2','FontSize',14)
            h.Label.Position =[2.384126935875613,748.3768617952024,0];
            ax1.CLim(2) = max(ImOut_immobile_density,[],'all');
            h1 = imcontrast(ax1);
            uiwait(h1)                     
                        
            MaxVal = max(ImOut_immobile_density,[],'all');
            MinVal = min(ImOut_immobile_density,[],'all');
            Scale_Range = (ax1.CLim-MinVal)./(MaxVal-MinVal);
            PALM_CLim = ax1.CLim; 
            img_densityPALM_adjust = imadjust(rescale(ImOut_immobile_density),Scale_Range); % scale the image into selected data range
            
            % Create binary mask of motion blur, maxentropie only takes uint8 image
            I = im2uint8(img_densityPALM_adjust);
            T=adaptthresh(I,app.BinarySensitivity_Ch2.Value);
            mask_simple_partition = imbinarize(I,T);
            % [~, mask_simple_partition]=maxentropie(im2uint8(img_densityPALM_adjust));
            % mask_simple_partition(mask_simple_partition == 255) = 1; % convert mask label to 1 instead of 255

            figure('Units','normalized','Position',[0.0969 0.2806 0.7260 0.4722],...
                'Name','Immobile PALM or Ch2 PALM generated mask');
            tiledlayout(1,3,'TileSpacing','none','Padding','tight','Position',[0 0 1 1]);
            
            ax1 = nexttile;
            imshow(ImOut_immobile_density,'colormap',gray,'Parent',ax1);
            title(ax1,'Rendered PALM')
            h = imcontrast;uiwait(h);
            MaxVal = max(ImOut_immobile_density,[],'all');
            MinVal = min(ImOut_immobile_density,[],'all');
            Scale_Range = (ax1.CLim-MinVal)./(MaxVal-MinVal);
            

            ax2 = nexttile;
            imshow(mask_simple_partition,'Parent',ax2)
            title(ax2,'PALM binary segmentation')

            ax3 = nexttile;
            img_densityPALM_adjust = imadjust(rescale(ImOut_immobile_density),Scale_Range);
            I3 = labeloverlay(img_densityPALM_adjust,mask_simple_partition,'Transparency',0.7,'Colormap','autumn');
            imshow(I3,'Parent',ax3)
            title(ax3,'PALM binary segmentation overlay')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            app.minMobileSRarea = app.D_SRArea_const.Value*sqrt(app.minMobileD.Value);

            % ==============  set colormap for mPALM render ============== %
            % app.cmap_mpalm = customcolormap([0 0.5 1], [0 1 0; 1 0 0; 0 0 1]); % colormap for MPALM rendering
            rgbColors = flipud(hsv(256));
            rgbColors = rgbColors(39:end,:);
            app.cmap_mpalm = interp1(linspace(0, 1, size(rgbColors, 1)), rgbColors, linspace(0, 1, 256));  % keep one bin index for NaN value

            % log scale D colormap
            MinLogD = app.render_minlogD.Value; % Min of log10(D)
            MaxLogD = app.render_maxlogD.Value; % Max of log10(D)
            
            % Edge will return 255 color index, save 1 for NaN value
            app.logD_edge = linspace(MinLogD, MaxLogD, 256); 
            app.D_edge = linspace(10^MinLogD, 10^MaxLogD, 256);             

            app.SeqDriftState = false;


            % DValues = 10.^logDValues;
            % app.areaEdge = app.D_SRArea_const.Value*sqrt(DValues);

            % ==============  set colormap for mPALM render ============== %
        end

        % Button pushed function: loadDataset_Ch1
        function load_Ch1_Callback(app, event)
            % reset
            all_fig = allchild(0);
            for i = 1:length(all_fig)
                if ~isequal(all_fig(i).Name,"mainApp")
                    delete(all_fig(i));
                end
            end
            close all; clc;
            app.HMRFsegmentStatus.Color = [1 0 0];
            app.loadDatasetStatus_Ch1.Color = [1 0 0];
            app.PALMBinaryStatus_Ch1.Color = [1 0 0];            
            app.loadDatasetStatus_Ch2.Color = [1 0 0];
            app.driftCorrectionStatus_Ch1.Color = [1 0 0];
            cla(app.ax_mobilityTime)
            cla(app.ax_tcPALM)
            cla(app.ax_logD_Histogram)
            cla(app.ax_locPerFrame)
            cla(app.ax_Ch1_photon_histogram)
            cla(app.ax_Ch1_SNR)
            app.MobileAnglePALMButton.Enable = false;
            app.drawROIButton.Enable = false;
            % app.MobilityPALMButton.Enable = false;
            app.showLocTable.Enable = false;            
            app.saveCorrectedTable_Ch1.Enable = false;
            app.SeqDriftState = false;
            app.saveCorrectedTableCh1and2.Enable = "off";
                                    
            [app.Ch1_info_name,app.Ch1_info_path] = uigetfile({'*.mat'},...
                          'Load info mat');
            if app.Ch1_info_name ~= 0
                cd(app.Ch1_info_path)                       
                [app.Ch1_loc_name,app.Ch1_loc_path] = uigetfile({'*.csv'},...
                              'Load SR area csv');                      
                load(fullfile(app.Ch1_info_path,app.Ch1_info_name),'impars');
                tab_full_Ch1_SRarea = readtable(fullfile(app.Ch1_loc_path,app.Ch1_loc_name));
                switch app.UseWhichAreaDropDown.Value
                    case 'drift corrected'
                        % make sure no conflict before table join
                        tab_full_Ch1_SRarea.D = (tab_full_Ch1_SRarea.SRArea./app.D_SRArea_const.Value).^2;
                        app.Ch1_loc_table = tab_full_Ch1_SRarea;
                    case 'raw'
                        % convert python index into matlab index
                        tab_full_Ch1_SRarea.Frame = tab_full_Ch1_SRarea.Frame +1;
                        tab_full_Ch1_SRarea.PSF_idx = tab_full_Ch1_SRarea.PSF_idx +1;
                        
                        % X-Y localization from Matlab elliptical gaussian fitting,
                        % coordinate are aligned with matlab imshow
                        tab_full_Ch1_SRarea.Xpos = tab_full_Ch1_SRarea.Xpos * app.camPixelSize_Ch1.Value;
                        tab_full_Ch1_SRarea.Ypos = tab_full_Ch1_SRarea.Ypos * app.camPixelSize_Ch1.Value;
                        tab_full_Ch1_SRarea.Area = tab_full_Ch1_SRarea.SRArea;                                                                     
                        tab_full_Ch1_SRarea.D = (tab_full_Ch1_SRarea.SRArea./app.D_SRArea_const.Value).^2;
                        app.Ch1_loc_table = tab_full_Ch1_SRarea;
                end
                
                % app.Ch1_loc_table = CombineTable(app,tab_psf_fitresult,tab_full_Ch1_SRarea);

                if isfield(impars, 'ImWidth') && isfield(impars, 'ImHeight')
                    app.ImWidth.Value = impars.ImWidth;
                    app.ImHeight.Value = impars.ImHeight;
                end

                if app.DrawROICheckBox.Value == true
                    fig = figure('Position',[471 104 822 738]);
                    scatter(app.Ch1_loc_table.Xpos, app.Ch1_loc_table.Ypos,2,'filled','MarkerFaceColor',[0 0 0],'MarkerEdgeColor','none','MarkerFaceAlpha',0.3);
                    xlabel('X Position');
                    ylabel('Y Position');
                    title('Select ROI');
                    axis image ij

                    % Draw a polygon ROI
                    hROI = drawpolygon('LineWidth', 2, 'Color', 'r');

                    % Get the X and Y coordinates of the vertices of the polygon
                    roiVertices = hROI.Position;

                    % Use inpolygon to find points within the ROI
                    [in, on] = inpolygon(app.Ch1_loc_table.Xpos, app.Ch1_loc_table.Ypos, roiVertices(:,1), roiVertices(:,2));

                    % Filter the table to include only rows within the ROI
                    app.Ch1_loc_table = app.Ch1_loc_table(in | on, :);                         

                    % save(fullfile(SRFile.folder,sprintf('roi_metadata.mat')),'hROI');
                    close(fig)        
                end
                
                if app.loadPartialData1.Value == 1
                    app.Ch1_loc_table = app.Ch1_loc_table(app.Ch1_loc_table.Frame >= app.Startframe.Value & app.Ch1_loc_table.Frame <= app.Stopframe.Value,:);                
                end             
                
                if app.loadPartialData2.Value == 1
                    app.Ch1_loc_table = app.Ch1_loc_table(1:app.LocalizationNumCutOff.Value,:);
                end
                                
                app.Ch1_loc_table = app.Ch1_loc_table(app.Ch1_loc_table.TotalPhoton <= app.maxPhoton_Ch1.Value & app.Ch1_loc_table.TotalPhoton >= app.minPhoton_Ch1.Value,:); % filter out abnormal molecules
                histogram(app.ax_Ch1_photon_histogram, app.Ch1_loc_table.TotalPhoton,'Normalization','probability');
                histogram(app.ax_Ch1_SNR,app.Ch1_loc_table.SNR,'Normalization','probability');
                % app.raw_table_XYLim = [min(app.raw_table.Xpos),max(app.raw_table.Xpos),min(app.raw_table.Ypos),max(app.raw_table.Ypos)];
                % app.Ch1_loc_XYLim = [app.camPixelSize_Ch1.Value,app.camPixelSize_Ch1.Value*app.ImWidth.Value,app.camPixelSize_Ch1.Value,app.camPixelSize_Ch1.Value*app.ImHeight.Value];
                app.Ch1_loc_XYLim = [0,app.camPixelSize_Ch1.Value*app.ImWidth.Value,0,app.camPixelSize_Ch1.Value*app.ImHeight.Value];

                app.loadDatasetStatus_Ch1.Color = [0 1 0];
                app.totalLocNumber.Enable = true;
                app.totalLocNumber.Value = height(app.Ch1_loc_table);

                app.meanLocPerFrame.Enable = true;
                G = findgroups(app.Ch1_loc_table.Frame);
                locPerFrame = splitapply(@numel,app.Ch1_loc_table.Frame,G);
                app.meanLocPerFrame.Value = mean(locPerFrame);
                plot(app.ax_locPerFrame,locPerFrame);

                % histogram(app.ax_logD_Histogram,app.Ch1_loc_table.Area,'BinEdges',0:200:10000,'Normalization','probability');
                % histogram(app.ax_logD_Histogram,log10(app.Ch1_loc_table.D),'BinEdges',-3:0.1:2,'Normalization','probability');
                merge_D = app.Ch1_loc_table.D; merge_D = merge_D(merge_D>0); % avoid bug
                [f, xi] = ksdensity(log10(merge_D));
                % Plot the kernel smoothed probability
                plot(app.ax_logD_Histogram, xi,f,'b','LineWidth',1); % app.ax_logD_Histogram.XLim = [-3 2]; % [-3.5 1];
                app.ax_logD_Histogram.XLim = [app.MinLogD_plot.Value app.MaxLogD_plot.Value]; 

                % app.immobileAreaPercent.Value = sum(app.Ch1_loc_table.Area <= app.minMobileSRarea)/height(app.Ch1_loc_table);                
                                
                app.HMRFsegmentStatus.Color = [1 0 0];
                app.PALMBinaryStatus_Ch1.Color = [1 0 0];
                app.showLocTable.Enable = true;

                
            else % click cancel
                app.loadDatasetStatus_Ch1.Color = [1 0 0];
                app.showLocTable.Enable = false;
            end

            % function T_combined = CombineTable(app,tab_psf_fitresult,tab_full_Ch1_SRarea)
            %     % Assign which area to use
            %     switch app.UseWhichAreaDropDown.Value
            %         case 'drift corrected'
            %             % make sure no conflict before table join
            %             T_combined = tab_full_Ch1_SRarea;
            %         case 'raw'
            %             % convert python index into matlab index
            %             tab_full_Ch1_SRarea.Frame = tab_full_Ch1_SRarea.Frame +1;
            %             tab_full_Ch1_SRarea.PSF_idx = tab_full_Ch1_SRarea.PSF_idx +1;
            %             T_combined = join(tab_full_Ch1_SRarea,tab_psf_fitresult,'Keys',{'Frame','PSF_idx'});
            %             % X-Y localization from Matlab elliptical gaussian fitting,
            %             % coordinate are aligned with matlab imshow
            %             T_combined.Xpos = T_combined.Xpos * app.camPixelSize_Ch1.Value;
            %             T_combined.Ypos = T_combined.Ypos * app.camPixelSize_Ch1.Value;
            %             T_combined.Area = T_combined.SRArea;
            %     end
            % 
            % end
            
        end

        % Button pushed function: MobilityPALMButton
        function MPALM_RENDER(app, event)
            %% ================== 2023/09/21 mobility-PALM rendering without cluster analysis ===================== %%
            %% MPALM render considering distance and local density followed by cross-one non-NaN convolution
            dxy = app.render_pixelSize.Value; % rendered pixel size, unit NM
            sigma_render = app.render_sigma.Value;
            % Xmin = min(app.raw_table.Xpos);Xmax=max(app.raw_table.Xpos);Ymin = min(app.raw_table.Ypos);Ymax=max(app.raw_table.Ypos);
            Xmin = app.Ch1_loc_XYLim(1);Xmax=app.Ch1_loc_XYLim(2);Ymin = app.Ch1_loc_XYLim(3);Ymax=app.Ch1_loc_XYLim(4);
            Edges{1}=Xmin:dxy:Xmax+dxy; % make edge cover all data points
            Edges{2}=Ymin:dxy:Ymax+dxy;
                         
            %% Step2: Generate mobility map with proper gaussian weighted average
            switch app.locsTypeSwitch.Value
                case 'all'
                    Xpos = app.Ch1_loc_table.Xpos;
                    Ypos = app.Ch1_loc_table.Ypos;
                    Area =  app.Ch1_loc_table.Area;
                    D = app.Ch1_loc_table.D;
                    app.filtD_Ch1_loc_table = app.Ch1_loc_table;

                    % Xpos_rescale = (app.roi_Ch1_loc_table.Xpos-Xmin)./dxy+0.5 ; % unit grid pixel
                    % Ypos_rescale = (app.roi_Ch1_loc_table.Ypos-Ymin)./dxy+0.5 ;
                case 'partial'
                    idx = app.Ch1_loc_table.D >= 10^app.mpalm_moleculeType_minLogD.Value & app.Ch1_loc_table.D <= 10^app.mpalm_moleculeType_maxLogD.Value;
                    Xpos = app.Ch1_loc_table.Xpos(idx);
                    Ypos = app.Ch1_loc_table.Ypos(idx);
                    Area =  app.Ch1_loc_table.Area(idx);
                    D = app.Ch1_loc_table.D(idx);
                    app.filtD_Ch1_loc_table = app.Ch1_loc_table(idx,:);

                % case 'mobile'
                %     Xpos = app.Ch1_loc_table.Xpos(app.Ch1_loc_table.Area >= app.minMobileSRarea);
                %     Ypos = app.Ch1_loc_table.Ypos(app.Ch1_loc_table.Area >= app.minMobileSRarea);
                %     Area =  app.Ch1_loc_table.Area(app.Ch1_loc_table.Area >= app.minMobileSRarea);
                % case 'immobile'
                %     Xpos = app.Ch1_loc_table.Xpos(app.Ch1_loc_table.Area < app.minMobileSRarea);
                %     Ypos = app.Ch1_loc_table.Ypos(app.Ch1_loc_table.Area < app.minMobileSRarea);
                %     Area =  app.Ch1_loc_table.Area(app.Ch1_loc_table.Area < app.minMobileSRarea);

            end
                    
            [Im,Xedges,Yedges,binX,binY] = histcounts2(Xpos,Ypos,Edges{1},Edges{2}); % bin every localizations into grid, similar to Im = hist3([Xpos',Ypos'],'Edges',Edges);
            
            % remove points not fit the grid
            Xpos = Xpos(~binX==0);
            Ypos = Ypos(~binY==0);
            D = D(~binX==0);
            [Im,Xedges,Yedges,binX,binY] = histcounts2(Xpos,Ypos,Edges{1},Edges{2});
            
            % Calculate the average Cdata for each bin index
            matrix = accumarray([binX(:), binY(:)], D(:), size(Im), @mean);
           
            % Im = hist3([Xpos',Ypos'],'Edges',Edges);

            TempX=-round(3*sigma_render/dxy)*dxy:dxy:round(3*sigma_render/dxy)*dxy;
            TempY=-round(3*sigma_render/dxy)*dxy:dxy:round(3*sigma_render/dxy)*dxy;
            
            ConVecX = exp(-0.5*(TempX/sigma_render).^2);
            ConVecX=ConVecX/sum(ConVecX);
            ConVecY = exp(-0.5*(TempY/sigma_render).^2);
            ConVecY=ConVecY/sum(ConVecY);
            
            Im_density = conv2(ConVecX,ConVecY,Im,'same');

            Im2=Im_density/dxy/dxy*10^6; % convert unit of localizations in each bin from nm^-2 to um^-2
            app.Ch1Img_filt_densityPALM = transpose(Im2); % x-y direction of histcounts2 are transposed with original image
            
            % Create a logical mask indicating the zero elements
            zeroMask = (matrix == 0);
            
            % Compute the weighted average of the Gaussian distribution for zero elements
            if any(zeroMask(:))
            
                % Get the size of the matrix
                [M, N] = size(matrix);
                
                % Initialize the filtered matrix
                filteredMatrix = matrix;
                
                % Loop over each element in the matrix
                for i = 1:M
                    for j = 1:N
                        if zeroMask(i, j) % if this pixel is zero
                            % Initialize total weight and weighted sum
                            totalWeight = 0;
                            weightedSum = 0;
                            
                            % Loop over neighbors (a 3x3 pixel square center by the current zero-value pixel when sigma_render equals dxy)
                            for dx = -round(1*sigma_render/dxy):round(1*sigma_render/dxy)  %-round(2*sigma_render/dxy):round(2*sigma_render/dxy)
                                for dy = -round(1*sigma_render/dxy):round(1*sigma_render/dxy) %-round(2*sigma_render/dxy):round(2*sigma_render/dxy)
                                    % Check if neighbor is within the matrix bounds
                                    if i+dx >= 1 && i+dx <= M && j+dy >= 1 && j+dy <= N
                                        % Check if neighbor is non-zero
                                        if ~zeroMask(i+dx, j+dy)
                                            % Compute weights based on Gaussian distribution
                                            weightX = exp(-0.5*(dx*dxy/sigma_render).^2); %exp(-0.5*(dx)^2);
                                            weightY = exp(-0.5*(dy*dxy/sigma_render).^2); %exp(-0.5*(dy)^2);
                                            
                                            % Accumulate weighted sum and total weight
                                            weightedSum = weightedSum + matrix(i+dx, j+dy) * weightX * weightY * Im_density(i+dx, j+dy);
                                            totalWeight = totalWeight + weightX * weightY * Im_density(i+dx, j+dy);
                                        end
                                    end
                                end
                            end
                            
                            % Compute the weighted average for the zero element
                            filteredMatrix(i, j) = weightedSum / totalWeight;
                        end
                    end
                end
            else
                % If there are no zero elements, the filtered matrix is the same as the original matrix
                filteredMatrix = matrix;
            end
            
            % Create a binary mask to identify the non-zero values in A
            mask = filteredMatrix ~= 0;            
            
            % % Define the kernel mask for smoothing, not as good as
            % cross-on kernel
            % kernel = [1, 1, 1;
            %           1, 1, 1;
            %           1, 1, 1];
            
            % cross-one kernel
            kernel = [0, 1, 0;
                      1, 1, 1;
                      0, 1, 0];
            
            % Normalize the kernel so that its sum is equal to 1
            kernel = kernel / sum(kernel(:));
            
            % Create a matrix with NaN values at zero positions and non-zero values at non-zero positions in A
            maskedA = filteredMatrix;
            maskedA(mask == 0) = NaN;
            
            % Perform kernel smoothing on the non-zero elements in A using nanconv
            % ImOut_mobility = nanconv(filteredMatrix, kernel,"nanout"); % this get sharper results
            ImOut_mobility = nanconv(maskedA, kernel,"nanout"); % this get sharper results            
            % smoothedA = nanconv(maskedA, kernel);
            
            % app.img_mobility = transpose(ImOut_mobility);
            app.img_mobility = transpose(ImOut_mobility);
            fprintf('mPALM render done \n')
            
            %% Step3: combine PALM and mobility map
 
            % log scale D colormap
            MinLogD = app.render_minlogD.Value; % Min of log10(D)
            MaxLogD = app.render_maxlogD.Value; % Max of log10(D)

            rescale_ImOut_mobility = app.img_mobility;

            % areaEdge = app.areaEdge;
            rescale_ImOut_mobility(rescale_ImOut_mobility < 10^MinLogD & rescale_ImOut_mobility > 0 ) = 10^MinLogD;
            rescale_ImOut_mobility(rescale_ImOut_mobility >  10^MaxLogD) = 10^MaxLogD;

            % Create custom colormap, first black reserved for NaN
            J = app.cmap_mpalm;
            J(1,:) = [0 0 0];
            app.local_cmap_mpalm = J;
 
            CData_rescale_ImOut_mobility = discretize(rescale_ImOut_mobility,app.D_edge);             
            CData_rescale_ImOut_mobility = CData_rescale_ImOut_mobility +1;
            CData_rescale_ImOut_mobility(isnan(CData_rescale_ImOut_mobility)) = 1;
%             hI.CData = CData_rescale_ImOut_mobility;
%             hI.CDataMapping = 'direct';                                   
%             ax = gca; ax.XTickLabel = '';ax.YTickLabel = '';
%             ax.Units = 'normalized';ax.Position = [0 0 1 1];
            
            %---------- PLOT: PALM density integrated MPALM using HSV color scheme ------%
            app.rgbImOut_mobility = ind2rgb(CData_rescale_ImOut_mobility,J);
            app.density_mPALM_imadjustApp = coarse_rendering(app);

            % rgbColors = flipud(hsv(256));
            % rgbColors = rgbColors(40:end,:);
            % rgbColors = interp1(linspace(0, 1, size(rgbColors, 1)), rgbColors, linspace(0, 1, 256));
            % 
            % % Reshape the matrix to an image-like format with dimensions (height, width, 3)
            % [height, ~] = size(rgbColors); % Retrieve the height of the matrix
            % imageData = reshape(rgbColors, [height, 1, 3]);
            [Height, ~] = size(app.cmap_mpalm); % Retrieve the height of the matrix
            imageData = reshape(app.cmap_mpalm, [Height, 1, 3]);
            
            imageData = imageData .* ones(Height, 20, 3);
            
            % Display the image
            figure('Color', 'black');
            imshow(imageData);
            switch app.ColorscaleSwitch.Value
                case "Log"
                    % log scale D colormap
                    MinLogD = app.render_minlogD.Value; % Min of log10(D)
                    MaxLogD = app.render_maxlogD.Value; % Max of log10(D)                       
                    % DValues = 10.^linspace(MinLogD, MaxLogD, 10);
                    logDValues = linspace(MinLogD, MaxLogD, 10);

                    % Calculate the index for the provided area
                    index = round((logDValues - min(logDValues)) ./ (max(logDValues) - min(logDValues)) * 255) + 1;

                    % index = round((DValues - min(DValues)) ./ (max(DValues) - min(DValues)) * 255) + 1;            
                    text(24, -20, 'D', 'Color', 'White','FontSize',10);
                    for i = 1:10
                        % text(24, index(i), sprintf("%.2f",DValues(i)), 'Color', 'White','FontSize',10);
                        text(24, index(i), sprintf("%.2f",10^logDValues(i)), 'Color', 'White','FontSize',10);
                    end
                    
                    text(60, -20, 'logD (um^2/s)', 'Color', 'White','FontSize',10);
                    for i = 1:10
                        text(60, index(i), sprintf("%.2f",logDValues(i)), 'Color', 'White','FontSize',10);
                    end

                case "Linear"
                    % Linear scale D colormap
                    MinLogD = app.render_minlogD.Value; % Min of log10(D)
                    MaxLogD = app.render_maxlogD.Value; % Max of log10(D)         
                    MinD = 10^MinLogD;
                    MaxD = 10^MaxLogD;
                    DValues = linspace(MinD, MaxD, 10);

                    % Calculate the index for the provided area
                    index = round((DValues - min(DValues)) ./ (max(DValues) - min(DValues)) * 255) + 1;            
                    text(24, -20, 'D (um^2/s)', 'Color', 'White','FontSize',10);
                    for i = 1:10
                        text(24, index(i), sprintf("%.3f",DValues(i)), 'Color', 'White','FontSize',10);
                    end

            end
            
            
            % MinD = 10^MinLogD;
            % MaxD = 10^MaxLogD;
            % DValues = linspace(MinD, MaxD, 10);
            
            % % Calculate the index for the provided area
            % index = round((DValues - min(DValues)) ./ (max(DValues) - min(DValues)) * 255) + 1;            
            % text(24, -20, 'D (um^2/s)', 'Color', 'White','FontSize',10);
            % for i = 1:10
            %     text(24, index(i), sprintf("%.2f",DValues(i)), 'Color', 'White','FontSize',10);
            % end
            % 
            % 
            % 
            % for i = 1:numAreas
            %     text(24, index(i), sprintf("%.2f",logDValues(i)), 'Color', 'White','FontSize',10);
            % end
                       
%             if isequal(app.PALMBinaryStatus_Ch1.Color,[0 1 0]) || isequal(app.HMRFsegmentStatus.Color, [0 1 0])               
%                   
%                 % ---------- PLOT: Segmentation overlaid MPALM------%
%                 segfusedRgbImOut_mobility = imfuse(app.simple_partition_Ch1,app.rgbImOut_mobility,'blend','Scaling','joint');
%                 figure;imshow(segfusedRgbImOut_mobility);title('Segmentation overlaid MPALM');
%                 
%                 % -------PLOT: Segmentation overlaid mobile locs ---%
%                 figure;imshow(app.simple_partition_Ch1);hold on;
%                 mobile_raw_table = app.Ch1_loc_table(app.Ch1_loc_table.Area > app.minMobileSRarea,:);
%                 Xpos_rescale = (mobile_raw_table.Xpos-Xmin)./dxy+0.5 ;
%                 Ypos_rescale = (mobile_raw_table.Ypos-Ymin)./dxy+0.5 ;
%                 
%                 % Find the colormap mapping index of each range of logD. and
%                 % map the first black color to all NaN data later
%                 cidx = discretize(mobile_raw_table.Area,areaEdge); 
%                 cidx = cidx +1;
%                 cidx(isnan(cidx)) = 256;
% 
%                 % overlay detection localization
%                 scatter(Xpos_rescale,Ypos_rescale,15,J(cidx,:),'filled'); hold off;
%                 title('Segmentation overlaid mobile localizations');
%                 
%                 
%                 % ------- PLOT: All localizations overlaid
%                 % segmentation fused MPALM ---- %
%                 figure;imshow(segfusedRgbImOut_mobility);%freezeColors;
%                 
%                 Xpos_rescale = (app.Ch1_loc_table.Xpos-Xmin)./dxy+0.5 ;
%                 Ypos_rescale = (app.Ch1_loc_table.Ypos-Ymin)./dxy+0.5 ;
% 
%                 cidx = discretize(app.Ch1_loc_table.Area,areaEdge); 
%                 cidx = cidx +1;
%                 cidx(app.Ch1_loc_table.Area < min(areaEdge) & app.Ch1_loc_table.Area > 0) = 2;
%                 cidx(app.Ch1_loc_table.Area > max(areaEdge)) = 256;
%                 
%                 % overlay detection localization
%                 hold on;
%                 scatter(Xpos_rescale,Ypos_rescale,15,J(cidx,:),'filled');                
%                 hold off;
%                 title('All localizations overlaid segmentation fused MPALM')
                
%                     % ------ PLOT: All localizations overlaid chromatin
%                     % segmentation fused MPALM ---- %
%                     figure;
%                     montage({app.simple_chromatin_partition,rgbImOut_mobility})
%                     title('CD segmentation and MPALM ')
%             end
        
                
        end

        % Button pushed function: drawROIButton
        function ROIScatterOverlayCallback(app, event)
            % Get the ROI info and update the roi_raw_table
            DrawROI(app)
            areaEdge = app.areaEdge;
            %% Overlay localizations over PALM
            switch app.locsTypeSwitch.Value
                case 'all'
                    filter_roi_raw_table = app.roi_raw_table;
                case 'mobile'
                    filter_roi_raw_table = app.roi_raw_table(app.roi_raw_table.Area > app.minMobileSRarea,:);
            end
            
            % create a ROI window with slider that can visualize the
            % appearance order of localizations in ROI
            app.zoomScatterfig = uifigure();
            g = uigridlayout(app.zoomScatterfig,[2,2]);
            g.RowHeight = {'1x','fit'};
            g.ColumnWidth = {50,'1x'};
            
            ax = uiaxes(g);ax.Layout.Column = [1 2];
            
            cidx = discretize(filter_roi_raw_table.D,app.D_edge); 
            cidx = cidx +1; 
            cidx(filter_roi_raw_table.D < 10^app.render_minlogD.Value & filter_roi_raw_table.D > 0) = 2;
            cidx(filter_roi_raw_table.D > 10^app.render_maxlogD.Value) = 256;
            
            % overlay detection localization  
            J = app.cmap_mpalm;
            J(1,:) = [0 0 0];
            imshow(app.Ch1Img_densityPALM,'colormap',gray,'Parent',ax); 
            ax.CLim = [app.render_minDensity_Ch1.Value app.render_maxDensity_Ch1.Value];hold(ax,'on');
            scatter(ax,filter_roi_raw_table.Xpos_rescale,filter_roi_raw_table.Ypos_rescale,15,J(cidx,:),'filled');hold(ax,'off');

            % adjustArea = filter_roi_raw_table.Area;
            % adjustArea(filter_roi_raw_table.Area < min(areaEdge)) = min(areaEdge);
            % adjustArea(filter_roi_raw_table.Area > max(areaEdge)) = max(areaEdge);
            % 
            % J = app.cmap_mpalm;
            % cidx = discretize(adjustArea,areaEdge); 
            % cidx = cidx +1;            
            % imshow(app.Ch1Img_densityPALM,'colormap',gray,'Parent',ax);
            % 
            % ax.CLim = [app.render_minDensity_Ch1.Value app.render_maxDensity_Ch1.Value]; hold(ax,'on'); % render PALM 
            % 
            % scatter(ax,filter_roi_raw_table.Xpos_rescale,filter_roi_raw_table.Ypos_rescale,15,J(cidx,:),'filled'); hold off; % must use RGB color instead of freezeColormap

            % Determine the limits of the ROI
            x_min = min(app.roi_position(1));
            x_max = max(app.roi_position(1)+app.roi_position(3));
            y_min = min(app.roi_position(2));
            y_max = max(app.roi_position(2)+app.roi_position(4));
            
            % Calculate padding to add some margin around the ROI
            padding_x = 0.1 * (x_max - x_min);
            padding_y = 0.1 * (y_max - y_min);
            
            % Set the limits of the x and y axes to zoom in on the ROI
            xlim(ax,[x_min - padding_x, x_max + padding_x]);
            ylim(ax,[y_min - padding_y, y_max + padding_y]);

            % Create sub roi
            b1 = uibutton(g, ...
                "Text","ROI",...
                "ButtonPushedFcn", @(app,event) FindROILocsWhenPushed(app,ax,filter_roi_raw_table));

            % Create the slider
            sld = uislider(g, ...
                "Limits",[1 max(filter_roi_raw_table.Frame)], ...
                "Value",max(filter_roi_raw_table.Frame));
            
            sld.ValueChangingFcn = @(src,event) updatePlot(src,event,ax,filter_roi_raw_table);
            
            function updatePlot(src,event,ax,filter_roi_raw_table)
                val = event.Value;
                imshow(app.Ch1Img_densityPALM,'colormap',gray,'Parent',ax);
                ax.CLim = [app.render_minDensity_Ch1.Value app.render_maxDensity_Ch1.Value]; hold(ax,'on');% render PALM
                val_idx = filter_roi_raw_table.Frame <= val;
                scatter(ax,filter_roi_raw_table.Xpos_rescale(val_idx),filter_roi_raw_table.Ypos_rescale(val_idx),15,J(cidx(val_idx),:),'filled');
                xlim(ax,[x_min - padding_x, x_max + padding_x]);
                ylim(ax,[y_min - padding_y, y_max + padding_y]);                
                hold(ax,'off');
            end
            function FindROILocsWhenPushed(app,ax,mobile_raw_table)
                hroi = drawrectangle(ax);% app.roi_position = hroi.Position;
                in_ROI = inROI(hroi,mobile_raw_table.Xpos_rescale,mobile_raw_table.Ypos_rescale);           
                roi_table = mobile_raw_table(in_ROI,:);  
                fig = uifigure('Position',[500 500 760 360],'Name','Locs info in ROI');
                t = uitable(fig,'Data',roi_table);
                t.Position = [20 20 720 320];

            end
            

        end

        % Button pushed function: HMRFsegmentButton
        function segmentation(app, event)
            H2B_HMRF_segmentation(app)

            % merge 7 class into 2 class (non-CD and CD)
            app.HMRFseg.simple_img_class = app.HMRFseg.img_class;
            app.HMRFseg.simple_img_class(ismember(app.HMRFseg.img_class,[1:3])) = 0;
            app.HMRFseg.simple_img_class(ismember(app.HMRFseg.img_class,[4:7])) = 1;
            
            full_nucleus_partition = reshape(app.HMRFseg.img_class, size(app.HMRFseg.nucleus_mask)); full_nucleus_partition = full_nucleus_partition';
            app.full_partition = full_nucleus_partition;

            simple_nucleus_partition = reshape(app.HMRFseg.simple_img_class, size(app.HMRFseg.nucleus_mask)); simple_nucleus_partition = simple_nucleus_partition';
            app.simple_partition_Ch1 = simple_nucleus_partition;

            cmap_all = uint8([4, 0, 0; 56, 46, 142; 137, 48, 141; 215, 31, 40; 239, 127, 25; 244, 191, 27; 244, 237, 70; 255, 255, 255]);
            cmap_CD = uint8([4, 0, 0; 56, 46, 142; 215, 31, 40;255, 255, 255]);
            
            % make this as a montage
            figure('Units','normalized','Position',[0.1 0.1 0.5 0.5]);
            tiledlayout(1,2,'TileSpacing','none','Padding','tight');
            
            ax1 = nexttile;
            imagesc(ax1,full_nucleus_partition)
            colormap(ax1,cmap_all)
            axis(ax1,'off')
            daspect(ax1,[1, 1, 1]);
            title(ax1,'HMRF chromatin segmentation')

            
            ax2 = nexttile;
            imagesc(ax2,simple_nucleus_partition)
            colormap(ax2,cmap_CD)
            axis(ax2,'off')
            daspect(ax2,[1, 1, 1]);
            title(ax2,'Binary CD and non-CD')

            app.HMRFsegmentStatus.Color = [0 1 0];
            % app.MobilityPALMButton.Enable = true;
            app.MobileAnglePALMButton.Enable = true;
        end

        % Button pushed function: PALMBinaryButton_Ch1
        function PALMBinaryButton_Ch1Pushed(app, event)
            %% Binary mask from immobile molecules based PALM image 
            app.simple_partition_Ch1 = PALMBinaryFcn(app,app.Ch1_loc_table,app.Ch1_loc_table.Xpos,app.Ch1_loc_table.Ypos,'immobile',app.Ch1_loc_XYLim);
            
%             ImOut_immobile_density = density_PALM(app,app.Ch1_loc_table,'immobile');
% 
%             % scale ImOut_density linearly into [0, 1] using imcontrast is more easier
%             app.densityPALMfig = figure('Visible','on','Units','inches',...
%                 'Position',[2.1 1.2 10 7],'Name','Immobile molecules generated PALM');
%             ax1 = axes(...
%                 'Units','normalized',...
%                 'Position', [0 0 1 1],... % stretch to fill axes 
%                 'XTickLabel','',...
%                 'YTickLabel','');
%             imshow(ImOut_immobile_density,'InitialMagnification',1000,'colormap',hot,'Parent',ax1);
%             ax1.CLim = [app.render_minDensity_Ch1.Value app.render_maxDensity_Ch1.Value]; 
%             h=colorbar;
%             h.Position = [0.9,0.04,0.02,0.9];
%             h.Units = 'normalized';
%             xlabel(h,'Localizations/\mum^2','FontSize',14)
%             h.Label.Position =[2.384126935875613,748.3768617952024,0];
%             h1 = imcontrast(ax1);
%             uiwait(h1)                     
%             
%             PALM_CLim = [ax1.CLim(1) ax1.CLim(2)];
%             
%             MaxVal = max(ImOut_immobile_density,[],'all');
%             MinVal = min(ImOut_immobile_density,[],'all');
%             Scale_Range = (PALM_CLim-MinVal)./(MaxVal-MinVal);
%             img_densityPALM_adjust = imadjust(rescale(ImOut_immobile_density),Scale_Range); % scale the image into selected data range
%             
%             % Create binary mask of motion blur, maxentropie only takes uint8 image
%             [~, mask_simple_partition]=maxentropie(im2uint8(img_densityPALM_adjust));
%             mask_simple_partition(mask_simple_partition == 255) = 1; % convert mask label to 1 instead of 255
%             
%             app.simple_partition_Ch1 = mask_simple_partition;
%             
%             figure('Units','normalized','Position',[0.1 0.1 0.5 0.5],...
%                 'Name','Immobile PALM generated mask');
%             tiledlayout(1,2,'TileSpacing','none','Padding','tight','Position',[0 0 1 1]);
%             
%             ax1 = nexttile;
%             imshow(ImOut_immobile_density,'colormap',gray,'Parent',ax1);
%             ax1.CLim = PALM_CLim;
%             title(ax1,'Rendered PALM')
% 
%             ax2 = nexttile;
%             imshow(app.simple_partition_Ch1,'Parent',ax2)
%             title(ax2,'PALM binary segmentation')
            
            
            app.PALMBinaryStatus_Ch1.Color = [0 1 0];
            % app.MobilityPALMButton.Enable = true;
            app.MobileAnglePALMButton.Enable = true;
            app.MDFButton.Enable = true;


        end

        % Value changed function: loadPartialData1
        function LoadPartialDataOption1(app, event)
            value = app.loadPartialData1.Value;
            if value == 1
                app.Startframe.Enable = true;
                app.Stopframe.Enable = true;
            else
                app.Startframe.Enable = false;
                app.Stopframe.Enable = false;
            end
        end

        % Callback function
        function driftCorrection_Ch1_Callback(app, event)
            fprintf('Drift correction ...\n');
            data.ctrsX = app.Ch1_loc_table.Xpos/app.camPixelSize_Ch1.Value;
            data.ctrsY = app.Ch1_loc_table.Ypos/app.camPixelSize_Ch1.Value;
            data.frame = app.Ch1_loc_table.Frame;

            driftCorrectParams.binFrame = app.drift_Binframe.Value;
            driftCorrectParams.gapFrame = app.drift_Gapframe.Value;
            driftCorrectParams.totalFrame = max(app.Ch1_loc_table.Frame);
            driftCorrectParams.image_pixel_size = app.camPixelSize_Ch1.Value; % unit: nm/pixel
            driftCorrectParams.bin_pixel_size = app.drift_Binpixelsize.Value; % default: 30nm
            driftCorrectParams.sigma_blur = app.drift_Sigmablur.Value; % default: 11
            driftCorrectParams.padding = app.drift_Padding.Value; % default: 15
            driftCorrectParams.smoothrange = app.drift_Smoothrange.Value; % default: 2
            
            % based on WangBo's feedback, drift1 gets better result than
            % drift2
            [~, drift1_predict, drift_fig] = ScatterDriftCorrection3(data, driftCorrectParams);
            drift = drift1_predict - drift1_predict(1, :);
            % exportgraphics(drift_fig, fullfile(input_dir, 'ThunderStormLocs', filesep, filename(1:(end-4)), '_drift.png'));

            app.Ch1_loc_table.Xpos = app.Ch1_loc_table.Xpos + drift(app.Ch1_loc_table.Frame, 2);
            app.Ch1_loc_table.Ypos = app.Ch1_loc_table.Ypos + drift(app.Ch1_loc_table.Frame, 3);
%             app.Ch1_loc_XYLim = [min(app.Ch1_loc_table.Xpos),max(app.Ch1_loc_table.Xpos),min(app.Ch1_loc_table.Ypos),max(app.Ch1_loc_table.Ypos)];

            app.driftCorrectionStatus_Ch1.Color = [0 1 0];
            app.saveCorrectedTable_Ch1.Enable = true;

        end

        % Button pushed function: render_PALM_Ch1
        function render_PALM_Ch1Callback(app, event)
            ImOut_density = density_PALM(app,app.Ch1_loc_table,app.Ch1_loc_table.Xpos,app.Ch1_loc_table.Ypos,'all',app.Ch1_loc_XYLim);
            app.render_minDensity_Ch1.Value = min(ImOut_density,[],'all');
            app.render_maxDensity_Ch1.Value = max(ImOut_density,[],'all');

            % scale ImOut_density linearly into [0, 1] using imcontrast is more easier
            app.densityPALMfig = uifigure(...
                'Units','inches','Position',[2.1 1.2 10.8438 7.4896],...
                'Name','PALM rendering using all locs');
            ax1 = uiaxes(app.densityPALMfig,...
                'Units','normalized',...
                'Position', [0 0 0.9 1], ...
                'XLimitMethod','tight', 'YLimitMethod','tight', ...
                'XTick','','YTick','');

            % g1 = uigridlayout(app.densityPALMfig,[1 2],'BackgroundColor','k');
            % g1.ColumnWidth = {'1x',100};
            % ax1 = uiaxes(g1,...
            %     'Units','normalized',...
            %     'Position', [0 0 0.9 1],... % stretch to fill axes 
            %     'XTickLabel','',...
            %     'YTickLabel','');
            % g2 = uigridlayout(g1,[4 1]);
            % g2.RowHeight = {22,22,22,'1x'};
            % b1 = uibutton(g2, ...
            %     "Text","Save raw", ...
            %     "ButtonPushedFcn", @(src,event) SaveRawImage(src,app));
            % ax1.Layout.Row = [1 2];
            
            imshow(ImOut_density,'InitialMagnification',1000,'colormap',hot,'Parent',ax1);
            ax1.CLim = [app.render_minDensity_Ch1.Value app.render_maxDensity_Ch1.Value]; 
            h=colorbar(ax1);
            h.Position = [0.8000 0.1000 0.0267 0.8498];
            h.Units = 'normalized';
            h.Color = 'black';
            xlabel(h,'Localizations/\mum^2','FontSize',14,'Color','Black')
            % h.Label.Position =[2.384126935875613,748.3768617952024,0];
            h1 = imcontrast(ax1);
            uiwait(h1)                     
            app.render_minDensity_Ch1.Value = ax1.CLim(1); % update the min density
            app.render_maxDensity_Ch1.Value = ax1.CLim(2);
            app.Ch1Img_densityPALM = ImOut_density;
            app.drawROIButton.Enable = true;
            % app.MobilityPALMButton.Enable = true;
            
            function SaveRawImage(src,app)
                [file,location] = uiputfile('Ch1_PALM.tif','Saving Ch1 PALM TIF image');
                imwrite(uint16(app.Ch1Img_densityPALM), fullfile(location, file), 'WriteMode', 'overwrite');
            end


        end

        % Close request function: mainAppUIFigure
        function mainAppUIFigureCloseRequest(app, event)
            delete(app)
                        
            close all
            all_fig = allchild(0);
            for i = 1:length(all_fig)
                if ~isequal(all_fig(i).Name,"mainApp")
                    delete(all_fig(i));
                end
            end
           
        end

        % Button pushed function: MobileAnglePALMButton
        function AnglePALMCallback(app, event)
            %% Overlay mobile localizations over PALM
            % Get the ROI info and update the roi_raw_table
            DrawROI(app); areaEdge = app.areaEdge;
            
            mobile_raw_table = app.roi_raw_table(app.roi_raw_table.Area > app.minMobileSRarea,:);
            mobile_raw_table.Ellipticity = 10.^(mobile_raw_table.EllipticityIdx./100);
            mobile_raw_table = mobile_raw_table(mobile_raw_table.Ellipticity >= app.minEllipticity.Value & mobile_raw_table.Ellipticity <= app.maxEllipticity.Value,:);
            
            % -------PLOT: Segmentation overlaid mobile localizations with angle ---%
            
            app.zoomAnglefig = uifigure('Name','Segmentation overlaid mobile localizations with angle',...
                'Position',[400 400 874 653]);
            g1 = uigridlayout(app.zoomAnglefig,[2 2]);
            g1.RowHeight = {'1x',50};
            g1.ColumnWidth = {'1x',200};

            ax1 = uiaxes(g1); 

            ax2 = polaraxes(g1);
            polarhistogram(ax2,deg2rad(mobile_raw_table.Angle));
            
            ax1.Layout.Row = [1 2];  
            % imshow(app.Ch1Img_densityPALM,'colormap',gray,'Parent',ax);
            imshow(app.simple_partition_Ch1,'Parent',ax1);hold(ax1,'on');

            b1 = uibutton(g1, ...
                "Text","ROI", ...
                "ButtonPushedFcn", @(app,event) FindROILocsWhenPushed(app,ax1,mobile_raw_table));

            % get the color index of localizations
            adjustArea = mobile_raw_table.Area;
            adjustArea(mobile_raw_table.Area < min(areaEdge)) = min(areaEdge);
            adjustArea(mobile_raw_table.Area > max(areaEdge)) = max(areaEdge);            
            J = app.cmap_mpalm;
            cidx = discretize(adjustArea,areaEdge); 
            cidx = cidx +1;
            
            % overlay detection localization
            scatter(ax1,mobile_raw_table.Xpos_rescale,mobile_raw_table.Ypos_rescale,15,J(cidx,:),'filled'); 
          
            %% Overlay angle direction and sigma amplitude            
            % loop through the localizations
            
            % Pre-calculate some variables outside the loop where possible
            x = mobile_raw_table.Xpos_rescale;
            y = mobile_raw_table.Ypos_rescale;
            angle_deg = mobile_raw_table.Angle;
            % Convert all angles to radians at once
            angle_rad = deg2rad(angle_deg); 
            amp = mobile_raw_table.Ellipticity;
            half_amp_cos = (amp./2).*cos(angle_rad);
            half_amp_sin = (amp./2).*sin(angle_rad);
            
            for i = 1:height(mobile_raw_table)                           
                % calculate start and end points for the line to be centered at (x, y)
                x_start = x(i) + half_amp_cos(i);
                x_end   = x(i) - half_amp_cos(i);
                y_start = y(i) - half_amp_sin(i);
                y_end   = y(i) + half_amp_sin(i);
            
                line(ax1,[x_start, x_end], [y_start, y_end]); % draw line
            end

            % Determine the limits of the ROI
            x_min = min(app.roi_position(1));
            x_max = max(app.roi_position(1)+app.roi_position(3));
            y_min = min(app.roi_position(2));
            y_max = max(app.roi_position(2)+app.roi_position(4));
            
            % Calculate padding to add some margin around the ROI
            padding_x = 0.1 * (x_max - x_min);
            padding_y = 0.1 * (y_max - y_min);
            
            % Set the limits of the x and y axes to zoom in on the ROI
            xlim(ax1,[x_min - padding_x, x_max + padding_x]);
            ylim(ax1,[y_min - padding_y, y_max + padding_y]);
                        
            hold(ax1,'off'); % release the plot


            function FindROILocsWhenPushed(app,ax,mobile_raw_table)
                hroi = drawrectangle(ax);% app.roi_position = hroi.Position;
                in_ROI = inROI(hroi,mobile_raw_table.Xpos_rescale,mobile_raw_table.Ypos_rescale);           
                roi_table = mobile_raw_table(in_ROI,:);  
                fig = uifigure('Position',[500 500 760 360],'Name','Mobile locs info in ROI');
                t = uitable(fig,'Data',roi_table);
                t.Position = [20 20 720 320];
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.mainAppUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {738, 738};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {364, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: loadDataset_Ch2
        function load_Ch2_Callback(app, event)
            app.loadDatasetStatus_Ch2.Color = [1 0 0];
            app.totalLocNumber_Ch2.Enable = false;
            app.meanLocPerFrame_Ch2.Enable = false;
            app.driftCorrectionStatus_Ch2.Color = [1 0 0];
            app.ImageRegistrationStatus_Ch2.Color = [1 0 0];
            app.PALMBinaryStatus_Ch2.Color = [1 0 0];
            app.PALMRenderSwitch_Ch2.Value = 'raw';

            switch app.UseWhichAreaDropDown.Value
                case 'drift corrected'
                    [app.Ch2LocName,app.Ch2LocPath] = uigetfile({'*.csv'},...
                                  'Load Ch2 aligned localization file');
                    if app.Ch2LocName ~= 0                                               
                        app.Ch2_loc_table = readtable(fullfile(app.Ch2LocPath,app.Ch2LocName));
                    end
                case 'raw'
                    [app.Ch2LocName,app.Ch2LocPath] = uigetfile({'*.csv'},...
                                  'Load Ch2 ThunderSTORM localization file');
                    if app.Ch2LocName ~= 0 
                        Ch2Table = readtable(fullfile(app.Ch2LocPath,app.Ch2LocName),'VariableNamingRule','preserve');
                        Ch2Table = renamevars(Ch2Table,["x [nm]","y [nm]"],["Xpos","Ypos"]);
                        Ch2Table = renamevars(Ch2Table,["frame"],["Frame"]);
                        % Convert X-Y coordinate in ThunderSTORM to align with
                        % matlab imshow!!!
                        Ch2Table.xPix = Ch2Table.Xpos/app.camPixelSize_Ch2.Value+0.5;
                        Ch2Table.yPix = Ch2Table.Ypos/app.camPixelSize_Ch2.Value+0.5;
                        Ch2Table.Xpos = Ch2Table.xPix * app.camPixelSize_Ch2.Value;
                        Ch2Table.Ypos = Ch2Table.yPix * app.camPixelSize_Ch2.Value;
            
                        loc_XYLim = [0,app.camPixelSize_Ch2.Value*app.ImWidth.Value,0,app.camPixelSize_Ch2.Value*app.ImHeight.Value];
                        ImOut_density = density_PALM(app,Ch2Table,Ch2Table.Xpos,Ch2Table.Ypos,'all',loc_XYLim);                
            
                        % scale ImOut_density linearly into [0, 1] using imcontrast is more easier
                        fig = figure(...
                            'Units','inches','Position',[3.8229 1.5833 10.8438 7.4896],...
                            'Name','dSTORM rendering using all locs','Color','k');
                        ax1 = uiaxes(fig,...
                            'Units','normalized',...
                            'Position', [0 0 1 1],... % stretch to fill axes 
                            'XTickLabel','',...
                            'YTickLabel','');
                        title(ax1,'Draw ROI to remove background signal')
                        imshow(ImOut_density,'InitialMagnification',1000,'colormap',gray,'Parent',ax1);                 
                        h=colorbar(ax1);
                        h.Units = 'normalized';
                        xlabel(h,'Localizations/\mum^2','FontSize',14)
                        % h.Label.Position =[2.384126935875613,748.3768617952024,0];
                        h1 = imcontrast(ax1);
                        uiwait(h1)                
                        app.render_minDensity_Ch2.Value = ax1.CLim(1); % update the min density
                        app.render_maxDensity_Ch2.Value = ax1.CLim(2);
                        hROI = drawpolygon(ax1,'LabelVisible','on',...
                            'FaceAlpha', 0, 'MarkerSize', 5,'LineWidth', 2,'Color','green');
                        position = hROI.Position*app.render_pixelSize.Value/app.camPixelSize_Ch2.Value;
                        nuclear_mask = logical(poly2mask(position(:,1),position(:,2),app.ImHeight.Value,app.ImWidth.Value));                
                        yPix_rounded = round(Ch2Table.yPix);
                        xPix_rounded = round(Ch2Table.xPix);
                        linear_indices = sub2ind(size(nuclear_mask), yPix_rounded, xPix_rounded);
                        in_roi_array = nuclear_mask(linear_indices);
                        app.Ch2_loc_table = Ch2Table(logical(in_roi_array),:);
                    end
            end
                 
            app.loadDatasetStatus_Ch2.Color = [0 1 0];
            app.totalLocNumber_Ch2.Enable = true;
            app.totalLocNumber_Ch2.Value = height(app.Ch2_loc_table);
            app.meanLocPerFrame_Ch2.Enable = true;
            G = findgroups(app.Ch2_loc_table.Frame);
            locPerFrame = splitapply(@numel,app.Ch2_loc_table.Frame,G);
            app.meanLocPerFrame_Ch2.Value = mean(locPerFrame);
                              
        end

        % Button pushed function: render_PALM_Ch2
        function render_PALM_Ch2Callback(app, event)
            switch app.PALMRenderSwitch_Ch2.Value
                case 'raw'
                    app.Ch2_loc_XYLim = [min(app.Ch2_loc_table.Xpos),max(app.Ch2_loc_table.Xpos),min(app.Ch2_loc_table.Ypos),max(app.Ch2_loc_table.Ypos)];
                    ImOut_density = density_PALM(app,app.Ch2_loc_table,app.Ch2_loc_table.Xpos,app.Ch2_loc_table.Ypos,'all',app.Ch2_loc_XYLim);
                case 'registered'
                    app.Ch2_loc_XYLim = app.Ch1_loc_XYLim;
                    ImOut_density = density_PALM(app,app.Ch2_loc_table,app.Ch2_loc_table.regis_Xpos,app.Ch2_loc_table.regis_Ypos,'all',app.Ch2_loc_XYLim);
            end

            % scale ImOut_density linearly into [0, 1] using imcontrast is more easier
            densitydSTORMfig = uifigure(...
                'Units','inches','Position',[3.8229 1.5833 10.8438 7.4896],...
                'Name','dSTORM rendering using all locs');
            g1 = uigridlayout(densitydSTORMfig,[1 2],'BackgroundColor','k');
            g1.ColumnWidth = {'1x',100};
            ax1 = uiaxes(g1,...
                'Units','normalized',...
                'Position', [0 0 0.9 1],... % stretch to fill axes 
                'XTickLabel','',...
                'YTickLabel','');
            g2 = uigridlayout(g1,[4 1]);
            g2.RowHeight = {22,22,22,'1x'};
            b1 = uibutton(g2, ...
                "Text","Save raw", ...
                "ButtonPushedFcn", @(src,event) SaveRawImage(src,app));
            ax1.Layout.Row = [1 2];
            
            imshow(ImOut_density,'InitialMagnification',1000,'colormap',gray,'Parent',ax1);
            ax1.CLim = [app.render_minDensity_Ch2.Value app.render_maxDensity_Ch2.Value]; 
            h=colorbar(ax1);
            h.Position = [0.8000 0.1000 0.0267 0.8498];
            h.Units = 'normalized';
            xlabel(h,'Localizations/\mum^2','FontSize',14)
            % h.Label.Position =[2.384126935875613,748.3768617952024,0];
            h1 = imcontrast(ax1);
            uiwait(h1)                     
            app.render_minDensity_Ch2.Value = ax1.CLim(1); % update the min density
            app.render_maxDensity_Ch2.Value = ax1.CLim(2);
            app.Ch2Img_densityPALM = ImOut_density;
          
            function SaveRawImage(src,app)
                [file,location] = uiputfile('Ch2_PALM.tif','Saving Ch2 PALM TIF image');
                imwrite(uint16(app.Ch2Img_densityPALM), fullfile(location, file), 'WriteMode', 'overwrite');
            end

        end

        % Button pushed function: driftCorrection_Ch2
        function driftCorrection_Ch2_Callback(app, event)
            fprintf('Drift correction ...\n');
            data.ctrsX = app.Ch2_loc_table.regis_Xpos/app.camPixelSize_Ch2.Value; % original is Xpos, now first align then drift correction
            data.ctrsY = app.Ch2_loc_table.regis_Ypos/app.camPixelSize_Ch2.Value;
            data.frame = app.Ch2_loc_table.Frame;

            driftCorrectParams.binFrame = app.drift_Binframe_Ch2.Value;
            driftCorrectParams.gapFrame = app.drift_Gapframe_Ch2.Value;
            driftCorrectParams.totalFrame = max(app.Ch2_loc_table.Frame);
            driftCorrectParams.image_pixel_size = app.camPixelSize_Ch2.Value; % unit: nm/pixel
            driftCorrectParams.bin_pixel_size = app.drift_Binpixelsize_Ch2.Value; % default: 30nm
            driftCorrectParams.sigma_blur = app.drift_Sigmablur_Ch2.Value; % default: 11
            driftCorrectParams.padding = app.drift_Padding_Ch2.Value; % default: 15
            driftCorrectParams.smoothrange = app.drift_Smoothrange_Ch2.Value; % default: 2
            
            % latest drift correction function updated by ZW
            [~, drift1_predict, drift_fig] = ScatterDriftCorrection3(data, driftCorrectParams);
            app.drift_fromCh2 = drift1_predict - drift1_predict(1, :);
            % exportgraphics(drift_fig, fullfile(input_dir, 'ThunderStormLocs', filesep, filename(1:(end-4)), '_drift.png'));

            app.Ch2_loc_table.regis_Xpos = app.Ch2_loc_table.regis_Xpos + app.drift_fromCh2(app.Ch2_loc_table.Frame, 2);
            app.Ch2_loc_table.regis_Ypos = app.Ch2_loc_table.regis_Ypos + app.drift_fromCh2(app.Ch2_loc_table.Frame, 3);

            % drift correct Ch1 based on Ch2 drift estimation
            app.Ch1_loc_table.Xpos = app.Ch1_loc_table.Xpos + app.drift_fromCh2(app.Ch1_loc_table.Frame, 2)*app.camPixelSize_Ch1.Value/app.camPixelSize_Ch2.Value;
            app.Ch1_loc_table.Ypos = app.Ch1_loc_table.Ypos + app.drift_fromCh2(app.Ch1_loc_table.Frame, 3)*app.camPixelSize_Ch1.Value/app.camPixelSize_Ch2.Value;

%             app.Ch1_loc_XYLim = [min(app.Ch1_loc_table.Xpos),max(app.Ch1_loc_table.Xpos),min(app.Ch1_loc_table.Ypos),max(app.Ch1_loc_table.Ypos)];
            
            app.driftCorrectionStatus_Ch2.Color = [0 1 0];
            app.saveCorrectedTableCh1and2.Enable = "on";
        end

        % Callback function
        function ChannelModeChangeCallback(app, event)
            value = app.ChannelmodeDropDown.Value;
            switch value
                case "Single"
%                     app.maxPhoton_Ch1.Value = 2000;
%                     app.camPixelSize_Ch1.Value = 110;
                case "Dual"
%                     app.maxPhoton_Ch1.Value = 4000;
%                     app.camPixelSize_Ch1.Value = 160;
            end
        end

        % Button pushed function: showLocTable
        function showLocTableFcn(app, event)
            fig = uifigure('Position',[500 500 760 800],'Name','Big table for Ch1 locs');
            t = uitable(fig,'Data',app.Ch1_loc_table);
            t.Position = [20 20 720 780];
        end

        % Value changed function: UseWhichAreaDropDown
        function UseWhichAreaFcn(app, event)
            % Assign which area to use
            switch app.UseWhichAreaDropDown.Value
                case 'UNetArea'
                    app.Ch1_loc_table.Area = app.Ch1_loc_table.UNetArea;
                case 'SRArea'
                    app.Ch1_loc_table.Area = app.Ch1_loc_table.SRArea;
            end
        end

        % Button pushed function: RadialDistFcn
        function RadialDistFcnCallBack(app, event)
            params.dr = app.render_pixelSize.Value;
            params.calib = app.render_pixelSize.Value;            
            dxy = app.render_pixelSize.Value;            
            params.num_pts = 15;
            
            Xmin = app.Ch1_loc_XYLim(1);Xmax=app.Ch1_loc_XYLim(2);Ymin = app.Ch1_loc_XYLim(3);Ymax=app.Ch1_loc_XYLim(4);
            data1_x = (app.Ch1_loc_table.Xpos-Xmin)./dxy+0.5 ; % unit rendered pixel
            data1_y = (app.Ch1_loc_table.Ypos-Ymin)./dxy+0.5 ; % unit rendered pixel

            roi = drawrectangle(app.densityPALMfig.Children(2));

            [m,n] = size(app.Ch1Img_densityPALM);
            bw = createMask(roi,m,n);

            fprintf("Calculating RDF for the selected ROI ...")

            % % Extract unique frames
            % uniqueFrames = unique(app.Ch1_loc_table.Frame);
            
            % % Initialize the structure array
            % data1 = struct('x', cell(length(uniqueFrames), 1),'y', cell(length(uniqueFrames), 1));    
            % data2 = struct('x', cell(length(uniqueFrames), 1),'y', cell(length(uniqueFrames), 1));  

            % % Populate the structure array based on frames
            % app.Ch1_loc_table.XposGrid = data1_x;
            % app.Ch1_loc_table.YposGrid = data1_y;
            % for i = 1:length(uniqueFrames)
            %     iframe = uniqueFrames(i);
            % 
            %     % Find indices of rows with the current frame
            %     idx = app.Ch1_loc_table.Frame == iframe;
            % 
            %     % Store X positions for the current frame in the structure
            %     data1(i).x = app.Ch1_loc_table.XposGrid(idx);
            %     data1(i).y = app.Ch1_loc_table.YposGrid(idx);
            % end
            % [corrs params] = Spatial_Temporal_Xcorr(data1, data2, bw, calib, dr,100);
            
            [corrs, params] = ParCalculateXcorrApp(data1_x, data1_y, bw, params); 
            
            % calculating Ripley'K, L, H function
            pos = [data1_x, data1_y];
            r = 0:(params.dr/params.calib):params.max_r; % unit rendered pixel
            [K, L] = RipleyK(app,pos,bw,roi,r);
            H = L-params.radii/params.calib;

            % Plot results
            figure('Units','inches','Position',[7.1562 3.8854 5.8333 2.7812]); tiledlayout(1,2);
            nexttile; hold on;
            errorbar(params.radii,corrs.C_11,corrs.dC_11,'s-','MarkerFaceColor','b','MarkerEdgeColor','b','Color','b','MarkerSize',2)
            plot([0 max(params.radii)], [1 1], 'k--', 'LineWidth', 1, 'HandleVisibility','off');
            ylabel('G(r)');xlabel('r(nm)');
            % ylim([0 12])
            % xlim([0 200])
            axis square

            nexttile; hold on;
            plot(params.radii,H);
            plot([0 max(params.radii)], [0 0], 'k--', 'LineWidth', 1, 'HandleVisibility','off');
            ylabel("Ripley's \it H(r)")
            xlabel('r(nm)')
            %ylim([0 20])
            axis square
        end

        % Button pushed function: MDFButton
        function MDFButtonCallback(app, event)
           
            calib = app.render_pixelSize.Value;
            dxy = app.render_pixelSize.Value;
            dr = app.render_pixelSize.Value;
            num_pts = 30;

            Xmin = app.Ch1_loc_XYLim(1);Xmax=app.Ch1_loc_XYLim(2);Ymin = app.Ch1_loc_XYLim(3);Ymax=app.Ch1_loc_XYLim(4);
            data1_x = (app.Ch1_loc_table.Xpos-Xmin)./dxy+0.5 ; % unit rendered pixel
            data1_y = (app.Ch1_loc_table.Ypos-Ymin)./dxy+0.5 ; % unit rendered pixel
            pos = [data1_x, data1_y, app.Ch1_loc_table.D];

            roi = drawrectangle(app.densityPALMfig.Children(2));
            max_r = num_pts * (dr/calib); % unit rendered pixel
            r = 0:(dr/calib):max_r; % unit rendered pixel
            
            rad_dist_bins = 0:(dr/calib):max_r; % the 1D spatial bins to histogram radii
            inner_rads = rad_dist_bins(1:end-1); % inner radius for each ring
            outer_rads = rad_dist_bins(2:end); %outer radius for each ring
            mean_rads = (inner_rads+outer_rads)/2; %center point of ring 
            
            params.radii = mean_rads*calib; 
            
            %% MDF
            % pos: [x, y]
            % mask: mask region used to calculate average particle density
            % roi: roi object that generate the mask
            % r: step to measure the RipleyK, e.g.: 0:10:500
            
            % Calculate number of bins in x and y directions
            [num_bins_y,num_bins_x] = size(app.Ch1Img_densityPALM);
            
            grid_indices_x = discretize(pos(:,1),1:num_bins_x+1);
            grid_indices_y = discretize(pos(:,2),1:num_bins_y+1);
            
            % Loop through each point to compute K and L
            inroi_pos = inROI(roi,pos(:,1),pos(:,2));

            [m,n] = size(app.Ch1Img_densityPALM);
            bw = createMask(roi,m,n);

            % create a mask to get only locs in the high density            
            dense_roi_mask = bw & logical(app.simple_partition_Ch1);

            % Convert points to subscripts
            sub = round(pos); % rounding to get integer subscripts
            ind = sub2ind(size(bw), sub(:, 2), sub(:, 1)); % assuming points is in format [x y]
            
            % Query if points are inside the binary mask
            points_inside_bw = dense_roi_mask(ind) == 1;
            % points_inside_simple_partition = app.simple_partition_Ch1(ind) > 0;
            % inroi_densePos = pos(points_inside_bw,:);

            inroi_idx = find(inroi_pos);
            tot_inroi = length(inroi_idx);
            mdf = zeros(num_pts,tot_inroi);
            
            fprintf("Calculating mobility distribution function (mdf) for the selected ROI ...\n")
            progressbarText(0);
            for it = 1:tot_inroi
                inroi_it = inroi_idx(it);
                % Find neighboring points within the same or adjacent grid cells
                x_idx = grid_indices_x(inroi_it);
                y_idx = grid_indices_y(inroi_it);
            
                % Define search range based on current point's grid indices
                x_range = max(1, x_idx - max_r):min(num_bins_x, x_idx + max_r);
                y_range = max(1, y_idx - max_r):min(num_bins_y, y_idx + max_r);
            
                % Get indices of points within the same or adjacent grid  cells 
                neighbor_indices = find(ismember(grid_indices_x, x_range) & ismember(grid_indices_y, y_range));
            
                % Compute distances within the defined radius
                distances = sqrt((pos(neighbor_indices, 1) - pos(inroi_it, 1)).^2 + (pos(neighbor_indices, 2) - pos(inroi_it, 2)).^2);
        
                % Compute distances in each bin
                [~, ~, binIdx] = histcounts(distances, r);
        
                % inroi_pos = pos(inroi_it,:);
                neighbor_pos = pos(neighbor_indices,:);
                valid_binIdx = binIdx(binIdx~=0);
                valid_neighbor_pos = neighbor_pos(binIdx ~= 0,:);
                
                % Count the number of elements in each bin
                num_elements = accumarray(valid_binIdx, 1); 
                
                % Calculate MDF using vectorized operations
                temp_mdf = accumarray(valid_binIdx, valid_neighbor_pos(:,3)) ./ num_elements;
                mdf(:,it) = temp_mdf;                                               
                progressbarText(it/tot_inroi);
            end
            
            mean_mdf = mean(mdf,2,"omitnan");
            std_mdf = std(mdf,0,2,"omitnan");
            
            fprintf("Calculating mobility distribution function of dense locs (dense-mdf) for the selected ROI ...\n")

            inroiDense_idx = find(points_inside_bw);
            tot_inroi = length(inroiDense_idx);
            dense_mdf = zeros(num_pts,tot_inroi);

            progressbarText(0);
            for it = 1:tot_inroi
                inroi_it = inroiDense_idx(it);
                % Find neighboring points within the same or adjacent grid cells
                x_idx = grid_indices_x(inroi_it);
                y_idx = grid_indices_y(inroi_it);
            
                % Define search range based on current point's grid indices
                x_range = max(1, x_idx - max_r):min(num_bins_x, x_idx + max_r);
                y_range = max(1, y_idx - max_r):min(num_bins_y, y_idx + max_r);
            
                % Get indices of points within the same or adjacent grid
                % cells and also outside high density region
                neighbor_indices = find(ismember(grid_indices_x, x_range) & ismember(grid_indices_y, y_range));
            
                % Compute distances within the defined radius
                distances = sqrt((pos(neighbor_indices, 1) - pos(inroi_it, 1)).^2 + (pos(neighbor_indices, 2) - pos(inroi_it, 2)).^2);
        
                % Compute distances in each bin
                [~, ~, binIdx] = histcounts(distances, r);
        
                neighbor_pos = pos(neighbor_indices,:);
                valid_binIdx = binIdx(binIdx~=0);
                valid_neighbor_pos = neighbor_pos(binIdx ~= 0,:);
                
                % Count the number of elements in each bin
                num_elements = accumarray(valid_binIdx, 1); 
                
                % Calculate MDF using vectorized operations
                temp_mdf = accumarray(valid_binIdx, valid_neighbor_pos(:,3)) ./ num_elements;
                dense_mdf(:,it) = temp_mdf;                                               
                progressbarText(it/tot_inroi);
            end

            mean_dense_mdf = mean(dense_mdf,2,"omitnan");
            std_dense_mdf = std(dense_mdf,0,2,"omitnan");
            
            
%             invalid = sum(isnan(dense_mdf),1) > 0;
%             nonan_dense_mdf = dense_mdf(:,invalid == 0);
%             diff_dense_mdf = diff(nonan_dense_mdf,1,1);
%             mean_diffdense_mdf = mean(diff_dense_mdf,2);
%             std_diffdense_mdf = std(diff_dense_mdf,0,2);

            % plot the results
            figure('Units','inches','Position',[7.1562 3.8854 8.6 2.7812]); tiledlayout(1,3);
            nexttile;
            errorbar(params.radii,mean_mdf,std_mdf,'s-','MarkerFaceColor','b','MarkerEdgeColor','b','Color','b','MarkerSize',2)
            ylabel('MDF(r)')
            xlabel('r(nm)')            
            axis square

            nexttile;
            errorbar(params.radii,mean_dense_mdf,std_dense_mdf,'s-','MarkerFaceColor','b','MarkerEdgeColor','b','Color','b','MarkerSize',2)
            ylabel("Dense MDF(r)")
            xlabel('r(nm)')
            axis square

            nexttile;
            x = params.radii(2:end);
            y = diff(mean_dense_mdf);
            yy = smooth(x,y);
            plot(x,y,'b.',x,yy,'r-')
            ylabel("\Delta dense MDF(r)")
            xlabel('r(nm)')
            axis square
        end

        % Button pushed function: driftCorrectionButton_Ch1
        function driftCorrectionButton_Ch1Fcn(app, event)
           % Call dialog box with input values
            app.SeqDriftCorrectionApp = SeqDriftCorrectionApp(app);

            while isvalid(app.SeqDriftCorrectionApp); pause(0.1); end
            if app.SeqDriftState == true
                drift = app.Ch1_drift1_predict - app.Ch1_drift1_predict(1, :);
                app.Ch1_loc_table.Xpos = app.Ch1_loc_table.Xpos + drift(app.Ch1_loc_table.Frame, 2);
                app.Ch1_loc_table.Ypos = app.Ch1_loc_table.Ypos + drift(app.Ch1_loc_table.Frame, 3);
                % app.Ch1_loc_XYLim = [min(app.Ch1_loc_table.Xpos),max(app.Ch1_loc_table.Xpos),min(app.Ch1_loc_table.Ypos),max(app.Ch1_loc_table.Ypos)];
                app.saveCorrectedTable_Ch1.Enable = true;
            end


        end

        % Button pushed function: saveCorrectedTable_Ch1
        function saveCorrectedTable_Ch1CallBack(app, event)
            writetable(app.Ch1_loc_table, fullfile(app.Ch1_loc_path,'drift_corrected_Ch1_SR_pred_v3.csv'))
        end

        % Value changed function: render_minlogD
        function colormap_refresh_Dmin(app, event)
            MinLogD = app.render_minlogD.Value; % Min of log10(D)
            MaxLogD = app.render_maxlogD.Value; % Max of log10(D)
            
            % Edge will return 255 color index, save 1 for NaN value
            app.logD_edge = linspace(MinLogD, MaxLogD, 256); 
            app.D_edge = linspace(10^MinLogD, 10^MaxLogD, 256);            
        end

        % Value changed function: render_maxlogD
        function colormap_refresh_Dmax(app, event)
            MinLogD = app.render_minlogD.Value; % Min of log10(D)
            MaxLogD = app.render_maxlogD.Value; % Max of log10(D)
            
            % Edge will return 255 color index, save 1 for NaN value
            app.logD_edge = linspace(MinLogD, MaxLogD, 256); 
            app.D_edge = linspace(10^MinLogD, 10^MaxLogD, 256); 
            
        end

        % Value changed function: minMobileD
        function update_minMobileD(app, event)
            app.minMobileSRarea = app.D_SRArea_const.Value*sqrt(app.minMobileD.Value);
        end

        % Value changed function: ColorscaleSwitch
        function Colormap_scale_switch(app, event)
            value = app.ColorscaleSwitch.Value;
            switch value
                case "Log"
                    % log scale D colormap
                    MinLogD = app.render_minlogD.Value; % Min of log10(D)
                    MaxLogD = app.render_maxlogD.Value; % Max of log10(D)                    
                    app.D_edge = 10.^linspace(MinLogD, MaxLogD, 256);

                    % % Find the colormap mapping index of each range of logD. and
                    % % map the first black color to all NaN data later
                    % logDValues = linspace(MinLogD, MaxLogD, 256);
                    % DValues = 10.^logDValues;
                    % app.areaEdge = app.D_SRArea_const.Value*sqrt(DValues);
                case "Linear"
                    % Linear scale D colormap
                    MinLogD = app.render_minlogD.Value; % Min of log10(D)
                    MaxLogD = app.render_maxlogD.Value; % Max of log10(D)         
                    app.D_edge = linspace(10^MinLogD, 10^MaxLogD, 256);
                    % MinD = 10^(app.render_minlogD.Value);
                    % MaxD = 10^(app.render_maxlogD.Value);
                    % DValues = linspace(MinD, MaxD, 256);
                    % app.areaEdge = app.D_SRArea_const.Value*sqrt(DValues);
            end
        end

        % Button pushed function: ImageRegistration_Ch2
        function ImageRegistration_Ch2ButtonPushed(app, event)
            BeadsAlign(app);


            % [beadsImg_name,beadsImg_path] = uigetfile({'*.tif'},...
            %               'Select beads image for image registration');
            % beadsImg_MPALM = imread(fullfile(beadsImg_path,beadsImg_name),app.MPALM_channelEditField.Value);
            % beadsImg_PALM = imread(fullfile(beadsImg_path,beadsImg_name),app.regularPALM_channelEditField.Value);
            % 
            % % First input moving image, second input fixed image
            % MOVINGREG = registerImages(app,beadsImg_PALM,beadsImg_MPALM);
            % 
            % % % 将x和y坐标转换为齐次坐标  
            % % movingCoords = [app.Ch2_loc_table.Xpos, app.Ch2_loc_table.Ypos, ones(height(app.Ch2_loc_table))];
            % % % 应用仿射变换  
            % % transformedCoords = movingCoords * MOVINGREG.Transformation.T;  
            % 
            % % 将x和y坐标转换为齐次坐标
            % movingCoords = [app.Ch2_loc_table.xPix, app.Ch2_loc_table.yPix, ones(height(app.Ch2_loc_table),1)];
            % % 应用仿射变换  
            % transformedCoords = movingCoords * MOVINGREG.Transformation.T;  
            % 
            % % 提取变换后的x和y坐标（忽略齐次坐标的最后一个元素）  
            % app.Ch2_loc_table.regis_Xpos = transformedCoords(:,1)*app.camPixelSize_Ch1.Value;
            % app.Ch2_loc_table.regis_Ypos = transformedCoords(:,2)*app.camPixelSize_Ch1.Value;
            % 
            % app.ImageRegistrationStatus_Ch2.Color = [0 1 0];
            % app.PALMBinaryButton_Ch2.Enable = true;


        end

        % Button pushed function: PALMBinaryButton_Ch2
        function PALMBinaryButton_Ch2Pushed(app, event)
            %% Binary mask from immobile molecules based PALM image
            app.PALMRenderSwitch_Ch2.Value = 'registered';
            app.Ch2_loc_XYLim = app.Ch1_loc_XYLim;
            app.simple_partition_Ch2 = PALMBinaryFcn(app,app.Ch2_loc_table,app.Ch2_loc_table.regis_Xpos,app.Ch2_loc_table.regis_Ypos,'all',app.Ch2_loc_XYLim);
            app.PALMBinaryStatus_Ch2.Color = [0 1 0];
        end

        % Value changed function: PALMRenderSwitch_Ch2
        function PALMRenderSwitch_Ch2ValueChanged(app, event)
            value = app.PALMRenderSwitch_Ch2.Value;

            switch value
                case 'raw'
                    app.Ch2_loc_XYLim = [min(app.Ch2_loc_table.Xpos),max(app.Ch2_loc_table.Xpos),min(app.Ch2_loc_table.Ypos),max(app.Ch2_loc_table.Ypos)];
                case 'registered'
                    app.Ch2_loc_XYLim = app.Ch1_loc_XYLim;                    
            end
            
        end

        % Value changed function: locsTypeSwitch
        function locsTypeSwitchValueChanged(app, event)
            value = app.locsTypeSwitch.Value;
            switch value
                case "partial"
                    app.mpalm_moleculeType_minLogD.Enable = "on";
                    app.mpalm_moleculeType_maxLogD.Enable = "on";
                case "all"
                    app.mpalm_moleculeType_minLogD.Enable = "off";
                    app.mpalm_moleculeType_maxLogD.Enable = "off";
            end

        end

        % Button pushed function: saveCorrectedTableCh1and2
        function saveCorrectedTable_Ch1and2(app, event)
            writetable(app.Ch1_loc_table, fullfile(app.Ch1_loc_path,'drift_corrected_Ch1_SR_pred_v3.csv'));
            writetable(app.Ch2_loc_table, fullfile(app.Ch1_loc_path,'drift_corrected_Ch2_SR_pred_v3.csv'));
            fprintf('Both Ch1 and Ch2 aligned and drift corrected table saved!\n');
        end

        % Value changed function: loadPartialData2
        function LoadPartialDataOption2(app, event)
            value = app.loadPartialData2.Value;            
            if value == 1
                app.LocalizationNumCutOff.Enable = true;                
            else
                app.LocalizationNumCutOff.Enable = false;                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.mainAppUIFigure = uifigure('Visible', 'off');
            app.mainAppUIFigure.Name = 'mainApp';
            app.mainAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @mainAppUIFigureCloseRequest, true);
            app.mainAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.mainAppUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.LOADCh1Tab = uitab(app.TabGroup);
            app.LOADCh1Tab.Title = 'LOAD Ch1';
            app.loadDataset_Ch1 = uibutton(app.LOADCh1Tab, 'push');
            app.loadDataset_Ch1.ButtonPushedFcn = createCallbackFcn(app, @load_Ch1_Callback, true);
            app.loadDataset_Ch1.Text = 'Load Ch1';
            app.loadDatasetStatus_Ch1 = uilamp(app.LOADCh1Tab);
            app.MaxallowedphotonEditFieldLabel = uilabel(app.LOADCh1Tab);
            app.MaxallowedphotonEditFieldLabel.Text = 'Max allowed photon#';
            app.maxPhoton_Ch1 = uieditfield(app.LOADCh1Tab, 'numeric');
            app.maxPhoton_Ch1.Value = Inf;
            app.CamerapixelsizenmLabel = uilabel(app.LOADCh1Tab);
            app.CamerapixelsizenmLabel.Text = 'Camera pixel size (nm)';
            app.camPixelSize_Ch1 = uieditfield(app.LOADCh1Tab, 'numeric');
            app.camPixelSize_Ch1.Value = 110;
            app.TotallocalizationnumberEditFieldLabel = uilabel(app.LOADCh1Tab);
            app.TotallocalizationnumberEditFieldLabel.Text = 'Total localization number:';
            app.totalLocNumber = uieditfield(app.LOADCh1Tab, 'numeric');
            app.totalLocNumber.Editable = 'off';
            app.AveragelocsperframeLabel = uilabel(app.LOADCh1Tab);
            app.AveragelocsperframeLabel.Text = 'Average locs per frame:';
            app.meanLocPerFrame = uieditfield(app.LOADCh1Tab, 'numeric');
            app.meanLocPerFrame.Editable = 'off';
            app.loadPartialData1 = uicheckbox(app.LOADCh1Tab);
            app.loadPartialData1.ValueChangedFcn = createCallbackFcn(app, @LoadPartialDataOption1, true);
            app.loadPartialData1.Text = '';
            app.StartframeEditFieldLabel = uilabel(app.LOADCh1Tab);
            app.StartframeEditFieldLabel.Enable = 'off';
            app.StartframeEditFieldLabel.Text = 'Start frame';
            app.Startframe = uieditfield(app.LOADCh1Tab, 'numeric');
            app.Startframe.Enable = 'off';
            app.StopframeLabel = uilabel(app.LOADCh1Tab);
            app.StopframeLabel.Enable = 'off';
            app.StopframeLabel.Text = 'Stop frame';
            app.Stopframe = uieditfield(app.LOADCh1Tab, 'numeric');
            app.Stopframe.Enable = 'off';
            app.DriftcorrectionLabel = uilabel(app.LOADCh1Tab);
            app.DriftcorrectionLabel.Text = '------------------Drift correction -----------------';
            app.ImageWidthLabel = uilabel(app.LOADCh1Tab);
            app.ImageWidthLabel.Text = 'Image Width';
            app.ImWidth = uieditfield(app.LOADCh1Tab, 'numeric');
            app.ImWidth.Editable = 'off';
            app.ImageHeightLabel = uilabel(app.LOADCh1Tab);
            app.ImageHeightLabel.Text = 'Image Height';
            app.ImHeight = uieditfield(app.LOADCh1Tab, 'numeric');
            app.ImHeight.Editable = 'off';
            app.CamerapixelsizenmLabel_2 = uilabel(app.LOADCh1Tab);
            app.CamerapixelsizenmLabel_2.Text = 'Exposure time (s)';
            app.exposureTime = uieditfield(app.LOADCh1Tab, 'numeric');
            app.exposureTime.Value = 0.0305;
            app.showLocTable = uibutton(app.LOADCh1Tab, 'push');
            app.showLocTable.ButtonPushedFcn = createCallbackFcn(app, @showLocTableFcn, true);
            app.showLocTable.Enable = 'off';
            app.showLocTable.Text = 'Show loc table';
            app.UsewhichareaDropDownLabel = uilabel(app.LOADCh1Tab);
            app.UsewhichareaDropDownLabel.Text = 'Use which area';
            app.UseWhichAreaDropDown = uidropdown(app.LOADCh1Tab);
            app.UseWhichAreaDropDown.Items = {'raw', 'drift corrected'};
            app.UseWhichAreaDropDown.ValueChangedFcn = createCallbackFcn(app, @UseWhichAreaFcn, true);
            app.UseWhichAreaDropDown.Value = 'raw';
            app.driftCorrectionButton_Ch1 = uibutton(app.LOADCh1Tab, 'push');
            app.driftCorrectionButton_Ch1.ButtonPushedFcn = createCallbackFcn(app, @driftCorrectionButton_Ch1Fcn, true);
            app.driftCorrectionButton_Ch1.Text = 'Drift correction';
            app.driftCorrectionStatus_Ch1 = uilamp(app.LOADCh1Tab);
            app.saveCorrectedTable_Ch1 = uibutton(app.LOADCh1Tab, 'push');
            app.saveCorrectedTable_Ch1.ButtonPushedFcn = createCallbackFcn(app, @saveCorrectedTable_Ch1CallBack, true);
            app.saveCorrectedTable_Ch1.Enable = 'off';
            app.saveCorrectedTable_Ch1.Text = 'Save';
            app.MinallowedphotonLabel = uilabel(app.LOADCh1Tab);
            app.MinallowedphotonLabel.Text = 'Min allowed photon#';
            app.minPhoton_Ch1 = uieditfield(app.LOADCh1Tab, 'numeric');
            app.FastmobilemeanareaLabel_2 = uilabel(app.LOADCh1Tab);
            app.FastmobilemeanareaLabel_2.Text = 'D-SR const';
            app.D_SRArea_const = uieditfield(app.LOADCh1Tab, 'numeric');
            app.D_SRArea_const.Value = 1162;
            app.loadPartialData2 = uicheckbox(app.LOADCh1Tab);
            app.loadPartialData2.ValueChangedFcn = createCallbackFcn(app, @LoadPartialDataOption2, true);
            app.loadPartialData2.Text = '';
            app.LoadpartialdataLabel = uilabel(app.LOADCh1Tab);
            app.LoadpartialdataLabel.Text = '------------------ Load partial data -----------------';
            app.MovieinfoLabel = uilabel(app.LOADCh1Tab);
            app.MovieinfoLabel.Text = '------------------ Movie info -----------------';
            app.LocalizationEditField_2Label = uilabel(app.LOADCh1Tab);
            app.LocalizationEditField_2Label.Enable = 'off';
            app.LocalizationEditField_2Label.Text = 'Localization #';
            app.LocalizationNumCutOff = uieditfield(app.LOADCh1Tab, 'numeric');
            app.LocalizationNumCutOff.Enable = 'off';
            app.DrawROICheckBox = uicheckbox(app.LOADCh1Tab);
            app.DrawROICheckBox.Text = 'Draw ROI';
            app.Ch1RenderTab = uitab(app.TabGroup);
            app.Ch1RenderTab.Title = 'Ch1 Render';
            app.PixelsizenmLabel = uilabel(app.Ch1RenderTab);
            app.PixelsizenmLabel.Text = 'Render pixel size (nm)';
            app.render_pixelSize = uieditfield(app.Ch1RenderTab, 'numeric');
            app.render_pixelSize.Value = 30;
            app.RendersigmanmLabel = uilabel(app.Ch1RenderTab);
            app.RendersigmanmLabel.Text = 'Render sigma (nm)';
            app.render_sigma = uieditfield(app.Ch1RenderTab, 'numeric');
            app.render_sigma.Value = 30;
            app.MobilityPALMButton = uibutton(app.Ch1RenderTab, 'push');
            app.MobilityPALMButton.ButtonPushedFcn = createCallbackFcn(app, @MPALM_RENDER, true);
            app.MobilityPALMButton.Text = 'Mobility PALM!';
            app.drawROIButton = uibutton(app.Ch1RenderTab, 'push');
            app.drawROIButton.ButtonPushedFcn = createCallbackFcn(app, @ROIScatterOverlayCallback, true);
            app.drawROIButton.Enable = 'off';
            app.drawROIButton.Text = 'Draw ROI';
            app.HMRFsegmentButton = uibutton(app.Ch1RenderTab, 'push');
            app.HMRFsegmentButton.ButtonPushedFcn = createCallbackFcn(app, @segmentation, true);
            app.HMRFsegmentButton.Tooltip = {'Currently available for H2B data only, to segment the chromatin into CD and non-CD region'};
            app.HMRFsegmentButton.Text = 'HMRF segment';
            app.HMRFsegmentStatus = uilamp(app.Ch1RenderTab);
            app.TotalclassEditFieldLabel = uilabel(app.Ch1RenderTab);
            app.TotalclassEditFieldLabel.Text = 'Total class';
            app.TotalSegmentClassNumber = uieditfield(app.Ch1RenderTab, 'numeric');
            app.TotalSegmentClassNumber.Enable = 'off';
            app.TotalSegmentClassNumber.Tooltip = {'Default value 7 usually gives best result'};
            app.TotalSegmentClassNumber.Value = 7;
            app.SegmentationLabel = uilabel(app.Ch1RenderTab);
            app.SegmentationLabel.Text = '------------------ Segmentation -----------------';
            app.mPALMrenderingLabel = uilabel(app.Ch1RenderTab);
            app.mPALMrenderingLabel.Text = '-------------- mPALM rendering -----------------';
            app.PALMBinaryButton_Ch1 = uibutton(app.Ch1RenderTab, 'push');
            app.PALMBinaryButton_Ch1.ButtonPushedFcn = createCallbackFcn(app, @PALMBinaryButton_Ch1Pushed, true);
            app.PALMBinaryButton_Ch1.Text = 'PALM Binary';
            app.PALMBinaryStatus_Ch1 = uilamp(app.Ch1RenderTab);
            app.PALMrenderingLabel = uilabel(app.Ch1RenderTab);
            app.PALMrenderingLabel.Text = '------------------ PALM rendering -----------------';
            app.minDensityEditFieldLabel = uilabel(app.Ch1RenderTab);
            app.minDensityEditFieldLabel.Text = 'minDensity';
            app.render_minDensity_Ch1 = uieditfield(app.Ch1RenderTab, 'numeric');
            app.maxDensityEditFieldLabel = uilabel(app.Ch1RenderTab);
            app.maxDensityEditFieldLabel.Text = 'maxDensity';
            app.render_maxDensity_Ch1 = uieditfield(app.Ch1RenderTab, 'numeric');
            app.render_maxDensity_Ch1.Value = 3000;
            app.render_PALM_Ch1 = uibutton(app.Ch1RenderTab, 'push');
            app.render_PALM_Ch1.ButtonPushedFcn = createCallbackFcn(app, @render_PALM_Ch1Callback, true);
            app.render_PALM_Ch1.Text = 'PALM!';
            app.minmobileDLabel = uilabel(app.Ch1RenderTab);
            app.minmobileDLabel.Text = 'min mobile D';
            app.minMobileD = uieditfield(app.Ch1RenderTab, 'numeric');
            app.minMobileD.ValueChangedFcn = createCallbackFcn(app, @update_minMobileD, true);
            app.minMobileD.Value = 1;
            app.ImmobileareapercentageEditFieldLabel = uilabel(app.Ch1RenderTab);
            app.ImmobileareapercentageEditFieldLabel.Enable = 'off';
            app.ImmobileareapercentageEditFieldLabel.Text = 'Immobile area percentage';
            app.immobileAreaPercent = uieditfield(app.Ch1RenderTab, 'numeric');
            app.immobileAreaPercent.Editable = 'off';
            app.ImmobilemeanareaLabel = uilabel(app.Ch1RenderTab);
            app.ImmobilemeanareaLabel.Text = 'Min log10(D)';
            app.render_minlogD = uieditfield(app.Ch1RenderTab, 'numeric');
            app.render_minlogD.ValueChangedFcn = createCallbackFcn(app, @colormap_refresh_Dmin, true);
            app.render_minlogD.Value = -2;
            app.FastmobilemeanareaLabel = uilabel(app.Ch1RenderTab);
            app.FastmobilemeanareaLabel.Text = 'Max log10(D)';
            app.render_maxlogD = uieditfield(app.Ch1RenderTab, 'numeric');
            app.render_maxlogD.ValueChangedFcn = createCallbackFcn(app, @colormap_refresh_Dmax, true);
            app.render_maxlogD.Value = 1;
            app.MobileAnglePALMButton = uibutton(app.Ch1RenderTab, 'push');
            app.MobileAnglePALMButton.ButtonPushedFcn = createCallbackFcn(app, @AnglePALMCallback, true);
            app.MobileAnglePALMButton.Enable = 'off';
            app.MobileAnglePALMButton.Text = 'Mobile Angle PALM!';
            app.MinellipticityEditFieldLabel = uilabel(app.Ch1RenderTab);
            app.MinellipticityEditFieldLabel.Text = 'Min ellipticity';
            app.minEllipticity = uieditfield(app.Ch1RenderTab, 'numeric');
            app.minEllipticity.Value = 1;
            app.MaxellipticityEditFieldLabel = uilabel(app.Ch1RenderTab);
            app.MaxellipticityEditFieldLabel.Text = 'Max ellipticity';
            app.maxEllipticity = uieditfield(app.Ch1RenderTab, 'numeric');
            app.maxEllipticity.Value = 2;
            app.RadialDistFcn = uibutton(app.Ch1RenderTab, 'push');
            app.RadialDistFcn.ButtonPushedFcn = createCallbackFcn(app, @RadialDistFcnCallBack, true);
            app.RadialDistFcn.Text = 'RDF';
            app.QuantificationLabel = uilabel(app.Ch1RenderTab);
            app.QuantificationLabel.Text = '-------------- Quantification -----------------';
            app.MDFButton = uibutton(app.Ch1RenderTab, 'push');
            app.MDFButton.ButtonPushedFcn = createCallbackFcn(app, @MDFButtonCallback, true);
            app.MDFButton.Enable = 'off';
            app.MDFButton.Text = 'MDF';
            app.MoleculetypeDropDownLabel = uilabel(app.Ch1RenderTab);
            app.MoleculetypeDropDownLabel.Text = 'Molecule type';
            app.locsTypeSwitch = uidropdown(app.Ch1RenderTab);
            app.locsTypeSwitch.Items = {'all', 'partial'};
            app.locsTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @locsTypeSwitchValueChanged, true);
            app.locsTypeSwitch.Value = 'all';
            app.ColorscaleSwitchLabel = uilabel(app.Ch1RenderTab);
            app.ColorscaleSwitchLabel.Text = 'Color scale';
            app.ColorscaleSwitch = uiswitch(app.Ch1RenderTab, 'rocker');
            app.ColorscaleSwitch.Items = {'Linear', 'Log'};
            app.ColorscaleSwitch.ValueChangedFcn = createCallbackFcn(app, @Colormap_scale_switch, true);
            app.ColorscaleSwitch.Value = 'Linear';
            app.MinlogDLabel = uilabel(app.Ch1RenderTab);
            app.MinlogDLabel.Enable = 'off';
            app.MinlogDLabel.Text = 'Max logD';
            app.mpalm_moleculeType_maxLogD = uieditfield(app.Ch1RenderTab, 'numeric');
            app.mpalm_moleculeType_maxLogD.Enable = 'off';
            app.mpalm_moleculeType_maxLogD.Value = -1;
            app.MaxlogDLabel = uilabel(app.Ch1RenderTab);
            app.MaxlogDLabel.Enable = 'off';
            app.MaxlogDLabel.Text = 'Min logD';
            app.mpalm_moleculeType_minLogD = uieditfield(app.Ch1RenderTab, 'numeric');
            app.mpalm_moleculeType_minLogD.Enable = 'off';
            app.mpalm_moleculeType_minLogD.Value = -Inf;
            app.Ch2RenderTab = uitab(app.TabGroup);
            app.Ch2RenderTab.Title = 'Ch2 Render';
            app.loadDataset_Ch2 = uibutton(app.Ch2RenderTab, 'push');
            app.loadDataset_Ch2.ButtonPushedFcn = createCallbackFcn(app, @load_Ch2_Callback, true);
            app.loadDataset_Ch2.Text = 'Load Ch2';
            app.render_PALM_Ch2 = uibutton(app.Ch2RenderTab, 'push');
            app.render_PALM_Ch2.ButtonPushedFcn = createCallbackFcn(app, @render_PALM_Ch2Callback, true);
            app.render_PALM_Ch2.Text = 'PALM!';
            app.loadDatasetStatus_Ch2 = uilamp(app.Ch2RenderTab);
            app.minDensityEditFieldLabel_2 = uilabel(app.Ch2RenderTab);
            app.minDensityEditFieldLabel_2.Text = 'minDensity';
            app.render_minDensity_Ch2 = uieditfield(app.Ch2RenderTab, 'numeric');
            app.maxDensityEditFieldLabel_2 = uilabel(app.Ch2RenderTab);
            app.maxDensityEditFieldLabel_2.Text = 'maxDensity';
            app.render_maxDensity_Ch2 = uieditfield(app.Ch2RenderTab, 'numeric');
            app.render_maxDensity_Ch2.Value = 3000;
            app.driftCorrection_Ch2 = uibutton(app.Ch2RenderTab, 'push');
            app.driftCorrection_Ch2.ButtonPushedFcn = createCallbackFcn(app, @driftCorrection_Ch2_Callback, true);
            app.driftCorrection_Ch2.Text = 'Drift correction';
            app.driftCorrectionStatus_Ch2 = uilamp(app.Ch2RenderTab);
            app.DriftcorrectionLabel_Ch2 = uilabel(app.Ch2RenderTab);
            app.DriftcorrectionLabel_Ch2.Text = '------------------ Step 2: Drift correction -----------------';
            app.BinframeEditFieldLabel_2 = uilabel(app.Ch2RenderTab);
            app.BinframeEditFieldLabel_2.Text = 'Bin frame';
            app.drift_Binframe_Ch2 = uieditfield(app.Ch2RenderTab, 'numeric');
            app.drift_Binframe_Ch2.Value = 2000;
            app.GapframeEditFieldLabel_2 = uilabel(app.Ch2RenderTab);
            app.GapframeEditFieldLabel_2.Text = 'Gap frame';
            app.drift_Gapframe_Ch2 = uieditfield(app.Ch2RenderTab, 'numeric');
            app.drift_Gapframe_Ch2.Value = 500;
            app.BinpixelsizeEditFieldLabel_2 = uilabel(app.Ch2RenderTab);
            app.BinpixelsizeEditFieldLabel_2.Text = 'Bin pixel size';
            app.drift_Binpixelsize_Ch2 = uieditfield(app.Ch2RenderTab, 'numeric');
            app.drift_Binpixelsize_Ch2.Value = 30;
            app.SigmablurEditFieldLabel_2 = uilabel(app.Ch2RenderTab);
            app.SigmablurEditFieldLabel_2.Text = 'Sigma blur';
            app.drift_Sigmablur_Ch2 = uieditfield(app.Ch2RenderTab, 'numeric');
            app.drift_Sigmablur_Ch2.Value = 11;
            app.PaddingEditFieldLabel_2 = uilabel(app.Ch2RenderTab);
            app.PaddingEditFieldLabel_2.Text = 'Padding';
            app.drift_Padding_Ch2 = uieditfield(app.Ch2RenderTab, 'numeric');
            app.drift_Padding_Ch2.Value = 15;
            app.SmoothrangeEditFieldLabel_2 = uilabel(app.Ch2RenderTab);
            app.SmoothrangeEditFieldLabel_2.Text = 'Smooth range';
            app.drift_Smoothrange_Ch2 = uieditfield(app.Ch2RenderTab, 'numeric');
            app.drift_Smoothrange_Ch2.Value = 2;
            app.TotallocalizationnumberEditFieldLabel_2 = uilabel(app.Ch2RenderTab);
            app.TotallocalizationnumberEditFieldLabel_2.Enable = 'off';
            app.TotallocalizationnumberEditFieldLabel_2.Text = 'Total localization number:';
            app.totalLocNumber_Ch2 = uieditfield(app.Ch2RenderTab, 'numeric');
            app.totalLocNumber_Ch2.Editable = 'off';
            app.totalLocNumber_Ch2.Enable = 'off';
            app.AveragelocsperframeLabel_2 = uilabel(app.Ch2RenderTab);
            app.AveragelocsperframeLabel_2.Enable = 'off';
            app.AveragelocsperframeLabel_2.Text = 'Average locs per frame:';
            app.meanLocPerFrame_Ch2 = uieditfield(app.Ch2RenderTab, 'numeric');
            app.meanLocPerFrame_Ch2.Editable = 'off';
            app.meanLocPerFrame_Ch2.Enable = 'off';
            app.ImageRegistrationLabel_Ch2 = uilabel(app.Ch2RenderTab);
            app.ImageRegistrationLabel_Ch2.Text = '------------------ Step 1: Image Registration -----------------';
            app.ImageRegistration_Ch2 = uibutton(app.Ch2RenderTab, 'push');
            app.ImageRegistration_Ch2.ButtonPushedFcn = createCallbackFcn(app, @ImageRegistration_Ch2ButtonPushed, true);
            app.ImageRegistration_Ch2.Text = 'Registration';
            app.ImageRegistrationStatus_Ch2 = uilamp(app.Ch2RenderTab);
            app.CamerapixelsizenmLabel_3 = uilabel(app.Ch2RenderTab);
            app.CamerapixelsizenmLabel_3.Text = 'Camera pixel size (nm)';
            app.camPixelSize_Ch2 = uieditfield(app.Ch2RenderTab, 'numeric');
            app.camPixelSize_Ch2.Value = 160;
            app.PALMRenderSwitch_Ch2 = uiswitch(app.Ch2RenderTab, 'rocker');
            app.PALMRenderSwitch_Ch2.Items = {'raw', 'registered'};
            app.PALMRenderSwitch_Ch2.ValueChangedFcn = createCallbackFcn(app, @PALMRenderSwitch_Ch2ValueChanged, true);
            app.PALMRenderSwitch_Ch2.Value = 'raw';
            app.PALMBinaryButton_Ch2 = uibutton(app.Ch2RenderTab, 'push');
            app.PALMBinaryButton_Ch2.ButtonPushedFcn = createCallbackFcn(app, @PALMBinaryButton_Ch2Pushed, true);
            app.PALMBinaryButton_Ch2.Enable = 'off';
            app.PALMBinaryButton_Ch2.Text = 'Binary mask';
            app.PALMBinaryStatus_Ch2 = uilamp(app.Ch2RenderTab);
            app.SensitivityEditFieldLabel = uilabel(app.Ch2RenderTab);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity';
            app.BinarySensitivity_Ch2 = uieditfield(app.Ch2RenderTab, 'numeric');
            app.BinarySensitivity_Ch2.Value = 0.5;
            app.saveCorrectedTableCh1and2 = uibutton(app.Ch2RenderTab, 'push');
            app.saveCorrectedTableCh1and2.ButtonPushedFcn = createCallbackFcn(app, @saveCorrectedTable_Ch1and2, true);
            app.saveCorrectedTableCh1and2.Enable = 'off';
            app.saveCorrectedTableCh1and2.Text = 'Save';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo.jpg');
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.Tab1 = uitab(app.TabGroup2);
            app.Tab1.Title = 'Tab1';
            app.ax_mobilityTime = uiaxes(app.Tab1);
            title(app.ax_mobilityTime, 'Mobility sequence')
            xlabel(app.ax_mobilityTime, 'Appearance order')
            ylabel(app.ax_mobilityTime, 'Diffusion coefficient')
            zlabel(app.ax_mobilityTime, 'Z')
            app.ax_tcPALM = uiaxes(app.Tab1);
            title(app.ax_tcPALM, 'tcPALM')
            xlabel(app.ax_tcPALM, 'Frame')
            ylabel(app.ax_tcPALM, 'Counts')
            zlabel(app.ax_tcPALM, 'Z')
            app.ax_locPerFrame = uiaxes(app.Tab1);
            title(app.ax_locPerFrame, 'Localization Per Frame')
            xlabel(app.ax_locPerFrame, 'Frame')
            ylabel(app.ax_locPerFrame, 'Counts')
            zlabel(app.ax_locPerFrame, 'Z')
            app.ax_logD_Histogram = uiaxes(app.Tab1);
            title(app.ax_logD_Histogram, 'Mobility distribution')
            xlabel(app.ax_logD_Histogram, 'Log10(D)')
            ylabel(app.ax_logD_Histogram, 'PDF')
            zlabel(app.ax_logD_Histogram, 'Z')
            app.MinLogDEditFieldLabel = uilabel(app.Tab1);
            app.MinLogDEditFieldLabel.Text = 'MinLogD';
            app.MinLogD_plot = uieditfield(app.Tab1, 'numeric');
            app.MinLogD_plot.Value = -3;
            app.MaxLogDEditFieldLabel = uilabel(app.Tab1);
            app.MaxLogDEditFieldLabel.Text = 'MaxLogD';
            app.MaxLogD_plot = uieditfield(app.Tab1, 'numeric');
            app.MaxLogD_plot.Value = 2;
            app.Tab2 = uitab(app.TabGroup2);
            app.Tab2.Title = 'Tab2';
            app.ax_Ch1_photon_histogram = uiaxes(app.Tab2);
            title(app.ax_Ch1_photon_histogram, 'Photon # histogram')
            xlabel(app.ax_Ch1_photon_histogram, 'photon #')
            ylabel(app.ax_Ch1_photon_histogram, 'Probability')
            zlabel(app.ax_Ch1_photon_histogram, 'Z')
            app.ax_Ch1_SNR = uiaxes(app.Tab2);
            title(app.ax_Ch1_SNR, 'Signal-to-noise ratio')
            xlabel(app.ax_Ch1_SNR, 'SNR (dB)')
            ylabel(app.ax_Ch1_SNR, 'Probability')
            zlabel(app.ax_Ch1_SNR, 'Z')
            % Show the figure after all components are created
            app.mainAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LocalVersion_mobilityPALM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainAppUIFigure)
        end
    end
end